classdef ZebraExploreApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ZebraMainFig                  matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        PlotTab                       matlab.ui.container.Tab
        uipanel2                      matlab.ui.container.Panel
        select_data                   matlab.ui.control.Button
        dataFormatPanel               matlab.ui.container.ButtonGroup
        normalDataRB                  matlab.ui.control.RadioButton
        meyerVepRB                    matlab.ui.control.RadioButton
        meyerSspRB                    matlab.ui.control.RadioButton
        lauraEEG                      matlab.ui.control.RadioButton
        axopatchCk                    matlab.ui.control.RadioButton
        TRCimportRB                   matlab.ui.control.RadioButton
        meaRB                         matlab.ui.control.RadioButton
        excludeChFlag                 matlab.ui.control.CheckBox
        internalSynchCk               matlab.ui.control.CheckBox
        uipanel15                     matlab.ui.container.ButtonGroup
        mVck                          matlab.ui.control.RadioButton
        microVck                      matlab.ui.control.RadioButton
        autoclear                     matlab.ui.control.CheckBox
        openDir                       matlab.ui.control.Button
        TrialtimeoffsetsLabel         matlab.ui.control.Label
        trTimeOffset_txt              matlab.ui.control.NumericEditField
        GainEditFieldLabel            matlab.ui.control.Label
        gain                          matlab.ui.control.NumericEditField
        SamplingsEditFieldLabel       matlab.ui.control.Label
        spEdit                        matlab.ui.control.NumericEditField
        NchannelsEditFieldLabel       matlab.ui.control.Label
        chEdit                        matlab.ui.control.NumericEditField
        TrialsEditFieldLabel          matlab.ui.control.Label
        trialsEdit                    matlab.ui.control.NumericEditField
        excludeCh                     matlab.ui.control.NumericEditField
        synchCh                       matlab.ui.control.NumericEditField
        trialText                     matlab.ui.control.Label
        trialSlider                   matlab.ui.control.Spinner
        ChText                        matlab.ui.control.Label
        chSlider                      matlab.ui.control.Spinner
        SweeptimesLabel               matlab.ui.control.Label
        SweepTimeEdit                 matlab.ui.control.NumericEditField
        plotSlate                     matlab.ui.container.Panel
        wide_plot_HP                  matlab.ui.control.UIAxes
        scrollLeft                    matlab.ui.control.Button
        tResetBtn                     matlab.ui.control.Button
        sendToClipboard               matlab.ui.control.Button
        tmaxTxt                       matlab.ui.control.NumericEditField
        tminTxt                       matlab.ui.control.NumericEditField
        spectraWide                   matlab.ui.control.UIAxes
        wide_plot_up                  matlab.ui.control.UIAxes
        ymaxTxt                       matlab.ui.control.NumericEditField
        yminTxt                       matlab.ui.control.NumericEditField
        ResetButton                   matlab.ui.control.Button
        scrollRight                   matlab.ui.control.Button
        uipanel8                      matlab.ui.container.Panel
        computeBP                     matlab.ui.control.Button
        plotMeanFlag                  matlab.ui.control.CheckBox
        plotMultiCh                   matlab.ui.control.CheckBox
        plotTrCk                      matlab.ui.control.CheckBox
        autoOffCk                     matlab.ui.control.CheckBox
        recomputeSPG                  matlab.ui.control.Button
        autoscaleYck                  matlab.ui.control.CheckBox
        plotTemplateCk                matlab.ui.control.CheckBox
        displayLeakCk                 matlab.ui.control.CheckBox
        lpcheck                       matlab.ui.control.CheckBox
        bpCheck1                      matlab.ui.control.CheckBox
        bpCheck2                      matlab.ui.control.CheckBox
        bpCheck3                      matlab.ui.control.CheckBox
        hpCheck                       matlab.ui.control.CheckBox
        screenCaptureBtn              matlab.ui.control.CheckBox
        SPGmode                       matlab.ui.container.ButtonGroup
        SPG                           matlab.ui.control.RadioButton
        gammaPower                    matlab.ui.control.RadioButton
        SPGlogCk                      matlab.ui.control.CheckBox
        EIbutton                      matlab.ui.control.RadioButton
        BP                            matlab.ui.control.RadioButton
        ChronuxCk                     matlab.ui.control.CheckBox
        leakageCk                     matlab.ui.control.CheckBox
        uipanel5                      matlab.ui.container.Panel
        saveCurrentCh                 matlab.ui.control.Button
        saveSPG                       matlab.ui.control.Button
        saveBP                        matlab.ui.control.Button
        SettingsTab                   matlab.ui.container.Tab
        autoProcessing                matlab.ui.container.Panel
        uipanel20                     matlab.ui.container.Panel
        powerSpectraCk                matlab.ui.control.CheckBox
        autoSavePSck                  matlab.ui.control.CheckBox
        analyzePWdistributionCk       matlab.ui.control.CheckBox
        autoSavePWck                  matlab.ui.control.CheckBox
        TrRej_ck                      matlab.ui.control.CheckBox
        multiChAnalyzer               matlab.ui.control.CheckBox
        singleUnitExtraction_ck       matlab.ui.control.CheckBox
        hit_ck                        matlab.ui.control.CheckBox
        checkbox_sleepscoring         matlab.ui.control.CheckBox
        saveParams_btn                matlab.ui.control.Button
        loadParams_btn                matlab.ui.control.Button
        uipanel21                     matlab.ui.container.Panel
        concat_ck                     matlab.ui.control.CheckBox
        analyzeWhileConc_ck           matlab.ui.control.CheckBox
        concat_btn                    matlab.ui.control.Button
        clearConc_btn                 matlab.ui.control.Button
        dataProcessing                matlab.ui.container.Panel
        uipanel4                      matlab.ui.container.ButtonGroup
        text8                         matlab.ui.control.Label
        text5                         matlab.ui.control.Label
        text16                        matlab.ui.control.Label
        text17                        matlab.ui.control.Label
        doubleStimCk                  matlab.ui.control.CheckBox
        templateCk                    matlab.ui.control.CheckBox
        text29                        matlab.ui.control.Label
        skip1trialCk                  matlab.ui.control.CheckBox
        text40                        matlab.ui.control.Label
        templExp_bt                   matlab.ui.control.Button
        templImp_bt                   matlab.ui.control.Button
        templRecalc_bt                matlab.ui.control.Button
        smooth_ck                     matlab.ui.control.CheckBox
        ROI4template_bt               matlab.ui.control.Button
        text42                        matlab.ui.control.Label
        text43                        matlab.ui.control.Label
        singleUnitsThr                matlab.ui.control.NumericEditField
        baselinePower                 matlab.ui.control.NumericEditField
        baselineFrom                  matlab.ui.control.NumericEditField
        baselineTo                    matlab.ui.control.NumericEditField
        respFrom                      matlab.ui.control.NumericEditField
        respTo                        matlab.ui.control.NumericEditField
        triggerMaxLength              matlab.ui.control.NumericEditField
        p100bef                       matlab.ui.control.NumericEditField
        p100after                     matlab.ui.control.NumericEditField
        winPWleft                     matlab.ui.control.NumericEditField
        winPWright                    matlab.ui.control.NumericEditField
        templateFrom                  matlab.ui.control.NumericEditField
        templateTo                    matlab.ui.control.NumericEditField
        BPtext                        matlab.ui.control.Label
        sliderBPpower                 matlab.ui.control.Spinner
        text32                        matlab.ui.control.Label
        DisplayPanel                  matlab.ui.container.ButtonGroup
        text4                         matlab.ui.control.Label
        notchFilter                   matlab.ui.control.CheckBox
        predefinedBW                  matlab.ui.container.ButtonGroup
        mouseCk                       matlab.ui.control.RadioButton
        zebraCk                       matlab.ui.control.RadioButton
        EEGck                         matlab.ui.control.RadioButton
        NewcastleBand                 matlab.ui.control.RadioButton
        envelopeChoice                matlab.ui.container.ButtonGroup
        text12                        matlab.ui.control.Label
        radiobutton6                  matlab.ui.control.RadioButton
        plotEnvelopeCk                matlab.ui.control.CheckBox
        filterBPpower                 matlab.ui.control.CheckBox
        envSPGfrom                    matlab.ui.control.NumericEditField
        envSPGto                      matlab.ui.control.NumericEditField
        text14                        matlab.ui.control.Label
        uipanel17                     matlab.ui.container.Panel
        uipanel18                     matlab.ui.container.Panel
        txt                           matlab.ui.control.Label
        txtspg                        matlab.ui.control.Label
        text21                        matlab.ui.control.Label
        SPGresolution                 matlab.ui.control.NumericEditField
        SPGwindow                     matlab.ui.control.NumericEditField
        SPGoverlap                    matlab.ui.control.NumericEditField
        uipanel19                     matlab.ui.container.Panel
        ChronuxAutoConfigCk           matlab.ui.control.CheckBox
        text25                        matlab.ui.control.Label
        text26                        matlab.ui.control.Label
        ChronuxRW                     matlab.ui.control.NumericEditField
        ChronuxOvl                    matlab.ui.control.NumericEditField
        text27                        matlab.ui.control.Label
        text28                        matlab.ui.control.Label
        decimateFlag                  matlab.ui.control.CheckBox
        text30                        matlab.ui.control.Label
        SPGfromFreq                   matlab.ui.control.NumericEditField
        SPGtoFreq                     matlab.ui.control.NumericEditField
        leakageLP                     matlab.ui.control.NumericEditField
        text33                        matlab.ui.control.Label
        text35                        matlab.ui.control.Label
        text36                        matlab.ui.control.Label
        text37                        matlab.ui.control.Label
        text38                        matlab.ui.control.Label
        text39                        matlab.ui.control.Label
        LPenableCK                    matlab.ui.control.CheckBox
        HPenableCK                    matlab.ui.control.CheckBox
        BP1enableCK                   matlab.ui.control.CheckBox
        BP3enableCK                   matlab.ui.control.CheckBox
        BP2enableCK                   matlab.ui.control.CheckBox
        lowerDB                       matlab.ui.control.NumericEditField
        upperDB                       matlab.ui.control.NumericEditField
        filtOrder                     matlab.ui.control.NumericEditField
        notchFrom                     matlab.ui.control.NumericEditField
        notchTo                       matlab.ui.control.NumericEditField
        lpTo                          matlab.ui.control.NumericEditField
        alphaFrom                     matlab.ui.control.NumericEditField
        alphaTo                       matlab.ui.control.NumericEditField
        betaFrom                      matlab.ui.control.NumericEditField
        betaTo                        matlab.ui.control.NumericEditField
        gammaFrom                     matlab.ui.control.NumericEditField
        gammaTo                       matlab.ui.control.NumericEditField
        hpFrom                        matlab.ui.control.NumericEditField
        ZebraAutoPanel                matlab.ui.container.Panel
        AutoSetContrasts_bt           matlab.ui.control.Button
        uipanel1                      matlab.ui.container.Panel
        AutoSpg_ck                    matlab.ui.control.CheckBox
        AutoTable_ck                  matlab.ui.control.CheckBox
        AutoTableAvg_ck               matlab.ui.control.CheckBox
        AutoTrace_ck                  matlab.ui.control.CheckBox
        uipanel3                      matlab.ui.container.Panel
        AutoFailures_ck               matlab.ui.control.CheckBox
        FailurethresholdLabel         matlab.ui.control.Label
        AutoFailThreshold_edit        matlab.ui.control.NumericEditField
        ChannelEditFieldLabel         matlab.ui.control.Label
        AutoSelCh_txt                 matlab.ui.control.NumericEditField
        AutoGo_bt                     matlab.ui.control.Button
        uipanel2_2                    matlab.ui.container.Panel
        text2                         matlab.ui.control.Label
        AutoFileNameIn_edit           matlab.ui.control.EditField
        AutoOutFileName_edit          matlab.ui.control.EditField
        text3                         matlab.ui.control.Label
        AutoMainFolder_edit           matlab.ui.control.EditField
        autoMode_bt                   matlab.ui.control.Button
        text4_2                       matlab.ui.control.Label
        nofconditionsEditFieldLabel   matlab.ui.control.Label
        AutoNConds_txt                matlab.ui.control.NumericEditField
        uipanel4_2                    matlab.ui.container.Panel
        AutoSpectra_ck                matlab.ui.control.CheckBox
        AutoWinSpectra_ck             matlab.ui.control.CheckBox
        AutoWinSpectra2_ck            matlab.ui.control.CheckBox
        AutoSpectraTr_ck              matlab.ui.control.CheckBox
        AutoSpectraAvg_ck             matlab.ui.control.CheckBox
        fromEditFieldLabel            matlab.ui.control.Label
        AutoSpectraFrom_edit          matlab.ui.control.NumericEditField
        toEditFieldLabel              matlab.ui.control.Label
        AutoSpectraTo_edit            matlab.ui.control.NumericEditField
        fromEditField_2Label          matlab.ui.control.Label
        AutoSpectraFrom2_edit         matlab.ui.control.NumericEditField
        toEditField_2Label            matlab.ui.control.Label
        AutoSpectraTo2_edit           matlab.ui.control.NumericEditField
        ZebraSaysTab                  matlab.ui.container.Tab
        outable                       matlab.ui.control.Table
        MetricsoftheentiretraceLabel  matlab.ui.control.Label
        MetricsoftheevokedresponsessingletrialsandaveragesLabel  matlab.ui.control.Label
        MetricsofasynchronouseventsburstsandseizuresLabel  matlab.ui.control.Label
        outEvochedResponses           matlab.ui.control.Table
        outEventsSummary              matlab.ui.control.Table
        EventDetectionTab             matlab.ui.container.Tab
        uipanel2_3                    matlab.ui.container.Panel
        text1                         matlab.ui.control.Label
        text2_2                       matlab.ui.control.Label
        text3_2                       matlab.ui.control.Label
        extractEvents                 matlab.ui.control.Button
        analyzeData                   matlab.ui.control.Button
        text4_3                       matlab.ui.control.Label
        gauss1param                   matlab.ui.control.Label
        gauss2param                   matlab.ui.control.Label
        text8_2                       matlab.ui.control.Label
        text9                         matlab.ui.control.Label
        replot                        matlab.ui.control.Button
        uipanel7                      matlab.ui.container.ButtonGroup
        detectionBP1                  matlab.ui.control.RadioButton
        detectionBP2                  matlab.ui.control.RadioButton
        detectionBP3                  matlab.ui.control.RadioButton
        detectionHP                   matlab.ui.control.RadioButton
        detectionSPG                  matlab.ui.control.RadioButton
        erodeCk                       matlab.ui.control.CheckBox
        uipanel3_2                    matlab.ui.container.ButtonGroup
        powerLogCk                    matlab.ui.control.RadioButton
        radiobutton2                  matlab.ui.control.RadioButton
        removeCk                      matlab.ui.control.CheckBox
        autoThr                       matlab.ui.control.CheckBox
        conMethod                     matlab.ui.container.ButtonGroup
        noConcCk                      matlab.ui.control.RadioButton
        joinCk                        matlab.ui.control.RadioButton
        joinOffsetChk                 matlab.ui.control.RadioButton
        traslateLeft                  matlab.ui.control.Button
        traslateRight                 matlab.ui.control.Button
        leftTime                      matlab.ui.control.NumericEditField
        rightTime                     matlab.ui.control.NumericEditField
        fromH                         matlab.ui.control.NumericEditField
        toH                           matlab.ui.control.NumericEditField
        binNumber                     matlab.ui.control.NumericEditField
        minDuration                   matlab.ui.control.NumericEditField
        baselineThr                   matlab.ui.control.NumericEditField
        maxInterruption               matlab.ui.control.NumericEditField
        eventThr                      matlab.ui.control.NumericEditField
        uipanel1_2                    matlab.ui.container.Panel
        histoPlot                     matlab.ui.control.UIAxes
        plotStates                    matlab.ui.control.UIAxes
        statesTrack                   matlab.ui.control.UIAxes
        saveHCD                       matlab.ui.control.Button
        uipanel4_3                    matlab.ui.container.Panel
        powerSpectra                  matlab.ui.control.UIAxes
        uipanel5_2                    matlab.ui.container.Panel
        text10                        matlab.ui.control.Label
        text11                        matlab.ui.control.Label
        savePWS                       matlab.ui.control.Button
        saveSegmentedStates           matlab.ui.control.Button
        SaveDownstSpec                matlab.ui.control.Button
        UStable                       matlab.ui.control.Table
        exportTab_btn                 matlab.ui.control.Button
        minPw                         matlab.ui.control.NumericEditField
        maxPw                         matlab.ui.control.NumericEditField
        minFreq                       matlab.ui.control.NumericEditField
        maxFreq                       matlab.ui.control.NumericEditField
    end

    properties (Access = public)
        notch % app.notch is an array with the two param
        notchOrder % order 6 for the notch filter % MIGHT BE UNUSED
        dtaComplete % Complete path and file name
        file_in % selected file name
        dir_in % directory of selected file
        expID
        fileInNum % keep track of the number of analyzed files for the output table
        eventNum % keep track of the number of analyzed events
        cnt % counter for full trace summary table
        EPcnt % counter for the summary table of evoched responses
        insetW % width of the inset window (s)
        lp % width of the low pass filter window % MIGHT BE UNUSED
        gainAmp % total gain of the ampification of the recording
        xnow
        
        sp % sampling period in s
        acqF % sampling frequency
        nCh % number of channels
        nTrials % number of trials
        currentTrial
        currentCh
        dtaLen % total length of the data
        x % Time vector
        params % structure of parameters for calculating the spectrogram
        fileTime % the time at which the file was opened
        freqN % number of frequencies of spectrogram
        
        LFP % matrix containing the raw recording. 3-D array: channel, trial, time
        bandPassed_LFP % 4-D array: band, channel, trial, time
        workLFP
        spg
        spgl
        spgt
        spgw
        spgDeleaked
        spgPlot
        spgPlotDeleaked
        meanSpgDeleaked
        meanLFP
        meanBP
        meanSpg
        meanSpgPlot
        meanPWS
        EIrat
        meanEIrat
        EPamplitude
        EIresponse
        EPoffset
        templ
        SPGlowDB
        SPGhighDB
        templateConv
        ntemp
        templatePeakSigned
        BP4power
        pwsf % Vector of frequencies for the spectra (1)
        pwsf2 % Vector of frequencies for the spectra (2)
        
        tmin
        tmax
        storedLFP
        filterOrder
        frequencyBand
        BPpowerL % 1-D array: n elements. Each i-element contains the number of "interesting" elements to be considered in BPpower for the i-band. n is the total number of bands.
        BPpower % 5-D array: band, 1=time 2=rms power, channel, trial, time
        BPpowerSP % the same as BPpowerL but for the sampling period.
        BPcomputed % logic array that indicates if each band has been calculated.
        dtaSummary
        eventSummary
        respSummary
        dtaPWsummary
        dtaPWlocalSummary
        
        minLFP
        maxLFP
        
        % Checks
        deleakedFlag
        autoMode 
        
        lpRMS
        bp1RMS
        bp2RMS
        bp3RMS
        hpRMS
        
        paramsStruct %structure for saving and loading parameters automatically
       
        % Stuff for ZebraAuto
        AutoDlgIn % The cell array containing the folders to be opened by ZebraAuto
        AutoFolders % This is the list of folders used by ZebraAuto
        
        % https://undocumentedmatlab.com/blog/property-value-change-listeners
        
        %% Variables for EventDetection
        evDet %scalar structure storing all eventDetection variables.
        
        %% Stored handles of children apps
        spectreApp %ZebraSpectre
        
    end
    
    methods (Access = private)

        function computeBPandNotch(app)
            %%
            % March 12, 2018
            % code has been modified to able/enable the BP computations
            
            % first: check if the notch filter is selected.
            if app.notchFilter.Value
                [b,a] = butter(3,2*app.notch*app.sp,'stop');    %app.notch is an array with the two param
                for i = 1:app.nCh
                    for j= 1:app.nTrials
                        tmp (1:app.dtaLen) = app.LFP (i,j,1:app.dtaLen);    %.'
                        tmp2 = filtfilt(b,a,tmp.');
                        %app.workLFP (i,j,1:app.dtaLen) = filtfilt(b,a,tmp.');
                        %app.workLFP (i,j,1:end) = filtfilt(b,a,app.LFP.');
                        app.workLFP(i,j,1:app.dtaLen) = tmp2(1:app.dtaLen);
                    end
                end
            else
                app.workLFP = app.LFP;
            end
            % computations are performed starting from the workLFP file
            
            app.filterOrder = get(app.filtOrder,'Value');
            % compute band passed data
            if app.LPenableCK.Value
                [b,a] = butter(app.filterOrder,2*app.frequencyBand(1,2)*app.sp,'low');  %Low pass filter
                computeFilteredData(app, b, a, 1);
                app.BPcomputed(1) = 1;  %GAB 06/04/2018 this line has to stay after the zebraguidata call
            end
            
            if app.BP1enableCK.Value
                [b,a] = butter(app.filterOrder,2*app.frequencyBand(2,:)*app.sp);        % band pass 1
                computeFilteredData(app, b, a, 2)
                app.BPcomputed(2) = 1;
            end
            
            if app.BP2enableCK.Value
                [b,a] = butter(app.filterOrder,2*app.frequencyBand(3,:)*app.sp);        % band pass 2
                computeFilteredData(app, b, a, 3)
                app.BPcomputed(3) = 1;
            end
            
            if app.BP3enableCK.Value
                [b,a] = butter(app.filterOrder,2*app.frequencyBand(4,:)*app.sp);        % band pass 3
                computeFilteredData(app, b, a, 4)
                app.BPcomputed(4) = 1;
            end
            
            if app.HPenableCK.Value
                [b,a] = butter(app.filterOrder,2*app.frequencyBand(5,1)*app.sp, 'high');% high pass and single units
                computeFilteredData(app, b, a, 5)
                app.BPcomputed(5) = 1;
            end
            % compute the LP data for spectral leakage correction
            if app.leakageCk.Value
                [b,a] = butter(app.filterOrder,2*app.frequencyBand(6,2)*app.sp,'low');  %Low pass filter
                computeFilteredData(app, b, a, 6)
                app.BPcomputed(6) = 1;
            end
            
            % envelope computation in beta+gamma band for the automatic ID of events
            %tmp = abs(app.bandPassed_LFP(2,:)+app.bandPassed_LFP(3,:));
            %app.envelope = abs(hilbert(tmp));
            % computer rolling average
            %app.rollingAV = sgolayfilt(tmp,3,1001);
        end
        
        function computeFilteredData(app, b, a, k)
            %% b, a are the descriptor of the filter. k is the index of filtered data
            % 1 LP; 2-4 three BP; 5 HP; 6 low pass for spectral leakage
            for i = 1:app.nCh
                tmp1 = [];
                tmp2 = [];
                for j= 1:app.nTrials
                    tmp1(1:app.dtaLen) = app.workLFP(i,j,1:app.dtaLen);    %.'
                    tmp2 = filtfilt(b,a,tmp1.');
                    app.bandPassed_LFP(k,i,j,:) = tmp2(:);
                end
            end
        end
        
        function computePowerInTime(app)
            %% May 8, 2017
            % Here we compute the short time RMS power for the four band passed data
            % The computation is performed on a window which amplitude is given by the
            % larger of 1) the time window of the SPG computation (ChronuxRW) or
            % 2) the lower period of the bandwidth.
            % The overlap is one fifth of this.
            % Therefore each STRMS plot has its own sampling, so each array contains
            % both the x and the power
            
            chronuxWin = app.ChronuxRW.Value;
            
            % First, preallocation of the array app.BPpower
            % This array has different lengths for the different bands. The max
            % length is given by the window size determined by the value of
            % ChronuxWin.
            pntWin = floor((chronuxWin/app.sp)/2);        % Cunning strategy! See later!
            pntOvl = floor(pntWin/5);
            pntWin = 2* pntOvl * 5;                           % In this way pntWin is a multiple of 10
            centerBegin = pntWin/2+1;
            BPindexes = centerBegin:pntOvl:(app.dtaLen-pntWin/2);  % this array is defined to preallocate it and to compute
            maxDim = length(BPindexes);                               % the size of the longest array
            
            % Each BP power data has its own length that is stored in the array
            % app.BPpowerL
            app.BPpowerL = zeros(5);
            % indexes of BPpower: 1) 1-> x, 2-> power; 2) band pass; 3) channel; 4)
            % trial; 6) time.
            app.BPpower = zeros(2, 5, app.nCh, app.nTrials, maxDim);
            
            % Lets cycle on the filtered data
            for iCh = 1:app.nCh
                for jTr= 1:app.nTrials
                    for BPsel=2:5
                        if app.BPcomputed(BPsel)   % perform the computation only if that BP has been computed
                            % first compute the x
                            BPindexes = 1;    % initialize the index array
                            BPindLeft = 1;
                            BPindRight = 1;
                            % compute the width of the window for power computation
                            lp = 1/app.frequencyBand(BPsel,1);      % lower limit of the bandpass
                            winT = max([lp chronuxWin]);                % for higher frequency I use the window used for SPG computation
                            pntWin = floor((winT/app.sp)/2);        % Cunning strategy! See later!
                            pntOvl = floor(pntWin/5);
                            pntWin = 2* pntOvl * 5;                     % In this way pntWin is a multiple of 10
                            pntWin2 = pntWin/2;
                            centerBegin = pntWin/2+1;
                            %pntOvl = pntWin;  %GAB!! PROVA MOMENTANEA!!!!
                            BPindexes = centerBegin:pntOvl:(app.dtaLen-pntWin2);  % this array contains the indexes of the
                                                                                      % BP data around which are computed the RMS powers
                            xBPpowerEnd = length(BPindexes);
                            app.BPpowerL(BPsel) = xBPpowerEnd;
                            if xBPpowerEnd<1, xBPpowerEnd = 1;
                                % this condition can only occur when the data is too short
                                % for the specified bandwidth. This is done to prevent an
                                % ensuing error condition, but, of course, no power can be
                                % computed in this bandwidth.
                                BPindexes(1) = app.dtaLen/2;
                                % the power plot contains a single point at the middle of
                                % the trial.
                            end
                            BPindLeft(1:xBPpowerEnd) = BPindexes(1:xBPpowerEnd)-pntWin2;    % left and right limits (in sampling points)
                            BPindRight(1:xBPpowerEnd) = BPindexes(1:xBPpowerEnd)+pntWin2;   % of the integration window
                            if (BPindLeft(1)<1), BPindLeft(1) = 1;
                            end
                            if (BPindRight(xBPpowerEnd)>app.dtaLen), BPindRight(xBPpowerEnd)=app.dtaLen;
                            end
                            % compute X axis
                            app.BPpower (1,BPsel,iCh,jTr,1:xBPpowerEnd) = app.sp * (BPindexes(1:xBPpowerEnd)-1);  % time coordinate
                            if (xBPpowerEnd>1)         % again, more controls to protect bad bandwidths
                                app.BPpowerSP(BPsel) = app.sp * (BPindexes(2)-BPindexes(1));
                            else
                                app.BPpowerSP(BPsel) = 0;
                            end
                            for timI = 1:xBPpowerEnd
                                % this loop computes the power
                %                 app.BPpower (2,BPsel,iCh,jTr,1:xBPpowerEnd) = rms...
                %                     (app.bandPassed_LFP(BPsel,iCh,jTr,BPindLeft(1:xBPpowerEnd):BPindRight(1:xBPpowerEnd)),4);
                                app.BPpower (2,BPsel,iCh,jTr,timI) = rms...
                                    (app.bandPassed_LFP(BPsel,iCh,jTr,BPindLeft(timI):BPindRight(timI)),4);
                            end
                        end
                    end
                end
            end
        end
        
    
        function concatenateTrials(app)
            app.LFP = app.storedLFP;
            app.dtaLen = size(app.LFP,3);
            app.nTrials = size(app.LFP,2);
            openFile_partII(app)
        end
        
        
        function openFile(app, selection, path_in)
            
            % this function open and process the file specified in [path_in selection]
            
            %%
            app.file_in = selection;
            app.dir_in = path_in;
            app.dtaComplete=[path_in selection];
            set(app.plotSlate,'title', app.dtaComplete);
            
            % Output to console of status and timing
            tic
            disp([num2str(toc) ' Begin to load data file'])
            % initialize all arrays
            LFPin = [];
            app.LFP = [];
            app.bandPassed_LFP = [];
            app.workLFP = [];
            app.spg = [];
            app.spgDeleaked = [];
            app.spgPlot = [];
            app.spgPlotDeleaked = [];
            app.meanLFP = [];
            app.meanBP = [];
            app.meanSpg = [];
            app.meanSpgPlot = [];
            app.meanPWS = [];
            app.EIrat = [];
            app.meanEIrat = [];
            app.EPamplitude = [];
            app.EIresponse = [];
            app.EPoffset = [];
            app.templ = [];
            
            % flags to indicate the frequency domain computations that have been
            % performed on the data set
            app.BPcomputed(1:6) = 0;
            % 1 = LP; 2-4 = BP1-3; 5 = HP; 6 = LP for leakage
            
            app.EPcnt = 0;
            app.xnow = -100;
            exCh = get(app.excludeCh,'Value');
            sCh = get(app.synchCh,'Value');
            app.deleakedFlag = 0;       % this flag is set to 1 if SPGs have been deleaked.
            
            % Now the processing pipelines differentiate depending on the input mode.
            flagN = app.normalDataRB.Value;
            flagL = app.lauraEEG.Value;
            flagMV = app.meyerVepRB.Value;
            flagSSP = app.meyerSspRB.Value;
            flagAxP = app.axopatchCk.Value;
            flagTRC = app.TRCimportRB.Value;
            flagMEA = app.meaRB.Value;
            flagEx = app.excludeChFlag.Value;
            flagExSynch =  app.internalSynchCk.Value;
            flagSkipFirstTrial = app.skip1trialCk.Value;
            
            % This function opens a specified file either in ASCII or in binary format.
            % the variable nCh holds the number of channels present in the data file
            % the resulting LFP data will be a matrix of nSweeps columns.
            %%
            
            %% TRC data import
            if flagTRC
                % open the dialogue window for TRC data import
                % The TRCimport must be opened by passing the file path and it returns the structure TRC that contina
                % the data, all the associated info and the events.
                TRCoutput = cell([40 1]);
                [TRCoutput{1:40}] = importTRCfilesOptions(app.dir_in,app.file_in,app.dtaComplete);
                disp([num2str(toc) ' End of TRC import'])
                %TRCimportData = zebraguidata (TRCimportDialogue);
                % let's fill the data structure with the imported data
                set(app.microVck,'Value',1);   % select microV unit
            %         setGain (hObject, handles, 1000);
                app.expID = TRCoutput{1};
                nCh = TRCoutput{9};
                app.SPGlowDB = -80;                             % set limits for spectrogram plot
                set(app.lowerDB,'Value',app.SPGlowDB)
                app.SPGhighDB = 0;
                set(app.upperDB,'Value',app.SPGhighDB)
            
                setCh(app, nCh)
                app.dtaLen = TRCoutput{11};
                acqF = TRCoutput{12};
                setSP (app, acqF);
                app.nTrials = length(TRCoutput{26}) - 1;     % if no events nTrials = -1 !
                LFPin = zeros(app.nCh, app.dtaLen);      % preallocation of LFPin
                LFPin = TRCoutput{16};
                if (app.nTrials>1)
                    set (app.skip1trialCk,'value',0);       % no need to skip the first trial
                    stimTrigger (1:app.nTrials) = 0;        % preallocation of trigger
                    for j=1:app.nTrials
                        stimTrigger (j) = TRCoutput{26}(j+1).latency;
                        % the first trigger is skipped because it is associated to the
                        % beginning of the equiluminant grey and not to a chekerboard stimulus.
                    end
                    %Compute the inter-trial distance.
                    trialInterdistance (1:app.nTrials-1) = stimTrigger(2:app.nTrials)-stimTrigger (1:app.nTrials-1);
                    % Lets find the minimal inter trial distance
                    minLen = min(trialInterdistance);      % this will be the new app.dtaLen
                    % Let's extract the trials from the available data channels.
                    disp([num2str(toc) ' Extract trials from the data channels.'])
                    
                    % Initialize the variable size ENRICO 11/12/2018
                    temp = zeros(app.nCh, app.nTrials, minLen);
                    
                    for i=1:app.nCh
                        for j=1:app.nTrials
                            temp(i,j,1:minLen) = LFPin(i,stimTrigger(j):stimTrigger(j)+minLen-1);
                        end
                    end
                    app.LFP = [];
                    app.LFP = temp;
                    app.dtaLen = minLen;
                else
                    app.nTrials = 1;
                    app.LFP(1:app.nCh,1,1:app.dtaLen) = LFPin(1:app.nCh,1:app.dtaLen);
                end
            else
                % create an handle for the file opened in read mode
                fid=fopen(app.dtaComplete,'r');
            end
            %%
            
            %% Import NI or Axiopatch data
            if (flagN || flagL || flagAxP)
                % This is the default mode. Data from the NI or Axon board. nCh channels and only 1
                % trial. The trigger channel is included. The number of channels must
                % be defined at priori only for the NI boards
                set(app.mVck,'Value',1);
                if (flagAxP)
                    % in this modality data are generated by the Axopatch program and
                    % are saved as binary data. sp and Ch number obtained from data header
                    disp([num2str(toc) ' Begin loading of Axopatch data'])
                    [LFPin,si,h] = abfload(app.dtaComplete);
                    % si    scalar           the sampling interval in micro s
                    % h     struct           information on file (selected header parameters)
                    % number of channels
                    LFPin = LFPin';    % abfloat return the channels as columns. Traspose to set them as rows
                    setGain(app, 1000)
                    setSP(app, 1/si*1000000)
                    setCh(app, h.nADCNumChannels)
                    app.dtaLen = h.sweepLengthInPts;
                else
                    if flagL    % set some parameters specific to Laura's mode.
                        setSP(app, 512)
                        setGain(app, 5000)
                        setCh(app, 2)
                        set(app.synchCh, 'Value', 1)
                        set(app.SPGwindow,'Value',16)
                        set(app.SPGoverlap,'Value',2)
                    else
                        set(app.SPGwindow,'Value',256)
                        set(app.SPGoverlap,'Value',16)
                        app.nCh = get(app.chEdit,'Value');
                    end
                    if flagL
                        disp([num2str(toc) ' Begin loading of IN EEG data.'])
                        % this is required because these data use a ',' as separator of the
                        % decimal part. How stupid is this???
                        c = textscan(fid,'%s');
                        l = size(c{1});
                        app.dtaLen = l(1)/2-1;  % remove first row
                        LFPin = zeros(app.nCh,app.dtaLen);
                        for i=1:app.dtaLen
                            % replace the comma with the decimal point
                            for j=1:app.nCh
                                runningIndex = 2 + app.nCh * (i-1) + j;
                                LFPin (j,i) = str2double(strrep(c{1}{runningIndex},',','.'));
                            end
                        end
                    else
                        disp([num2str(toc) ' Begin loading of National Instruments EEG data.'])
                        % Create the string describing the data format
                        fmtSt='';
                        for ii=1:app.nCh
                           fmtSt = [fmtSt '%f'];
                        end
                        LFPin = fscanf(fid,fmtSt,[app.nCh,inf]);
                        l = size(LFPin);
                        app.dtaLen = l(2);
                    end
                end
                %% the three submode of DEFAULT mode continue from here
                app.nTrials = 1;    % in this open mode there is only one trial unless
                % the trials are extracted later on by using the trigger channel
                app.SPGlowDB = -80;
                set(app.lowerDB,'Value',app.SPGlowDB)
                app.SPGhighDB = 0;
                set(app.upperDB,'Value',app.SPGhighDB)
                if flagEx && ~flagExSynch    % if ext synch the channel is excluded only from the output
                    if (app.nCh<=1),app.nCh=2;   % This situation occurs if the original data has 2 channels
                    end                                  % and one forgets to restore the ch number
                    % remove one channel
                    cnt = 0;
                    for i=1:app.nCh
                        if (i~= exCh), cnt = cnt+1;
                            app.LFP (cnt,1,:) = LFPin(i,:);
                        end
                    end
                    app.nCh = app.nCh-1;
                else
                    app.LFP (1:app.nCh,1,:) = LFPin(1:app.nCh,:);
                end
                if flagExSynch
                   disp([num2str(toc) ' Extract single trials from a multitrial data set.'])
                   maxTrigger = get(app.triggerMaxLength,'Value');
                   indexTrials = [];
                   trialInterdistance = [];
                   temp = [];
                   % first, analyze the synch channel to extract the timing of each trial
                   % binarize the trigger channel
                   for i=1:app.dtaLen
                       if (app.LFP(sCh,1,i)<1), app.LFP (sCh,1,i) = 0;
                       else
                           app.LFP(sCh,1,i) = 10;
                       end
                   end
                   [sortedSynch,sortedInd] = sort(app.LFP (sCh,1,:),'descend');
                   % the indexes should be clusterized and the <t> of each cluster will
                   % give the timing of each stimulus. What is left is easy.
                   % Parse the sortedInd vector. A trigger event is represented by a cluster of
                   % neighboring indexes.
                   j = 1;   % index running on the sorted arrays
                   flg = get(app.skip1trialCk,'Value');
                   trialCounter = 0;
                   sortI = sortedInd(j);
                   oldSortI = 1;
                   oldJ = 0;
                   while sortedSynch(j)>1
                       % The code handles two different trigger signals
                       % The <yy 2018 implementation of Escher generates the trigger through
                       % the DTR line of the RS232 serial port with levels on= +10V off= -10V
                       % Later build wil use a digital output producing the ordinary TTL signal
            
                       % Legality of the trigger event
                       % A trigger event is legal if it meets 3 conditions:
                       % 1) it is longer than 30 sampling periods
                       % 2) it is at least 100 sp far away from the previous trigger event
                       % 3) it is shorter than triggerMaxLength (still in SP units)
            
            %           if (sum(sortedSynch(j:j+2))>29 && sortI>(oldSortI+100))
                       if (sortI>(oldSortI+100) || trialCounter==0)
                           % if this condition is true the new event is at least 100 sp away
                           % from the previous event, or it is the first event
                           % now we must measure its length
                           len = 1;
                           runningJ = j;
                           while sortedInd(runningJ+1) == sortedInd(runningJ)+1
                               % we are counting the SPs in the event
                               len = len + 1;
                               runningJ = runningJ+1;
                           end
                           % now test the event for legality
                           if (len>2 && len<maxTrigger)
                               % event is legal
                               trialCounter = trialCounter + 1;
                               indexTrials(trialCounter) = sortI;
                               if (trialCounter>1)
                                   if flg          % if true ignore the first trial
                                       if (trialCounter>2)
                                           % ignore the first trial during the computation
                                           % of the trial duration
                                           trialInterdistance(trialCounter-2) = sortI-indexTrials (trialCounter-1);
                                       end
                                   else
                                       trialInterdistance(trialCounter-1) = sortI-indexTrials (trialCounter-1);
                                   end
                               end
                               oldSortI = sortI;
                           end
                           j = runningJ;
                       end
                       j = j+1;
                       sortI = sortedInd(j);
                   end
                   % Lets find the minimal inter trial distance
                   minLen = min(trialInterdistance); % this will be the new app.dtaLen
                   % Let's extract the trials from the available data channels.
                   trTimeOffset = app.trTimeOffset_txt.Value; %offset in seconds GAB 30/10/2018
                   trTimeOffset_i = round(trTimeOffset/app.sp);
                   for i=1:app.nCh
                        skipped = 0;
                        for j=1:trialCounter
                            if (indexTrials(j)+minLen-1)>app.dtaLen
                                % bad synch: the data finishes before of the end of the
                                % last sweep
                                trialCounter = trialCounter - 1;
                            else
                                if (indexTrials(j)+trTimeOffset_i) > 0
                                    temp(i,j-skipped,1:minLen) = app.LFP(i,1,indexTrials(j)+trTimeOffset_i:indexTrials(j)+minLen-1+trTimeOffset_i); %GAB 30/10/2018 added +trTimeOffset_i
                                else
                                    skipped = skipped + 1;
                                end
                            end
                        end
                   end
                   app.LFP = [];
                   app.LFP = temp;
                   app.nTrials = trialCounter-skipped;
                   app.expID = '';
                   app.dtaLen = minLen;
                end
                % Close the data file
                fclose(fid);
            end
            %%
            
            %% Import Meyer ascii VEP
            if (flagMV)
                disp([num2str(toc) ' Begin loading of Meyer ASCII data.'])
                setSP(app, 2048/get(app.SweepTimeEdit,'Value')) %GABRIELE 2018/01/27; frequency is calculated as trialPoints/trialTime
                % since the SP has been likely changed, the frequencies of the
                % bandwidht filters should be taken care of
                setGain(app, 1000)
                set(app.microVck,'Value',1)
                %setCh (hObject, handles, 3);
                numCh = app.chEdit.Value; %GABRIELE 2018/01/27; allows to open file whith any number of channels
                setCh(app, numCh)
                app.dtaLen = 2048;
                set(app.SPGwindow,'Value','32')
                set(app.SPGoverlap,'Value','2')
                c = textscan(fid,'%s');
                app.expID = [c{1}{1} ' ' c{1}{2}];
                % create an array with the indexes of all cells that contain the string
                % '2048'. The data will begin immediately after the last cell pointed by the
                % index array. The length of the array is equal to the number of trials*3.
                index = find(strcmp(c{:}, '2048'));
                l = size(index);
                app.nTrials = l(1)/numCh;
                startC = index(app.nTrials*numCh)+1;
                [endC,g] = size(c{:});
            
                % loop on all points of each trial
                for (j=1:app.dtaLen)    %GABRIELE 2018/01/27; allows to open file whith any number of channels
                    % loop on all trials
                    for i=1:app.nTrials
                        % run on the entire cell array
                        runningIndex = startC + (j-1)*app.nTrials*numCh + (i-1)*numCh;
                        % first, replace all the ',' with the decimal point
                        for k=1:numCh
                            app.LFP (k,i,j) = str2double(strrep(c{1}{runningIndex+k-1},',','.'));
                        end
                    end
                end
                % Close the data file
                fclose(fid);
            end
            %%
            
            %% Load somatosensory potentials
            if (flagSSP)
                % Better be safe...
                setSP(app, 32768);
                setGain(app, 1000);
                set (app.microVck,'Value',1);
                c = textscan(fid,'%s');
                app.expID = [c{1}{1} ' ' c{1}{2}];
                % create an array with the indexes of all cells that contain the string
                % '1639'. The data will begin immediately after the last cell pointed by the
                % index array. The length of the array is equal to the number of trials*3.
                index = find(strcmp(c{:}, '1639'));
                l = size(index);
                app.nCh = l(1);
                app.nTrials = 1;
                app.dtaLen = 1639;
            
                % startC point to the beginning of the data
                startC = index(app.nCh)+1;
                [endC,g] = size(c{:});
            
                % loop on all points of each trial
                for j=1:app.dtaLen
                    % loop on all channels
                    for i=1:app.nCh
                        % run on the entire cell array
                        runningIndex = startC + (j-1)*app.nCh + (i-1);
                        app.LFP (i,1,j) = str2double(strrep(c{1}{runningIndex},',','.'));
                    end
                end
                % Now remove the artefact that sometimes pops out at the very beginning of the record
                clsW = int32(app.dtaLen/10);
                fixIt(1:app.nCh) = app.LFP(1:app.nCh,1,clsW);
                for k=1:app.nCh
                    app.LFP(k,1,1:clsW) = fixIt(k);
                end
                app.workLFP = app.LFP;    % Copied here since there is nothing to filter in these data
                % Close the data file
                fclose(fid);
            end
            %%
            
            %% Open IMM mea data
            if (flagMEA)
                disp([num2str(toc) ' Begin loading of IMM MEA data.'])
                setGain(app, 1000);
                % the data are contained in a structure called channelPlotData
                load(app.dtaComplete)
                app.nCh = 32;
                set (app.chEdit,'Value','32');
            
                name = fieldnames(channelPlotData);
                % extract the sampling period from channel0 data structure
                sp = channelPlotData.channel0.time(1,2);
                % NOTE: this SP is only approximate. THIS SHOULD BE FIXED!
                sp = 0.0001003394;
                setSP(app, 1/sp)
                % preallocate the input array
                fullDataSet = zeros(app.nCh,1,length(channelPlotData.(name{1}).value));
                % load all the channels
                for i=1:app.nCh
                    % run on the entire cell array
                    tmp = channelPlotData.(name{i}).value;
                    fullDataSet (i,1,:) = tmp(:);
                end
            
                if flagExSynch
                    disp([num2str(toc) ' Extract single trials from a multi-trials data set.'])
                    % extract the single trials
                    tmp = [];
                    stimOrigin = channelPlotData.Stimulation.time(1,6);
                    traceOrigin = channelPlotData.channel0.time(1,1);
                    stimTimes = channelPlotData.Stimulation.time(:,6) - traceOrigin;
                    app.nTrials = length(stimTimes);
                    intervals = stimTimes(2:app.nTrials)-stimTimes(1:app.nTrials-1);
                    app.dtaLen = floor(min(intervals)/app.sp + 1);
                    pnt = app.dtaLen;
                    % prealocate LFP
                    app.LFP = zeros(app.nCh,app.nTrials,pnt);
                    % now extract the data segments
                    for j=1:app.nTrials
                       beginIndex = floor(stimTimes(j)/app.sp + 1);
                       for i=1:app.nCh
                            app.LFP(i,j,1:pnt) = fullDataSet (i,1,beginIndex:beginIndex+pnt-1);
                            % filter the initial and final transient (trigger artifact)
                            app.LFP(i,j,1:10) = app.LFP(i,j,20:-1:11);
                            app.LFP(i,j,pnt:-1:pnt-30) = app.LFP(i,j,pnt-30:-1:pnt-60);
                       end
                    end
                else
                    app.LFP = fullDataSet;
                    app.nTrials = 1;
                    app.dtaLen = length(app.LFP);
                end
                app.expID = '';
            end
            
            %% gab&Enrico trial concatenation
            if app.concat_ck.Value
                if isempty(app.storedLFP) %ricordiamoci di inizializzarlo nella OpeningFunction
                    app.storedLFP = app.LFP(:,flagSkipFirstTrial+1:end,:);
                else
                    storeDim = size(app.storedLFP);
                    if storeDim(1) ~= app.nCh %quality control
                        error('Mismatch in number of channels')
                    end
                    storeLen = min([storeDim(3), app.dtaLen]);
                    app.storedLFP = cat(2, app.storedLFP(:,:,1:storeLen), app.LFP(:,flagSkipFirstTrial+1:end,1:storeLen));
                end
            else
                app.storedLFP = [];
            end
            
            if app.analyzeWhileConc_ck.Value
                concatenateTrials(app)
            elseif ~app.concat_ck.Value
                openFile_partII(app)
            end
            %% end of specific loading routines
            % -----------------------------------------------------------------------------------------
            disp([num2str(toc) ' End of data loading.'])
        end
        
        function openFile_partII(app)
            
            %%--flag copied from OpenFile function-----------------------------------
            exCh = app.excludeCh.Value;
            sCh = app.synchCh.Value;
            
            % Now the processing pipelines differentiate depending on the input mode.
            flagN = app.normalDataRB.Value;
            flagL = app.lauraEEG.Value;
            flagMV = app.meyerVepRB.Value;
            flagSSP = app.meyerSspRB.Value;
            flagAxP = app.axopatchCk.Value;
            flagTRC = app.TRCimportRB.Value;
            flagMEA = app.meaRB.Value;
            flagEx = app.excludeChFlag.Value;
            flagExSynch =  app.internalSynchCk.Value;
            %%-----------------------------------------------------------------------
            
            % now set the values for the ch and trial sliders based on the values
            % defined in the preceding code. If ch and trials are = 1 the control is
            % not updated and is made not visible.
            app.trialsEdit.Value = app.nTrials;
            app.currentTrial = 1;
            %set (app.chEdit,'String',app.nCh);
            app.currentCh = 1;
            if app.nTrials>1
                app.trialSlider.Limits = [1 app.nTrials];
                app.trialSlider.Value = 1;
                app.trialSlider.Visible = 'on';
                app.trialText.Visible = 'on';
                app.trialText.Text = ['Trial ' num2str(app.currentTrial)];
            else
                app.trialSlider.Visible = 'off';
                app.trialText.Visible = 'off';
            end
            
            if app.nCh>1 % If the number of channels >1, the channel slider must be shown, together with a text that states the current channel
                app.chSlider.Limits = [1 app.nCh];
                app.chSlider.Value = 1;
                %set(app.chSlider, 'SliderStep', [1/(app.nCh-1) 1/(app.nCh-1)])
                app.chSlider.Visible = 'on';
                app.ChText.Visible = 'on';
                app.ChText.Text = ['Ch ' num2str(app.currentCh)];
                % set the slider control in the PWpower distribution figure
                if app.analyzePWdistributionCk.Value
                    app.analyzePWdata.selectCh.Max = app.nCh;
                    app.analyzePWdata.selectCh.Value = 1;
                    app.analyzePWdata.selectCh.SliderStep = [1/(app.nCh-1) 1/(app.nCh-1)];
                    app.analyzePWdata.selectCh.Visible = 'on';
                    app.analyzePWdata.selectedChTxt.Visible = 'on';
                    app.analyzePWdata.selectedChTxt.String = ['Ch ' num2str(app.currentCh)];
                end
            else
                app.chSlider.Visible = 'off';
                app.ChText.Visible = 'off';
                if app.analyzePWdistributionCk.Value
                    app.analyzePWdata.selectedChTxt.Visible = 'off';
                    app.analyzePWdata.selectCh.Visible = 'off';
                end
            end
            
            
            app.fileInNum = app.fileInNum + 1;
            drawnow    % refresh the GUI
            cfactor = 1000/app.gainAmp;      % conversion factor to express LFP in mV
            
            %dtaLen = size(app.LFP);
            %app.dtaLen = dtaLen(3);
            
            
            app.x = 0:app.sp:double(app.sp*(app.dtaLen-1));
            app.LFP = app.LFP * cfactor;            % scale the LFP for the relative gain
            % set t axes
            app.tmin = 0;
            app.tmax = app.dtaLen*app.sp;
            app.tminTxt.Value = app.tmin;
            app.tmaxTxt.Value = app.tmax;
            
            % break point here if you want to access the loaded data before computing the
            % band-passed data set.
            if ~flagSSP
                disp([num2str(toc) ' Begin computation of band passed data.'])
                computeBPandNotch(app)   % elements of handles will be modified at this call
                computePowerInTime(app)   % temporal profile of power
            end
            
            % here I have learned an important lesson: handles are passed by values:
            % any changes on the structure operated in called function IS NOT returned
            % back to the calling function. If the changes are saved to the zebraguidata
            % object, the new content of the object has to be loaded in order to update
            % the app.
            
            
            disp([num2str(toc) ' Compute the data spectrograms.'])
            computeSpectrogram(app)
            
            % ---------------------------------------------------------------------------------------------------------------------
            
            disp([num2str(toc) ' Passing data to the active external processor.'])
            % now verify whether the external processors are active and, if they are, call
            % them up passing them the proper data.
            
            if app.powerSpectraCk.Value
                % call the ZebraSpectre code for spectra computation
                % we are here after opening a new data. Set the proper default values in the ZebraSpectre GUI
                app.spectreApp.fromTtxt.Value = 0;      % beginning of the time window for spectre computation
                app.spectreApp.toTtxt.Value = app.tmax;
                % end of the time window for spectre computation
            
                % ZebraSpectre('computeSpectra_Callback',app.spectreData.computeSpectra,[],app.spectreData)
                %computePowerSpectra (hObject, handles);
                app.spectreApp.computeSpectra_function() % Calculate and plot the spectrum
            end
            
            if app.analyzePWdistributionCk.Value
                analyzePowerDistribution(app)
            end
            
            if app.nTrials > 1
                disp([num2str(toc) ' Compute averages on trials.'])
                % compute al relevant averages. First, mean LFP
                % compute mean band passed data
                % compute mean spectrogram and 'gamma' band power
                computeMeanOnTrials(app)
            end
            
            % Insert a breakpoint here if you want to access directly to the averaged
            % trials. app.meanLFP (nCh, 1:app.dtaLen)
            
            
            disp([num2str(toc) ' Plot data.'])
            plotWideWindow(app,[])
            plotMultiBand(app)
            plotSpectrogram(app)
            
            disp([num2str(toc) ' Create summary table.'])
            % SUMMARY TABLE
            % compute the total RMS power in the five bands
            if ~flagSSP
                app.lpRMS = 0;
                app.bp1RMS = 0;
                app.bp2RMS = 0;
                app.bp3RMS = 0;
                app.hpRMS = 0;
                for i = 1:app.nCh
                    if ~(flagExSynch && i==sCh) % if ext synch the channel is excluded from the output
                        if ~(flagExSynch && flagEx && i==exCh) % exclude channel
                            for j = 1:app.nTrials
                                app.cnt = app.cnt + 1;
                                if app.BPcomputed(1)
                                    tmp(1:app.dtaLen) = app.bandPassed_LFP(1,i,j,1:app.dtaLen);
                                    app.lpRMS = rms(tmp);
                                end
                                if app.BPcomputed(2)
                                    tmp(1:app.dtaLen) = app.bandPassed_LFP(2,i,j,1:app.dtaLen);
                                    app.bp1RMS = rms(tmp);
                                end
                                if app.BPcomputed(3)
                                    tmp(1:app.dtaLen) = app.bandPassed_LFP(3,i,j,1:app.dtaLen);
                                    app.bp2RMS = rms(tmp);
                                end
                                if app.BPcomputed(4)
                                    tmp(1:app.dtaLen) = app.bandPassed_LFP(4,i,j,1:app.dtaLen);
                                    app.bp3RMS = rms(tmp);
                                end
                                if app.BPcomputed(5)
                                    tmp(1:app.dtaLen) = app.bandPassed_LFP(5,i,j,1:app.dtaLen);
                                    app.hpRMS = rms(tmp);
                                end
                                % now we place this data in a summary table that will be loaded with a
                                % number of fun fact about the data...
                                % data must be converted to the cell format in the following statements
            
                                app.dtaSummary(app.cnt,1) = cellstr(app.dir_in);
                                app.dtaSummary(app.cnt,2) = cellstr(app.file_in);
                                if app.autoMode
                                    app.dtaSummary(app.cnt,3) = cellstr('0');
                                else
                                    app.dtaSummary(app.cnt,3) = cellstr(app.fileTime);
                                end
                                app.dtaSummary(app.cnt,4) = num2cell(i);
                                app.dtaSummary(app.cnt,5) = num2cell(j);
                                app.dtaSummary(app.cnt,6) = num2cell(app.lpRMS);
                                app.dtaSummary(app.cnt,7) = num2cell(app.bp1RMS);
                                app.dtaSummary(app.cnt,8) = num2cell(app.bp2RMS);
                                app.dtaSummary(app.cnt,9) = num2cell(app.bp3RMS);
                                app.dtaSummary(app.cnt,10) = num2cell(app.hpRMS);
                                app.dtaSummary(app.cnt,11) = cellstr('Comments here');
                            end
                        end
                    end
                end
                app.outable.Data = app.dtaSummary;
            else
                % Put here something if you want to compute 'some' metric of the SSP
                % power...
            end
        end
        
        function outBPpowers(app, bpSel, timeFrom, timeTo, fileName)
            
            % process BP1
            i_from = floor((timeFrom-5*app.BPpowerSP(bpSel)) / app.BPpowerSP(bpSel));
            if (i_from <=1), i_from = 1;
            end
            i_to = ceil((timeTo-5*app.BPpowerSP(bpSel)) / app.BPpowerSP(bpSel));
            if (i_to > length(app.BPpower)), i_to = length(app.BPpower);
            end
            
            tmpOut = [];
            fmtSt = [];
            % the time axis is identical for all channels
            tmpOut(1,1:i_to-i_from+1) = ...
                app.BPpower (1,bpSel,1,app.currentTrial,i_from:i_to);
            % iterate on all channels
            for ii=1:app.nCh      % add 1 for the x axis.
                tmpOut(ii+1,1:i_to-i_from+1) = ...
                    app.BPpower (2,bpSel,ii,app.currentTrial,i_from:i_to);
            end
            % prepare for output
            for ii=1:app.nCh      % add 1 for the x axis.
               fmtSt=[fmtSt '%10.8f '] ;
            end
            fmtSt=[fmtSt '%10.8f\n'];       % start new line
            fileOut = [app.dir_in fileName];
            fid = fopen(fileOut,'w');
            fprintf(fid,fmtSt,tmpOut)
            fclose(fid);
        end
        
    
        function plotMultiBand(app)
            % Here we plot the multi band window according to the selected bands
            flagSSP = app.meyerSspRB.Value;
            
            if (~flagSSP)
                tmp = [];
%                axes(app.wide_plot_HP)
                cla(app.wide_plot_HP)
                hold(app.wide_plot_HP, 'on')
                flagTr = app.plotTrCk.Value;
                flagMd = app.plotMeanFlag.Value;
                flag = app.lpcheck.Value;
                if app.lpcheck.Value && app.BPcomputed(1)
                    if flagTr
                        tmp(1:app.dtaLen) = app.bandPassed_LFP(1,app.currentCh, app.currentTrial, 1:app.dtaLen);
                        plot(app.wide_plot_HP, app.x(1:app.dtaLen),tmp(1:app.dtaLen),'Color','k','LineWidth',0.5);
                    end
                    if flagMd
                        tmp(1:app.dtaLen) = app.meanBP(1, app.currentCh,1:app.dtaLen);
                        plot(app.wide_plot_HP, app.x(1:app.dtaLen),tmp(1:app.dtaLen),'Color','k','LineWidth',2);
                    end
                end
            
                if app.bpCheck1.Value && app.BPcomputed(2)
                    if flagTr
                        tmp(1:app.dtaLen) = app.bandPassed_LFP(2,app.currentCh, app.currentTrial, 1:app.dtaLen);
                        plot(app.wide_plot_HP, app.x(1:app.dtaLen),tmp(1:app.dtaLen),'Color','c','LineWidth',0.5);
                    end
                    if flagMd
                        tmp(1:app.dtaLen) = app.meanBP(2, app.currentCh,1:app.dtaLen);
                        plot(app.wide_plot_HP, app.x(1:app.dtaLen),tmp(1:app.dtaLen),'Color','c','LineWidth',2);
                    end
                end
            
                if app.bpCheck2.Value && app.BPcomputed(3)
                    if flagTr
                        tmp(1:app.dtaLen) = app.bandPassed_LFP(3,app.currentCh, app.currentTrial, 1:app.dtaLen);
                        plot(app.wide_plot_HP, app.x(1:app.dtaLen),tmp(1:app.dtaLen),'Color','r','LineWidth',0.5);
                    end
                    if flagMd
                        tmp(1:app.dtaLen) = app.meanBP(3, app.currentCh,1:app.dtaLen);
                        plot(app.wide_plot_HP, app.x(1:app.dtaLen),tmp(1:app.dtaLen),'Color','r','LineWidth',2);
                    end
                end
            
                if app.bpCheck3.Value && app.BPcomputed(4)
                    if flagTr
                        tmp(1:app.dtaLen) = app.bandPassed_LFP(4,app.currentCh, app.currentTrial, 1:app.dtaLen);
                        plot(app.wide_plot_HP, app.x(1:app.dtaLen),tmp(1:app.dtaLen),'Color','g','LineWidth',0.5);
                    end
                    if flagMd
                        tmp(1:app.dtaLen) = app.meanBP(4, app.currentCh,1:app.dtaLen);
                        plot(app.wide_plot_HP, app.x(1:app.dtaLen),tmp(1:app.dtaLen),'Color','g','LineWidth',2);
                    end
                end
            
                if app.hpCheck.Value && app.BPcomputed(5)
                    if flagTr
                        tmp(1:app.dtaLen) = app.bandPassed_LFP(5,app.currentCh, app.currentTrial, 1:app.dtaLen);
                        plot(app.wide_plot_HP, app.x(1:app.dtaLen),tmp(1:app.dtaLen),'Color','blue');
                    end
                    if flagMd
                        tmp(1:app.dtaLen) = app.meanBP(5, app.currentCh,1:app.dtaLen);
                        plot(app.wide_plot_HP, app.x(1:app.dtaLen),tmp(1:app.dtaLen),'Color','b','LineWidth',2);
                    end
                end
                axis(app.wide_plot_HP, [app.tmin app.tmax -inf inf])
                app.wide_plot_HP.XTick = [];
                flagUnits = app.microVck.Value;
                if flagUnits, app.wide_plot_HP.YLabel.String = 'LFP (microV)';
                else
                    app.wide_plot_HP.YLabel.String = 'LFP (mV)';
                end
            end
        end
        
        function plotWideWindow(app, event)
            %% The plot function requires a column vector. If the data is a matrix, the X axis moves
            % along the row. If more than n>1 columns are present, the function will
            % plot the various data in different colours.
            
            % Find out the index of the left and right limits of the plot window
            tMin = app.tminTxt.Value;
            tMax = app.tmaxTxt.Value;
            % convert the limits to array indexes
            tMinI = floor(tMin/app.sp+1);
            tMaxI = floor(tMax/app.sp+1);
            % quality control
            if tMinI <= 1, tMinI = 1;
            end
            
            if tMaxI > app.dtaLen, tMaxI = app.dtaLen;
            end
            
            app.minLFP = min(app.LFP(app.currentCh, app.currentTrial,tMinI:tMaxI));
            app.maxLFP = max(app.LFP(app.currentCh, app.currentTrial,tMinI:tMaxI));
            traceOut = [];
            
            % axes(app.wide_plot_up)
            cla(app.wide_plot_up)
%            app.wide_plot_up.ColorOrderIndex = 0; % Not sure what this is for. Enrico 11/10/2018
            hold(app.wide_plot_up, 'on')
            
            flagMd = app.plotMeanFlag.Value;     % plot mean trace
            flagMP = app.plotMultiCh.Value;      % plot all channels
            flagTr = app.plotTrCk.Value;         % plot single trials
            flagAutoOff = app.autoOffCk.Value;
            flagTempl = app.plotTemplateCk.Value;
            
            if (flagAutoOff==0), range = 0;  % take this out if you want to auto-offset the averages
            else
                range = (app.maxLFP-app.minLFP)/3;
            end
            
            if flagMP       % plot all channels
                % if multi channel no template!
                % if flagTr    Not sure what this flag stand for...
                traceOut(1:app.nCh,1:app.dtaLen) = app.LFP(1:app.nCh,app.currentTrial,1:app.dtaLen);
                for i=1:app.nCh      % add a vertical offset
                    traceOut(i,1:app.dtaLen) = (i-1)*range + traceOut(i,1:app.dtaLen);
                end
                % next: plot multiplot with the same colors
                app.wide_plot_up.ColorOrderIndex = 1;
                app.wide_plot_up.NextPlot = 'add';
                if flagTr   % plot the individual trials
                    % the additional properties are necessary to stop the plot to
                    % intercepts the mouse clicks
                    plot(app.wide_plot_up, app.x(tMinI:tMaxI),traceOut(:, tMinI:tMaxI), 'HitTest', 'off');
                end
                if (flagMd && app.nTrials > 1)  % plot all means with offset
                    traceOut(1:app.nCh,1:app.dtaLen) = app.meanLFP(1:app.nCh,1:app.dtaLen);
                    for (i=1:app.nCh)
                        traceOut(i,1:app.dtaLen) = (i-1)*range + traceOut(i,1:app.dtaLen);
                    end
                    app.wide_plot_up.ColorOrderIndex = 1;
                    plot(app.wide_plot_up, app.x(tMinI:tMaxI),traceOut(:, tMinI:tMaxI),'LineWidth',2, 'HitTest', 'off');
                end
            
                % next: plot multiplot with the same colors
                %axx = gca;
            else
                if flagTr   % plot the individual trials
                    traceOut(1:app.dtaLen) = app.LFP(app.currentCh,app.currentTrial,1:app.dtaLen);
                    plot(app.wide_plot_up, app.x(tMinI:tMaxI),traceOut(tMinI:tMaxI),'Color','k', 'HitTest', 'off');
                end
                if (flagMd && app.nTrials > 1)
                    plot(app.wide_plot_up, app.x(tMinI:tMaxI),app.meanLFP(app.currentCh,(tMinI:tMaxI)),'Color','k',...
                        'LineWidth', 2, 'HitTest', 'off');
                end
                if (flagTempl && app.nTrials>1 && flagTr)
                    % plot the template for each response with the correct scaling
                    % factor and offset
                    l = length(app.templ);
                    t0 = app.templateFrom.Value;
                    for ii= 1:app.ntemp
                        a = app.EPamplitude(app.currentCh,app.currentTrial,ii);
                        templ = a * app.templ + app.EPoffset(app.currentCh,app.currentTrial,ii);
                        x0 = t0 + app.dtaLen*app.sp/2 * (ii-1);
                        xend = x0 + l*app.sp;
                        xtempl = x0:app.sp:xend;
                        plot (app.wide_plot_up, xtempl(1:l), templ(1:l),'LineWidth',2, 'HitTest', 'off');
                    end
                end
            end
            flagUnits = app.microVck.Value;
            if flagUnits, app.wide_plot_up.YLabel.String = 'LFP (microV)';
            else
                app.wide_plot_up.YLabel.String = 'LFP (mV)';
            end
            if isempty(event)
                axis(app.wide_plot_up, [tMin tMax -inf inf]);
                app.ymaxTxt.Value = app.wide_plot_up.YLim(2);
                app.yminTxt.Value = app.wide_plot_up.YLim(1);
            else
                axis(app.wide_plot_up, [tMin tMax event.yLim(1) event.yLim(2)]);
            end
            %hold all;
            %%hold off
        end
        
        
        function setCh(app, nCh)
            app.nCh = nCh;
            set (app.chEdit,'Value',app.nCh);
            app.currentCh = 1;
        end
        
        function setGain(app, gain)
            app.gainAmp = gain;
            set(app.gain,'Value', app.gainAmp);
        end
        
        function setSP(app, freq)
            % This function set the sampling period, frequency and all the relative numers in the GUI
            app.sp = 1/freq;        % sampling period in s
            app.acqF = freq;        % sampling frequency in Hz
            set (app.spEdit,'Value',app.sp);
            app.frequencyBand(5,2) = 0.5*freq;  %GAB 22/05/2018
        end
        
        function startSpectraPlot(app)
            % this function causes the refresh of the spectra plots by activating a
            % callback in the ZebraSpectre figure
            
            %GAB 11/03/2018
            %   if the condition is not true, it means that Spectre window is open but
            %   spectra have not been calculated (and plotted) already.
            if ~isempty(app.spectreApp.powerSpectra.Children)
                %ZebraSpectre('maxFreq_Callback',app.spectreData.maxFreq,[],app.spectreData)
                app.spectreApp.plotPowerSpectra()
            end
        end
        
        function templUpdateFunction(app)
            inf_i = round(app.hRoi.Vertices(1,1)/app.sp)+1; %index of the roi interval left edge
            sup_i = round(app.hRoi.Vertices(3,1)/app.sp)+1; %index of the roi interval right edge
            [~,p100_i] = min(app.meanLFP(app.currentCh,inf_i:sup_i));
            p100_i = p100_i +inf_i-1;
            p100_t = (p100_i-1)*app.sp;
            bef = str2double(app.p100bef.String);
            aft = str2double(app.p100after.String);
            app.templateFrom.String = num2str(p100_t-bef);
            app.templateTo.String = num2str(p100_t+aft);
        end


        %% eventDetection functions:::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::
        function plotStatesFun(app)
            len = app.dtaLen;
            spp = app.sp;
            app.evDet.tmin = app.leftTime.Value;
            app.evDet.tmax = app.rightTime.Value;
            
            %axes(app.plotStates);
            cla(app.plotStates)
            %hold on
            time = 0:spp:spp*(len-1);
            plot(app.plotStates,time,app.evDet.LFP,'black');
            
            for usi=1:app.evDet.NUS
               i1 = app.evDet.upstates.fromI(usi);
               i2 = app.evDet.upstates.toI(usi);
               plot(app.plotStates,time(i1:i2),app.evDet.LFP(i1:i2),'red');
            end
            
            for usi=1:app.evDet.NDS
               i1 = app.evDet.downstates.fromI(usi);
               i2 = app.evDet.downstates.toI(usi);
               plot(app.plotStates,time(i1:i2),app.evDet.LFP(i1:i2),'green');
            end
            app.plotStates.XLim = [app.evDet.tmin app.evDet.tmax];
            app.plotStates.YLim = [-inf inf];
            %axis ([app.evDet.tmin app.evDet.tmax -inf inf])
            
            %axes(app.statesTrack);
            set(app.statesTrack, 'XTick', []);
            cla(app.statesTrack)
            %hold on
            plot (app.statesTrack,app.evDet.timeSPG,app.evDet.SPG);
            app.statesTrack.XLim = [app.evDet.tmin app.evDet.tmax];
            app.statesTrack.YLim = [-inf inf];
            %axis ([app.evDet.tmin app.evDet.tmax -inf inf])
            %hold off
        end
        
        function refineEdges(app)    
            erodeFlag = get (app.erodeCk,'Value');
            if erodeFlag
                % now refine the edges of the states
                for usi=1:app.evDet.NUS
                    % check the edges of the US: if they are farther away from the median
                    % than 2*SD the event is eroded to 2*SD. Check first left edge.
                    edgeI = app.evDet.upstates.fromI(usi);
                    value = tempData(edgeI);
                    while ~(abs(value-app.evDet.upstates.medianV(usi))<0.5*app.evDet.upstates.SD(usi))
                        % erode to the right
                        if edgeI==app.dtaLen, break
                        end
                        edgeI = edgeI+1;
                        app.evDet.upstates.fromI(usi) = edgeI;
                        value = tempData(edgeI);
                        % check not to have passed the end of the US!
                    end
                    edgeI = app.evDet.upstates.toI(usi);
                    value = tempData(edgeI);
                    while ~(abs(value-app.evDet.upstates.medianV)<2*app.evDet.upstates.SD)
                        if edgeI>1
                            % erode to the left
                            edgeI = edgeI-1;
                            app.evDet.upstates.toI(usi) = edgeI;
                            value = tempData(edgeI);
                            % check not to have passed the beginning of the US!
                        else
                            break
                        end
                    end
                    % now update the upstate definition
                    app.evDet.upstates.toT(usi) = (app.evDet.upstates.toI(usi)-1)*spp;
                    app.evDet.upstates.fromT(usi) = (app.evDet.upstates.fromI(usi)-1)*spp;
                    app.evDet.upstates.deltaT(usi) = app.evDet.upstates.toT(usi)-app.evDet.upstates.fromT (usi);
            
                    app.evDet.upstates.medianV(usi) = median(tempData(app.evDet.upstates.fromI(usi):app.evDet.upstates.toI(usi)));
                    app.evDet.upstates.SD(usi) = std(tempData(app.evDet.upstates.fromI(usi):app.evDet.upstates.toI(usi)));
                end
            end
        end
    
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.sp = app.spEdit.Value; % sampling period in s
            app.acqF = 1/app.sp; % sampling frequency
            app.insetW = 5; % width of the inset window (s)
            app.lp = 0.1; % width of the low pass filter window % MIGHT BE UNUSED
            app.dir_in = '';
            app.gainAmp = app.gain.Value; % gain setting of the amplifier
            app.nCh = app.chEdit.Value; % number of channels
            app.nTrials = app.trialsEdit.Value; % number of trials
            app.currentCh = app.chSlider.Value; % current channel
            
            sliderBPpower_Callback(app,[])
            
            app.filterOrder = app.filtOrder.Value; % butterworth filter order
            app.notch = [app.notchFrom.Value app.notchTo.Value]; % notch filter for 50 Hz removal
            app.frequencyBand = [0 app.lpTo.Value; app.alphaFrom.Value app.alphaTo.Value;...
                app.betaFrom.Value app.betaTo.Value; app.gammaFrom.Value app.gammaTo.Value;...
                app.hpFrom.Value 0.5/app.sp; 0 app.leakageLP.Value];
            app.notchOrder = 3; % order 6 for the notch filter % MIGHT BE UNUSED
            app.xnow = -999;
            
            app.fileInNum = 0; % keep track of the number of analyzed files for the output table
            app.eventNum = 0; % keep track of the number of analyzed events
            app.cnt = 0; % counter for full trace summary table
            app.EPcnt = 0; % counter for the summary table of evoched responses
            
            % the following structure is required for passing the proper parameters to
            % the Chronux routines.
            app.params = struct('tapers',[1 2],'Fs',0.001,'fpass',[0 10],'pad',0,'err',[]);
            
            % app.dtaSummary = struct('f1','file name','f2',1,'f3',2,'f4',3,'f5',4,'f6','Comments');
            
            % Definition of a cell array to store the output table
            % The fields are consistent with te GUIDE definition of outable provided in
            % the ZebraSays figure.
            % dtaSummary holds the complete file name time of file last modification
            % (it corresponds to the original creation time) and the RMS powers in the 5 bands
            
            app.dtaSummary = {'dir' 'file' 'time' 1 2 3 4 5 6 7 'notes'};
            
            app.eventSummary = {'dir' 'file' 1 2 3 4 5 6 7 8 9 10 11 12 13 'news'};
            % Cell array for the detected events
            % Field description:
            % 1: event number in the given file
            % 2: time at the centre of the event
            % 3: duration of the event
            % 4-6: RMS power of the baseline in the three bands
            % 7-9: RMS power of the event
            % 10-12: power normalised to the baseline
            %
            % 14: news and views (editable text)
            
            app.respSummary = {'file' 1 2 3 4 5 6 7 8 9 10 'news'};
            % Cell array for trial responses: field description
            % 1: file name
            % 2: channel number
            % 3: trial number
            % 4: repeat number
            % 5: Peak response
            % 6: time to peak
            % 7: Mean BP1 baseline power (Db)
            % 8: Mean BP1 response power (Db)
            % 9: normalized power (8-7)
            % 10: BP1 power multiplied by the scaled template
            % 11: comments
            
            app.dtaPWsummary = {'dir' 'file' 'time' 4 5 6 7 8 9 10 11 12 13};
            app.dtaPWlocalSummary = {'dir' 'file' 'time' 4 5 6 7 8 9 10 11 12 13};
            % Cell array for power distribution: field description
            % 1: file name
            % 2: channel number
            % 3: time of data file creation
            % 4: channel
            % 5: trial
            % 6: band pass index (1-4 corresponding to BP1-3 and HP
            % 7: Shannon entropy
            % 8: main mode: mean log of power
            % 9: main mode: std
            % 10: main mode: relative area
            % 11: secondary mode: mean of log of power
            % 12: secondary mode: std
            % 13: secondary mode: relative area
            
%            app.evDetection = eventDetection(hObject,handles);
%            app.evDetData = zebraguidata (app.evDetection);
            
            % All sliders must be invisible in the beginning
            set([app.ChText app.chSlider app.trialText app.trialSlider],'Visible','off')
            
            
            %GAB
            app.autoMode = 0;
            app.storedLFP = [];
            
           %% Initialize eventDetection variables
           
           
        end

        % Button pushed function: ROI4template_bt
        function ROI4template_bt_Callback(app, event)
            if isprop(app,'hRoi')
                delete(app.hRoi);
            end
            app.hRoi = drawrectangle(app.wide_plot_up);
            templUpdateFunction(app);
        end

        % Value changed function: SPGlogCk
        function SPGlogCk_Callback(app, event)
            plotSpectrogram(app)
        end

        % Selection changed function: SPGmode
        function SPGmode_SelectionChangeFcn(app, event)
            plotSpectrogram(app)
        end

        % Callback function
        function SweepTimeEdit_Callback(app, event)
            %GABRIELE 2018/01/27: useful for setting trial time in MeyerVEP mode
            app.sp = get(hObject,'Value')/2048;
            set(app.spEdit,'Value',app.sp);
            app.acqF = 1/app.sp;
        end

        % Value changed function: TrRej_ck
        function TrRej_ck_Callback(app, event)
            if get(hObject,'Value')
                app.trialRej=trialRejection;
                app.trialRejData=zebraguidata(app.trialRej);
            else
                close(app.trialRej)
                app.trialRejData=[];
            end
        end

        % Value changed function: alphaFrom
        function alphaFrom_Callback(app, event)
            app.frequencyBand(2,1) = app.alphaFrom.Value;
        end

        % Value changed function: alphaTo
        function alphaTo_Callback(app, event)
            app.frequencyBand(2,2) = app.alphaTo.Value;
        end

        % Value changed function: analyzePWdistributionCk
        function analyzePWdistributionCk_Callback(app, event)
            
            if app.analyzePWdistributionCk.Value
                % open the child process
                app.analyzePW = analyzePWdistribution(app);      % handle to the child figure
            else
                % close the child process
                close(app.analyzePW);
                app.analyzePW = [];
            end
        end

        % Value changed function: analyzeWhileConc_ck
        function analyzeWhileConc_ck_Callback(app, event)
            % hObject    handle to analyzeWhileConc_ck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of analyzeWhileConc_ck
            if hObject.Value
                app.concat_ck.Value = 1;
            end
        end

        % Value changed function: autoOffCk
        function autoOffCk_Callback(app, event)
            if (app.fileInNum>0), plotWideWindow(app,[])
            end
        end

        % Value changed function: betaFrom
        function betaFrom_Callback(app, event)
            app.frequencyBand(3,1) = app.betaFrom.Value;
        end

        % Value changed function: betaTo
        function betaTo_Callback(app, event)
            app.frequencyBand(3,2) = app.betaTo.Value;
        end

        % Value changed function: bpCheck1
        function bpCheck1_Callback(app, event)
            if (app.fileInNum), plotMultiBand(app)
                plotSpectrogram(app)
            end
        end

        % Value changed function: bpCheck2
        function bpCheck2_Callback(app, event)
            if (app.fileInNum), plotMultiBand(app)
                plotSpectrogram(app)
            end
        end

        % Value changed function: bpCheck3
        function bpCheck3_Callback(app, event)
            if (app.fileInNum), plotMultiBand(app)
                plotSpectrogram(app)
            end
        end

        % Value changed function: chEdit
        function chEdit_Callback(app, event)
            app.nCh = app.chEdit.Value;
            
            if app.nCh>1
                %set(app.chSlider, 'SliderStep', [1/(app.nCh-1) 1/(app.nCh-1)])
                set(app.ChText,'Text',['Ch ' num2str(app.currentCh)])
                set(app.chSlider, 'Limits', [1 app.nCh])
                set(app.chSlider, 'Value', 1)
            else
                set(app.chSlider,'Visible','off')
                set(app.ChText,'Visible','off')
            end
            app.currentCh = 1;
        end

        % Value changed function: chSlider
        function chSlider_Callback(app, event)
            app.currentCh = app.chSlider.Value;
            set(app.ChText,'Text',['Ch ' num2str(app.currentCh)])
            
            plotWideWindow(app,[])
            plotMultiBand(app)
            plotSpectrogram(app)
            if get(app.powerSpectraCk,'Value')
                startSpectraPlot(app)
            end
        end

        % Value changed function: checkbox_sleepscoring
        function checkbox_sleepscoring_Callback(app, event)
            % Didi October 2018: open the sleep scoring program to score NREM sleep
            % episodes in the australian data, based on delta/theta ratio, and
            % determine power in delta band in these episodes
            
            if app.checkbox_sleepscoring.Value
                 % open the program
                 app.S_SC = sleepscoring(app);
                 app.S_SCData = zebraguidata(app.S_SC);
            
             else
                 close(app.S_SC)
            end
        end

        % Button pushed function: clearConc_btn
        function clearConc_btn_Callback(app, event)
            % hObject    handle to clearConc_btn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            app.storedLFP = [];
        end

        % Button pushed function: computeBP
        function computeBP_Callback(app, event)
            %% This function computes and plots the filtered data. It must be evoked after
            % changing any filter settings to update the filtered data.
            % May 7, 2017.
            % I have added the temporal profile of the power in BP1, BP2, BP3 and HP
            
            if (app.fileInNum) % of course the following code is executed only if a data has been loaded
                computeBPandNotch(app)   % handles will be modified at this call
                computePowerInTime(app)  % temporal profile of power
                plotMultiBand(app)
                computeSpectrogram(app)
                plotSpectrogram(app)
            end
        end

        % Button pushed function: concat_btn
        function concat_btn_Callback(app, event)
            % hObject    handle to concat_btn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            concatenateTrials(app)
        end

        % Value changed function: concat_ck
        function concat_ck_Callback(app, event)
            % hObject    handle to concat_ck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if hObject.Value
                app.concat_btn.Enable = 'on';
            else
                app.concat_btn.Enable = 'off';
            end
        end

        % Selection changed function: dataFormatPanel
        function dataFormatPanel_SelectionChangeFcn(app, event)
            % eventdata  structure with the following fields (see UIBUTTONGROUP)
            %	EventName: string 'SelectionChanged' (read only)
            %	OldValue: handle of the previously selected object or empty if none was selected
            %	NewValue: handle of the currently selected object
            
            leak = get(app.leakageLP,'Value');
            %flag = get (hObject,'Tag');
            flag = event.NewValue.Text;
            if strcmpi(flag,'EEG format')
                setSP(app, 512);
                setCh(app, 2);
                setGain(app, 5000);
                set (app.EEGck,'Value',1);
                app.SPGlowDB = -80;
                set(app.lowerDB,'Value',app.SPGlowDB);
                app.SPGhighDB = -30;
                set(app.upperDB,'Value',app.SPGhighDB);
            
                % set the correct spectral bands
                app.frequencyBand = [0 4; 0.5 4; 12 16; 30 80; 100 0.5/app.sp; 0 leak];
            end
            
            if strcmpi(flag,'Meyer VEP')
                % set all required parameters
                %setSP (hObject, handles, 4096);
                setSP(app, 2048/get(app.SweepTimeEdit,'Value')); %gab
                setCh(app, 3)
                setGain(app, 1000)
                set(app.microVck,'Value',1)
                set(app.EEGck,'Value',1)
                app.SPGlowDB = -30; %gab
                set(app.lowerDB,'Value',app.SPGlowDB)
                app.SPGhighDB = -10; %gab
                set(app.upperDB,'Value',app.SPGhighDB)
            
                % set the correct spectral bands
                app.frequencyBand = [0 4; 0.5 4; 12 16; 30 80; 100 0.5/app.sp; 0 leak];
            
                %GABRIELE 2018/01/27
                set(app.spEdit,'Enable','off')
                set(app.SweepTimeEdit,'visible','on')
                set(app.SweepTimeTxt,'visible','on')
            else
                if strcmpi(eventdata.OldValue.Text,'Meyer VEP') %meyerVEP has been deselected
                    set(app.spEdit,'Enable','on')
                    set(app.SweepTimeEdit,'visible','off')
                    set(app.SweepTimeTxt,'visible','off')
                end
            end
            
            if strcmpi(flag,'SEP')
                % set all required parameters
                setSP(app, 32768)
                setCh(app, 2)
                set(app.microVck,'Value',1)
                setGain(app, 1000)
                set(app.EEGck,'Value',1)
                app.SPGlowDB = -80;
                set(app.lowerDB,'Value',app.SPGlowDB)
                app.SPGhighDB = -30;
                set(app.upperDB,'Value',app.SPGhighDB)
            
                % this mode does not perform spectral analysis
            end
            % now update the edit fields
            set(app.lpTo,'Value',app.frequencyBand (1,2))
            set(app.alphaFrom,'Value',app.frequencyBand (2,1))
            set(app.alphaTo,'Value',app.frequencyBand (2,2))
            set(app.betaFrom,'Value',app.frequencyBand (3,1))
            set(app.betaTo,'Value',app.frequencyBand (3,2))
            set(app.gammaFrom,'Value',app.frequencyBand (4,1))
            set(app.gammaTo,'Value',app.frequencyBand (4,2))
            set(app.hpFrom,'Value',app.frequencyBand (5,1))
        end

        % Value changed function: displayLeakCk
        function displayLeakCk_Callback(app, event)
            plotSpectrogram(app)
        end

        % Value changed function: gain
        function gain_Callback(app, event)
            app.gainAmp = app.gain.Value;
        end

        % Value changed function: gammaFrom
        function gammaFrom_Callback(app, event)
            app.frequencyBand(4,1) = app.gammaFrom.Value;
        end

        % Value changed function: gammaTo
        function gammaTo_Callback(app, event)
            app.frequencyBand(4,2) = app.gammaTo.Value;
        end

        % Value changed function: hit_ck
        function hit_ck_Callback(app, event)
            % Hint: get(hObject,'Value') returns toggle state of hit_ck
            if get(hObject,'Value')
                app.responsometer = Responsometer(app);
                %app.responsometerData = zebraguidata(app.responsometer);
            else
                % close the child process
                close(app.responsometer);
                %app.responsometerData = [];
                app.responsometer = [];
            end
        end

        % Value changed function: hpCheck
        function hpCheck_Callback(app, event)
            if (app.fileInNum), plotMultiBand(app)
                plotSpectrogram(app)
            end
        end

        % Value changed function: hpFrom
        function hpFrom_Callback(app, event)
            app.frequencyBand(5,1) = app.hpFrom.Value;
        end

        % Value changed function: leakageLP
        function leakageLP_Callback(app, event)
            app.frequencyBand(6,2) = get(hObject,'Value');
        end

        % Button pushed function: loadParams_btn
        function loadParams_btn_Callback(app, event)
            uiopen('*.mat');
            app.paramsStruct = paramsStruct;
            for i = 1 : height(paramsStruct.mainParamsTable)
%                 i
%                 if i==24
%                     disp('eccoci')
%                 end
                app.(paramsStruct.mainParamsTable.Name(i)).Value = paramsStruct.mainParamsTable.Value(i);
                if ~isempty(app.(paramsStruct.mainParamsTable.Name(i)).ValueChangedFcn)
                    try
                        app.(paramsStruct.mainParamsTable.Name(i)).ValueChangedFcn(app.(paramsStruct.mainParamsTable.Name(i)),[])
                    catch ME
                        warning('off','backtrace')
                        warning(strcat("Error while executing ", paramsStruct.mainParamsTable.Name(i), "'s Callback"))
                        warning(ME.message)
                        disp(newline)
                    end
                end
            end
            
            if isfield(paramsStruct, 'spectreParamsTable')
                disp(['spectre debugging' newline])
                app.spectreApp.loadParamsSpectre()
                disp([newline newline newline])
            
            end
            
            warning('on','backtrace')
            % load handel
            % sound(y,Fs)
            disp('params loaded!')
        end

        % Value changed function: lowerDB
        function lowerDB_Callback(app, event)
            app.SPGlowDB = app.lowerDB.Value;
            if (app.fileInNum>0), plotSpectrogram(app)
            end
        end

        % Value changed function: lpTo
        function lpTo_Callback(app, event)
            app.frequencyBand(1,2) = app.lpTo.Value;
        end

        % Value changed function: lpcheck
        function lpcheck_Callback(app, event)
            if (app.fileInNum), plotMultiBand(app)
                plotSpectrogram(app)
            end
        end

        % Value changed function: multiChAnalyzer
        function multiChAnalyzer_Callback(app, event)
            
            if get(hObject,'Value')
                % open the child process
                app.xCorr = multiChannelAnalyzer(app, []);  % handle to the multi channel analyzer
                app.xCorrData = zebraguidata(app.xCorr);
            else
                close(app.xCorr);
            end
        end

        % Value changed function: notchFilter
        function notchFilter_Callback(app, event)
            computeBP_Callback(app,event)
            computePowerSpectra(app)
        end

        % Value changed function: notchFrom
        function notchFrom_Callback(app, event)
            app.notch(1) = app.notchFrom.Value;
        end

        % Value changed function: notchTo
        function notchTo_Callback(app, event)
            app.notch(2) = app.notchTo.Value;
        end

        % Button pushed function: openDir
        function openDir_Callback(app, event)
            flagN = get(app.normalDataRB,'Value');
            if (flagN), fmtSt = '*.dat';
            end
            dirArray = {'name' 'date' 'bytes' 'isdir' 'datenum'};
            fileInfo = {'name' 'date' 'bytes' 'isdir' 'datenum'};
            selection = uigetdir('C:\Users\Gix\Documents\Work\Science\')
            if selection ~= 0
                fileName = '\ltp001.dat';
                dirArray = dir([selection '\Dir_*']);
                ndir = length(dirArray);
                for i=1:ndir
                    path_in = [selection '\' dirArray(i).name];
                    fileInfo = dir([path_in fileName]);
                    timeNum = datestr(fileInfo.datenum);
                    app.fileTime = timeNum (13:20);
                    openFile(app,fileName,path_in)
                end
            end
        end

        % Value changed function: plotEnvelopeCk
        function plotEnvelopeCk_Callback(app, event)
            if (app.fileInNum), plotMultiBand(app)
            end
        end

        % Value changed function: plotMeanFlag
        function plotMeanFlag_Callback(app, event)
            if app.fileInNum>0
                plotWideWindow(app,[])
                plotMultiBand(app)
                plotSpectrogram(app)
                if get(app.powerSpectraCk, 'Value')
                    app.spectreApp.plotPowerSpectra()
                end
            end
        end

        % Value changed function: plotMultiCh
        function plotMultiCh_Callback(app, event)
            if (app.fileInNum>0), plotWideWindow(app,[])
                plotSpectrogram(app)
                if get(app.powerSpectraCk, 'Value')
                    startSpectraPlot(app)
                end
            end
        end

        % Value changed function: plotTrCk
        function plotTrCk_Callback(app, event)
            if app.fileInNum>0 
                plotWideWindow(app,[])
                plotMultiBand(app)
                plotSpectrogram(app)
                if get(app.powerSpectraCk, 'Value')
                    app.spectreApp.plotPowerSpectra()
                end
            end
        end

        % Value changed function: powerSpectraCk
        function powerSpectraCk_Callback(app, event)
            if app.powerSpectraCk.Value
                % open the child process
                app.spectreApp = ZebraSpectreApp(app);  % handle to the power spectra window
            else
                app.spectreApp.delete
                app.spectreApp = [];
            end
        end

        % Selection changed function: predefinedBW
        function predefinedBW_SelectionChangeFcn(app, event)
            % select default values for bandpass filters
            y = get(hObject,'Tag');     % obtain the tag to the selected button
            leak = get(app.leakageLP,'Value');
            
            if strcmp(y, 'zebraCk')
                app.frequencyBand = [0 8; 0.4 4; 4 8; 8 12; 100 0.5/app.sp; 0 leak];
                % now update the edit fields
            end
            
            if strcmp(y, 'EEGck')
                app.frequencyBand = [0 4; 0.5 4; 6 12; 12 30; 100 0.5/app.sp; 0 leak];
            end
            
            if strcmp(y, 'mouseCk')
                app.frequencyBand = [0 8; 0.5 4;6 12; 30 80; 100 0.5/app.sp; 0 leak];
            end
            
            if strcmp(y, 'NewcastleBand')
                app.frequencyBand = [0 8; 0.5 4; 20 60; 70 300; 300 0.5/app.sp; 0 leak];
            end
            
            % update the edit fields
            set(app.lpTo,'Value',app.frequencyBand (1,2))
            set(app.alphaFrom,'Value',app.frequencyBand (2,1))
            set(app.alphaTo,'Value',app.frequencyBand (2,2))
            set(app.betaFrom,'Value',app.frequencyBand (3,1))
            set(app.betaTo,'Value',app.frequencyBand (3,2))
            set(app.gammaFrom,'Value',app.frequencyBand (4,1))
            set(app.gammaTo,'Value',app.frequencyBand (4,2))
            set(app.hpFrom,'Value',app.frequencyBand (5,1))
        end

        % Button pushed function: recomputeSPG
        function recomputeSPG_Callback(app, event)
            computeSpectrogram(app)
            if (app.nTrials>1), computeMeanOnTrials (app, []);
            end
            % warning: if trial >1 the means have not been recomputed!
            plotSpectrogram(app)
        end

        % Button pushed function: saveBP
        function saveBP_Callback(app, event)
            % Save as an ascii file the current view of the wide window
            % If multi plot is on, all channels are saved as separate columns,
            % otherwise it is saved only the current channel.
            % This can be used to save only a small portion of the record depending on
            % the X limits of the visualisation.
            
            % Data are saved with the following order (GAB):
            % 1) Single trial, multi channels
            % col 1. time   ( NOT  Full data (with notch filter, if applied) )
            % col 2-5. the four band width
            % repeat for all channels if the multi channel flag is selected
            
            % 2) Multi trial, multi channels (GAB, not checked)
            % December 8, 2017
            % The button saves only the bandpassed data that are visualized and that refer
            % to the current channel. I have implemented two different functioning modes:
            % 2.1) if Plot mean checkbox is selected, the program saves only the mean
            % 2.2) if Plot mean is checked the program saves all trials as separate
            % columns. If more than one bandwidth is specifiedthey are alla grouped
            % together.
            
            fileOut = [app.dir_in 'currentTraceBP.dat'];
            % check what data to save (single trial or mean over trials, single Ch or multi Ch)
            flagMd = get(app.plotMeanFlag,'Value');
            flagMulti = get(app.plotMultiCh,'Value');
            tmpOut = [];
            % determine the initial and final index of the visualized data
            lim = axis(app.wide_plot_up);
            ifrom = lim(1)/app.sp + 1;
            ito = lim(2)/app.sp + 1;
            if (ito>app.dtaLen), ito = app.dtaLen;
            end
            tmpOut(1,:) = app.x (1:(ito-ifrom+1));
            cntOut = 1;
            ct = app.currentTrial;
            for i=1:app.nCh
                % plot on all available channels.
                if (app.currentCh == i || flagMulti)
                    % this channel has to be saved out
                    if (app.nTrials>1 && flagMd)
                        % save the mean data and BP data. If flagMd is false save only
                        % the current trace.
                        cntOut=cntOut+1;
                        dimMeanBP=size(app.meanBP);
                        %tmpOut (cntOut,1:(ifrom-ito+1)) = app.meanPWS (1:app.nCh,1:(ifrom-ito+1));
                        tmpOut (cntOut:cntOut+dimMeanBP(1)-1,1:(ito-ifrom+1)) = app.meanBP(:,app.currentCh,ifrom:ito); %GAB
                    else
                        % save data from currentTrial
                        cntOut=cntOut+1;
                        tmpOut (cntOut,1:(ito-ifrom+1)) = app.workLFP(i,ct,ifrom:ito);
                        % save the band passed data
                        tmpOut (cntOut+1:cntOut+4,1:(ito-ifrom+1)) = app.bandPassed_LFP(1:4,i, ct, ifrom:ito);
                        cntOut=cntOut+4;
                    end
                end
            end
            % create the format descriptor
            fmtSt='';
            for ii=1:cntOut-1
               fmtSt=[fmtSt '%10.8f '];
            end
            fmtSt=[fmtSt '%10.8f\n'];       % start new line
            
            fid = fopen(fileOut,'w');
            fprintf (fid,fmtSt,tmpOut);
            fclose(fid);
        end

        % Button pushed function: saveCurrentCh
        function saveCurrentCh_Callback(app, event)
            % Save as an ascii file the current view of the wide window
            % If multi plot is on, all channels are saved as separate columns,
            % otherwise it is saved only the current channel.
            % This can be used to save only a small portion of the record depending on
            % the X limits of the visualisation.
            
            fileOut = [app.dir_in 'currentTrace.dat'];
            % check what data to save (single trial or mean over trials, single Ch or multi Ch)
            flagMd = get(app.plotMeanFlag,'Value');
            flagMulti = get(app.plotMultiCh,'Value');
            tmpOut = [];
            % determine the initial and final index of the visualized data
            lim = axis(app.wide_plot_up);
            ifrom = lim(1)/app.sp + 1;
            ito = lim(2)/app.sp + 1;
            if (ito>app.dtaLen), ito = app.dtaLen;
            end
            if flagMd
                % save all channels of the mean trace. Reserve one column for the
                % ordinates.
                tmpOut (2:app.nCh+1,1:(ito-ifrom+1)) = app.meanLFP (1:app.nCh,ifrom:ito);
            else
                % save the spectra of all channels of the current trial
            %    tmpOut (2:app.nCh+1,1:(ito-ifrom+1)) = app.workLFP (1:app.nCh,app.currentTrial,ifrom:ito);
                tmpOut (2:app.nCh+1,1:(ito-ifrom+1)) = app.workLFP (1:app.nCh,app.currentTrial,ifrom:ito);
            end
            tmpOut(1,:) = app.x (1:(ito-ifrom+1));
            % create the format descriptor
            fmtSt = '';
            for ii=1:app.nCh      % add 1 for the x axis.
               fmtSt = [fmtSt '%8.6f '];
            end
            fmtSt = [fmtSt '%8.6f\n'];       % start new line
            
            fid = fopen(fileOut,'w');
            fprintf (fid,fmtSt,tmpOut);
            fclose(fid);
        end

        % Button pushed function: saveParams_btn
        function saveParams_btn_Callback(app, event)
            app.paramsStruct = struct;
            
            propList = string(properties(app)); % get all the properies of the main app as string vector
            propListLogic = false(size(propList)); % initialize a logic vector, that will be used to tell apart checkboxes and edit fields from the rest
            
            for i=1:length(propList)
                if isobject(app.(propList(i))) && isprop(app.(propList(i)),'Type') % only isprop is not enough
                    if strcmp(app.(propList(i)).Type,'uinumericeditfield') || strcmp(app.(propList(i)).Type,'uicheckbox')
                        propListLogic(i) = true;
                    end
                end
            end
            
            paramsTable = table('size', [sum(propListLogic), 2],'VariableTypes', {'string', 'double'}, 'VariableNames', {'Name', 'Value'});
            paramsTable.Name = propList(propListLogic); % list of only checkboxes and editable textboxes
            
            % Extract all the names, values and callbacks of the checkboxes and editable textboxes of the main App
            for i=1:height(paramsTable)
                paramsTable.Value(i) = app.(paramsTable.Name(i)).Value;
            end
            
            app.paramsStruct.mainParamsTable = paramsTable;
            
            if app.powerSpectraCk.Value
                % if zebraspectre is open, ZebraSpectreApp saves its parameter in the table spectreParamsTable and adds it to the structure app.paramsStruct
		saveParamsSpectre(app.spectreApp)
            end
            
            paramsStruct = app.paramsStruct;
            
            [file,path] = uiputfile('*.mat','Save parameters file','Parameters.mat');
            
            save([path file],'paramsStruct')
        end

        % Button pushed function: saveSPG
        function saveSPG_Callback(app, event)
            % Save as an ascii file the current view of the SPG, envelope, RMS power and E/I index
            % If plotMean is on it saves the computed means otherwise it saves the current trace.
            % This can be used to save only a small portion of the record depending on
            % the X limits of the visualisation.
            
            % check what data to save (single trial or mean over trials, single Ch or multi Ch)
            flagMd = get(app.plotMeanFlag,'Value');
            %flagMulti = get(app.plotMultiCh,'Value'); yet unusued
            
            % determine the initial and final index of the visualized data
            lim = axis(app.wide_plot_up);
            timeFrom = lim(1);
            timeTo = lim(2);
            
            % process the RMS powers of the BP1-3, HP.
            % since each file may have different time axis they are saved as separate
            % files
            
            outBPpowers (app, 2, timeFrom, timeTo, 'BP1pw.dat');
            outBPpowers (app, 3, timeFrom, timeTo, 'BP2pw.dat');
            outBPpowers (app, 4, timeFrom, timeTo, 'BP3pw.dat');
            outBPpowers (app, 5, timeFrom, timeTo, 'HPpw.dat');
            
            % process the E/I ratio first, the envelope second
            SPGmin = app.spgt(1);
            SPGmax = app.spgt(app.spgl);
            resolutionSPG = (SPGmax-SPGmin)/(app.spgl-1);
            SPGfrom = int32(timeFrom/resolutionSPG)+1;
            SPGto =  int32(timeTo/resolutionSPG)+1;
            
            if (SPGto>app.spgl), SPGto = app.spgl;
            end
            tmpOut = [];
            
            if flagMd
                % save all channels of the mean E/I ratio. Reserve one column for the
                % time coordinate.
                tmpOut (2:app.nCh+1,1:(SPGto-SPGfrom+1)) = app.meanEIrat (1:app.nCh,SPGfrom:SPGto);
            else
                % save the E/I ratio of all channels of the current trial
                tmpOut (2:app.nCh+1,1:(SPGto-SPGfrom+1)) = app.EIrat (1:app.nCh,app.currentTrial,SPGfrom:SPGto);
            end
            c(1,:) = app.spgt(:);
            % create the format descriptor
            fmtSt='';
            for ii=1:app.nCh      % add 1 for the x axis.
               fmtSt= [fmtSt '%10.8f '];
            end
            fmtSt=[fmtSt '%10.8f\n'];       % start new line
            fileOut = [app.dir_in 'EIratio.dat'];
            fid = fopen(fileOut,'w');
            fprintf (fid,fmtSt,tmpOut);
            fclose(fid);
            
            % process the envelope
            if flagMd
                % save all channels of the mean E/I ratio. Reserve one column for the
                % time coordinate.
                tmpOut (2:app.nCh+1,1:(SPGto-SPGfrom+1)) = app.meanSpgPlot (1:app.nCh,SPGfrom:SPGto);
            else
                % save the E/I ratio of all channels of the current trial
                tmpOut (2:app.nCh+1,1:(SPGto-SPGfrom+1)) = app.spgPlot (1:app.nCh,app.currentTrial,SPGfrom:SPGto);
            end
            
            fileOut = [app.dir_in 'SPGplot.dat'];
            fid = fopen(fileOut,'w');
            fprintf (fid,fmtSt,tmpOut);
            fclose(fid);
        end

        % Value changed function: screenCaptureBtn
        function screenCaptureBtn_Callback(app, event)
            flag = get(hObject,'Value');
            if flag, scButtonOn(app);
            else
                scButtonOff(app)
            end
        end

        % Button pushed function: scrollLeft
        function scrollLeft_Callback(app, event)
            
            delta = app.tmax - app.tmin;
            app.tmin = app.tmin - delta;
            if app.tmin<0, app.tmin=0;
            end
            app.tmax = app.tmin + delta;
            set(app.tminTxt,'Value',app.tmin);
            set(app.tmaxTxt,'Value',app.tmax);
            plotWideWindow(app,[])
            plotMultiBand(app)
            plotSpectrogram(app)
        end

        % Button pushed function: scrollRight
        function scrollRight_Callback(app, event)
            
            delta = app.tmax - app.tmin;
            app.tmin = app.tmax;
            app.tmax = app.tmax + delta;
            set(app.tminTxt,'Value',app.tmin);
            set(app.tmaxTxt,'Value',app.tmax);
            plotWideWindow(app,[])
            plotMultiBand(app,[])
            plotSpectrogram(app,[])
        end

        % Button pushed function: select_data
        function select_data_Callback(app, event)
            % Open new data file according to the selected data type
            
            % Check if any accessory window is selected and, if that is the case,
            % closes it.
            
            flagN = get(app.normalDataRB,'Value');
            if (flagN), fmtSt = '*.dat';
            end
            flagL = get(app.lauraEEG,'Value');
            if (flagL), fmtSt = '*.eeg';
            end
            flagMV = get(app.meyerVepRB,'Value');
            if (flagMV), fmtSt = '*.asc';
            end
            flagSSP = get(app.meyerSspRB,'Value');
            if (flagSSP), fmtSt = '*.asc';
            end
            flagAxP = get(app.axopatchCk,'Value');
            if (flagAxP), fmtSt = '*.abf';
            end
            flagTRC = get(app.TRCimportRB,'Value');
            if (flagTRC), fmtSt = '*.trc';
            end
            flagMEA = get(app.meaRB,'Value');
            if (flagMEA), fmtSt = '*.mat';
            end
            
            
            if app.autoMode    %:::GABRIELE::: 08/12/2017, for AutoOpening
                selection=app.selection;
                path_in=[app.path_in '\'];
                app.fileTime=[]; % needed by OpenFile
                openFile(app,selection,path_in)
            else
                [selection,path_in,filterI] = uigetfile ([app.dir_in fmtSt]);
                fileInfo = {'name' 'date' 'bytes' 'isdir' 'datenum'};
                if selection ~= 0
                    fileInfo = dir([path_in selection]);
                    timeNum = datestr(fileInfo.datenum);
                    app.fileTime = timeNum (13:20);
                    openFile(app,selection,path_in)
                end
            end
        end

        % Button pushed function: sendToClipboard
        function sendToClipboard_Callback(app, event)
            h = ZebraExplore;
            hgexport(h,'-clipboard');
        end

        % Value changed function: singleUnitExtraction_ck
        function singleUnitExtraction_ck_Callback(app, event)
            % hObject    handle to singleUnitExtraction_ck (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % Didi October 2018: open single units program to extract single units from
            % LFP
             if get(hObject, 'Value')
                 % open the single unit program
                 app.SU = singleunits2(app);
                 %app.SUData = zebraguidata(app.SU);
            
             else
                 close(app.SU);
             end
        end

        % Value changed function: sliderBPpower
        function sliderBPpower_Callback(app, event)
            app.BP4power = app.sliderBPpower.Value;
            app.BPtext.Text = ['Select BP' num2str(app.BP4power)];
        end

        % Value changed function: spEdit
        function spEdit_Callback(app, event)
            app.sp = app.spEdit.Value;
            app.acqF = 1/app.sp;
            app.frequencyBand(5,2) = 0.5*app.acqF;  %GAB 22/05/2018
        end

        % Button pushed function: tResetBtn
        function tResetBtn_Callback(app, event)
            app.tmin = 0;
            set(app.tminTxt,'Value',app.tmin)
            app.tmax = app.dtaLen*app.sp;
            set(app.tmaxTxt,'Value',app.tmax)
            plotWideWindow(app,[])
            plotMultiBand(app)
            plotSpectrogram(app)
        end

        % Button pushed function: templExp_bt
        function templExp_bt_Callback(app, event)
            % hObject    handle to templExp_bt (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            templ = app.templ;
            uisave('templ','template.mat')
        end

        % Button pushed function: templImp_bt
        function templImp_bt_Callback(app, event)
            % hObject    handle to templImp_bt (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            templTemp = uigetfile;
            app.templ = templTemp;
        end

        % Value changed function: tmaxTxt
        function tmaxTxt_Callback(app, event)
            app.tmax = app.tmaxTxt.Value;
            if (app.tmin < app.tmax), plotWideWindow(app,[])
                plotMultiBand(app)
                plotSpectrogram(app)
            end
        end

        % Value changed function: tminTxt
        function tminTxt_Callback(app, event)
            app.tmin = app.tminTxt.Value;
            if (app.tmin < app.tmax), plotWideWindow(app,[])
                plotMultiBand(app)
                plotSpectrogram(app)
            end
        end

        % Value changed function: trialSlider
        function trialSlider_Callback(app, event)
            if (app.fileInNum >= 1 && app.nTrials > 1)
                app.currentTrial = int16(app.trialSlider.Value);
                set(app.trialText,'Text',['Trial ' num2str(app.currentTrial)]);
                plotWideWindow(app,[])
                plotMultiBand(app)
                plotSpectrogram(app)
                if get(app.powerSpectraCk, 'Value')
                    startSpectraPlot(app)
                end
            end
        end

        % Value changed function: upperDB
        function upperDB_Callback(app, event)
            app.SPGhighDB = app.upperDB.Value;
            if (app.fileInNum>0), plotSpectrogram (app)
            end
        end

        % Value changed function: ymaxTxt
        function ymaxTxt_Callback(app, event)
            %        str2double(get(hObject,'Value')) returns contents of ymaxTxt as a double
            %app.ymax = (get(hObject,'Value'));
            event = [];
            event.yLim = [app.yminTxt.Value, app.ymaxTxt.Value];
            plotWideWindow(app,event)
            plotMultiBand(app)
            plotSpectrogram(app)
        end

        % Button pushed function: autoMode_bt
        function autoMode_btButtonPushed(app, event)
            if ~app.autoMode
                app.autoMode = 1;
                app.select_data.Enable = 'off';
                app.AutoGo_bt.Enable = 'on';
                app.autoMode_bt.Text = 'Auto Mode ON';
                app.autoMode_bt.BackgroundColor = 'g';
            else
                app.autoMode = 0;
                app.select_data.Enable = 'on';
                app.AutoGo_bt.Enable = 'off';
                app.autoMode_bt.Text = 'Auto Mode OFF';
                app.autoMode_bt.BackgroundColor = 'r';
            end
        end

        % Button pushed function: AutoSetContrasts_bt
        function AutoSetContrasts_btButtonPushed(app, event)
            nCond = app.AutoNConds_txt.Value;
            
            conds = 1:nCond;
            
            % Create a cell array containing all the string to be put aside each text box
            prompt = arrayfun(@(x) ['specify folders for contrast level' num2str(x)], conds, 'un', 0);
            
            if ~isempty(app.AutoDlgIn)
                % If app.AutoDlgIn is not empty, AutoDlg is used as preset values of the ZebraAuto folders
                preset = app.AutoDlgIn;
            else
                % If there is nothing in app.AutoDlgIn, it is initialized as an empty cell array
                preset = cell(nCond, 1);
                preset(:) = {''};
            end
            
            title = 'specify folders for analysis (space separated)';
            nlines = 1;
            app.AutoDlgIn = inputdlg(prompt, title, nlines, preset, 'on');
            if isempty(app.AutoDlgIn)
                return
            end
            
            for i = 1:nCond
                AutoFolderTmp = strsplit(app.AutoDlgIn{i}, ',');
                app.AutoFolders{i} = cellfun(@str2double, AutoFolderTmp);
            end
        end

        % Button pushed function: AutoGo_bt
        function AutoGo_btButtonPushed(app, event)
            % The main path_in is taken from the path that is saved in ZebraAuto
            % And the same is done for the file name
            app.path_in = app.AutoMainFolder_edit.Value;
            app.selection = app.AutoFileNameIn_edit.Value;
            
            nCond = app.AutoNConds_txt.Value;
            
            if app.AutoTableAvg_ck.Value
                outTab = struct('AmpAvg',[],'AmpSEM',[],'TpeakAvg',[],'TpeakSEM',[],'BasePWAvg',[],'BasePWSEM',[],...
                    'RespPWAvg',[],'RespPWSEM',[],'RatioPWAvg',[],'RatioPWSEM',[],'TemplPWAvg',[],'TemplPwSEM',[]);
            else
                outTab = struct('Table',[]);
            end
                
            s = [];
            
            if app.AutoSpg_ck.Value
                spg = struct('spgMat',[],'spgT',[],'spgW',[]);
            end
            
            for i_Cond=1:nCond
                currFolds = app.AutoFolders{i_Cond}; %currFolds=folders of the current condition
                nTraces = length(currFolds);
                skip = 0; % Useful for "concatenate" condition. If skip = 1, skips the storage of data form a trace
                if nTraces>0
                    for i_Traces = 1:nTraces
                                    
                        iCond = i_Cond
                        iTraces = i_Traces
                        
                        if currFolds(iTraces)>9 %we need this because folder names as Dir_03 cannot be returned, i.g.: num2string(03)=3 
                            app.path_in = [app.folderIn '\Dir_' num2str(currFolds(iTraces))];
                        else
                            app.path_in = [app.folderIn '\Dir_0' num2str(currFolds(iTraces))];
                        end
                        
                        select_data_Callback(app,[])
                                             
                        if app.AutoSelCh_txt.Value ~= 1
                            app.chSlider.Value = app.AutoSelCh_txt.Value;
                            chSlider_Callback(app,[])
                        end
                        %isn't there an easier way?
                        
                        %2018/11/04: if "concatenation is selected", just open all the
                        %"nTraces" files and extract data only at the end of a "condition".
                        if app.concat_ck.Value
                            if iTraces < nTraces
                                skip = 1;
                            else
                                skip = 0;
                                iTraces = 1; %so that the result is stored as it was the "fist trace"
                                %cooncatenate trials and compute everything in ZebraMain
                                concat_btn_Callback(app, [])
                            end
                        end
                        
                        if ~skip
                            %% tables
                            if app.AutoTable_ck.Value
                                CheckCh = cell2mat(app.outEvochedResponses.Data(:,2)) == app.AutoSelCh_txt.Value;
                                tmpTab = app.outEvochedResponses.Data(CheckCh,:);
                                l = size(tmpTab);
                                if iTraces==1 %if we're in the 1st folder of a new dataset, clear all
                                    if ~app.concat_ck.Value
                                        currCondTab=cell(l(1), l(2), nTraces); %let's prepare a table to store a table from each of the nTraces files.
                                    else
                                        currCondTab=cell(l(1), l(2), 1); %we only have 1 table summarizing all the concatenated trials
                                    end
                                end
            
                                %it may happen that different traces have different number of epochs, so:
                                l_cct = size(currCondTab);
                                if l(1)==l_cct(1)
                                    currCondTab(:,:,iTraces) = tmpTab;
                                else
                                    if l(1)>l_cct(1) 
                                        %fill previous columns with some NaN s
                                        temp = currCondTab(end-1:end,:,:);
                                        filling = NaN(l(1)-l_cct(1),l_cct(2), nTraces);
                                        currCondTab = [currCondTab(1:end-2,:,:); num2cell(filling); temp];
                                        currCondTab(:,:,iTraces) = tmpTab;
                                    else
                                        %copy the table but the last 2 rows, fill the gap, copy
                                        %the last 2 rows.
                                        currCondTab(1:l(1)-2,:,iTraces) = tmpTab(1:end-2,:);
                                        currCondTab(l(1)-1:end-2,:,iTraces) = num2cell(NaN(l_cct(1)-l(1),l_cct(2),1));
                                        currCondTab(end-1:end,:,iTraces) = tmpTab(end-1:end,:);
                                    end
                                end
                                outTab(iCond).templPeakSgolay(iTraces) = app.templatePeakSigned(app.AutoSelCh_txt.Value);
                            end
            
                            %% spectra
                            if app.AutoSpectra_ck.Value
                                %--set parameters on ZebraSpectre----------------------
                                if app.AutoWinSpectra_ck.Value
                                    app.spectreApp.fromTtxt.Value = app.AutoSpectraFrom_edit.Value;
                                    app.spectreApp.toTtxt.Value = app.AutoSpectraTo_edit.Value;
                                    app.spectreApp.windowCk.Value = 1;
                                    app.spectreApp.subMean_ck.Value = 1;
                                    if app.AutoWinSpectra2_ck.Value
                                        app.spectreApp.fromT2txt.Value = app.AutoSpectraFrom2_edit.Value;
                                        app.spectreApp.toT2txt.Value = app.AutoSpectraTo2_edit.Value;
                                        app.spectreApp.window2Ck.Value = 1;
                                    end
                                end
                                computeSpectra_Call(spectreApp)
                                
                                %--Initialize variables at the beginning---------------------
                                if (iTraces==1 && iCond==1) %ho messo iTraces al posto di nTraces, che non aveva senso (2018/11/04)
                                    if app.AutoSpectraTr_ck.Value %save spectra of single traces
                                        specTemp = cell(nCond,nTraces,2); %It's a cell array because different traces can have different number of trials.
                                        if app.AutoWinSpectra2_ck.Value %second window
                                            specTemp2 = cell(nCond,nTraces,2);
                                        end
                                    end
                                    if app.AutoSpectraAvg_ck.Value %save mean power spectra
                                        specTempMean = zeros(nCond,nTraces,2,length(app.pwsf));
                                        if app.AutoWinSpectra2_ck.Value %second window
                                            specTempMean2 = zeros(nCond,nTraces,2,length(app.pwsf2));
                                        end
                                    end
                                end
            
                                %--Store spectra-------------------------------------------
                                if app.AutoSpectraAvg_ck.Value %save mean power spectra
                                    specTempMean(iCond, iTraces, 1, :) = app.pwsf; %frequency vector
                                    specTempMean(iCond, iTraces, 2, :) = app.meanPWS(app.AutoSelCh_txt.Value,:);
                                    if app.AutoWinSpectra2_ck.Value %second window
                                        specTempMean2(iCond, iTraces, 1, :) = app.pwsf2; %frequency vector
                                        specTempMean2(iCond, iTraces, 2, :) = app.meanPWS2(app.AutoSelCh_txt.Value,:);
                                    end
                                end
                                if app.AutoSpectraTr_ck.Value %save spectra of single trials
                                    specTemp{iCond, iTraces, 1} = app.pwsf;
                                    specTemp{iCond, iTraces, 2} = app.powerS(app.AutoSelCh_txt.Value,:,:);
                                    if app.AutoWinSpectra2_ck.Value %second window
                                        specTemp2{iCond, iTraces, 1} = app.pwsf2;
                                        specTemp2{iCond, iTraces, 2} = app.powerS2(app.AutoSelCh_txt.Value,:,:);
                                    end
                                end
                            end
                            %% spg
                            if app.AutoSpg_ck.Value
                                flagLeak = app.displayLeakCk.Value;
                                if iTraces == 1
                                    if (app.deleakedFlag && flagLeak)
                                        dim = size(app.meanSpgDeleaked(app.AutoSelCh_txt.Value,:,:));
                                    else
                                        dim = size(app.meanSpg(app.AutoSelCh_txt.Value,:,:));
                                    end
                                    dim2 = size(app.spgt);
                                    dimMin = min(dim(3),dim2(2));
                                    dim(3) = dimMin;
            
                                else
                                    dim = size(spg(iCond).spgMat(iTraces-1,:,:));
                                    if (app.deleakedFlag && flagLeak)
                                        dim1 = size(app.meanSpgDeleaked(app.AutoSelCh_txt.Value,:,:));
                                    else
                                        dim1 = size(app.meanSpg(app.AutoSelCh_txt.Value,:,:));
                                    end
            
                                    %---
                                    dim2 = size(app.spgt);
                                    dimMin = min(dim1(3),dim2(2));
                                    %---
            
                                    if dimMin<dim(3)
                                        spg(iCond).spgMat = spg(iCond).spgMat(:,1:dim1(2),1:dimMin);
                                        spg(iCond).spgT = spg(iCond).spgT(:,1:dimMin);
                                        dim = size(spg(iCond).spgMat(iTraces-1,:,:));
                                    end
                                end
                                spg(iCond).spgT(iTraces,:) = app.spgt(1:dim(3));
                                spg(iCond).spgW(iTraces,:) = app.spgw(1:dim(2));
                                if (app.deleakedFlag && flagLeak)
                                    spg(iCond).spgMat(iTraces,:,:) = app.meanSpgDeleaked(app.AutoSelCh_txt.Value,1:dim(2),1:dim(3));
                                else
                                    spg(iCond).spgMat(iTraces,:,:) = app.meanSpg(app.AutoSelCh_txt.Value,1:dim(2),1:dim(3));
                                end
            
                                %check that time and freq are the same among traces
                                if iTraces > 1
                                    checkT = isequal(spg(iCond).spgT(iTraces,:),spg(iCond).spgT(iTraces-1,:));
                                    checkW = isequal(spg(iCond).spgW(iTraces,:),spg(iCond).spgW(iTraces-1,:));
                                    if checkT * checkW == 0
                                        error('SPG: time or frequency mismatch')
                                    end
                                end
                            end
            
                            %% traces
                            if app.AutoTrace_ck.Value
                                %Traces can have different length, but they will be saved
                                %in the same matrix "trace" (trace*time). so:
                                %   1- if iTraces==1 we're storing the first trace of this
                                %       condition, so it's ok
                                %   2- if the current trace is longer than the ones already stored,
                                %       save only part of it.
                                %   3- if the current trace is shorted than the ones already
                                %       stored, shorten the matrix "trace" and then store
                                %       the trace
            
                                if iTraces == 1
                                    traces(iCond).trace = [];
                                    traces(iCond).trace = app.meanLFP(app.AutoSelCh_txt.Value,:);
                %                     dimTrace = size(handles.parentHandles.meanLFP(app.AutoSelCh,:));
                                else
                                    dimTraces = size(traces(iCond).trace);
                                    dimThis = size(app.meanLFP(app.AutoSelCh_txt.Value,:));
            
                                    if dimThis(2) >= dimTraces(2)
                                        traces(iCond).trace(iTraces,1:dimTraces(2)) = app.meanLFP(app.AutoSelCh_txt.Value,1:dimTraces(2));
                                    else
                                        traces(iCond).trace = traces(iCond).trace(:,1:dimThis(2));
                                        traces(iCond).trace(iTraces,1:dimThis(2)) = app.meanLFP(app.AutoSelCh_txt.Value,1:dimThis(2));
                                    end
                                end
            
                %                 handles.traces(iCond).trace(iTraces,1:dimTraces(2)) = handles.parentHandles.meanLFP(app.AutoSelCh,1:dimTrace(2));
                            end    
                        end
                    end
                    
                    %% this is done at the end of each "condition"
                    if app.AutoTable_ck.Value
                        tabSubset=currCondTab(:,5:10,:); %collect only numeric data
                        if app.doubleStimCk.Value
                            tabSubset(end-1:end,6,:) = num2cell(0); %this is needed because the last 2 cells of the 6th colums are empty and cannot be corverted into numbers later
                        else
                            tabSubset(end,6,:) = num2cell(0);
                        end
            
                        %convert the cell array into a matrix
                        finalDim_a = size(currCondTab); %final dimensions of matrix a
                        dim_subset = size(tabSubset);
            %             tabMat=zeros(dim_subset);
            %             for i=1:nTraces %runs over "analysed folders" CI DEVO METTERE i NON iTraces!!!!!
            %                 for j=1:6 %runs over currCondTab colums, that are supposed to be 6(ampl,time,4*power) in the current version of Zebra
            %                     tabMat(:,j,i)=cell2mat(tabSubset(:,j,i));
            %                     if j==1
            %                         tabMat(:,j,i)=tabMat(:,j,i)*(-1);
            % %                         if handles.parentHandles.doubleStimCk.Value
            % %                             if c(end-1,j,i)<0          
            % %                                 c(end-1,j,i)=0; 
            % %                             end
            % %                         end
            % %                         if c(end,j,i)<0 
            % %                             c(end,j,i)=0; 
            % %                         end
            %                     end
            % 
            %                 end
            %             end
                        tabMat = cell2mat(tabSubset);
                        tabMat(:,1,:) = -1*tabMat(:,1,:); %this command works even if ntraces = 1, and so tabMat is a 2D-matrix and not a 3D-matrix
                        
                        
                        %let's do some statistics if AverageConditions is checked:
                        if app.AutoTableAvg_ck.Value
                            if app.doubleStimCk.Value
                                fromRow=finalDim_a(1)-1;
                            else
                                fromRow=finalDim_a(1);
                            end
                            [outTab(iCond).AmpAvg,     outTab(iCond).AmpSEM    ] = MeanAndSEM(tabMat,1,fromRow,finalDim_a(1));
                            [outTab(iCond).TpeakAvg,   outTab(iCond).TpeakSEM  ] = MeanAndSEM(tabMat,2,fromRow,finalDim_a(1));
                            [outTab(iCond).BasePWAvg,  outTab(iCond).BasePWSEM ] = MeanAndSEM(tabMat,3,1,finalDim_a(1)-2);
                            [outTab(iCond).RespPWAvg,  outTab(iCond).RespPWSEM ] = MeanAndSEM(tabMat,4,1,finalDim_a(1)-2);
                            [outTab(iCond).RatioPWAvg, outTab(iCond).RatioPWSEM] = MeanAndSEM(tabMat,5,1,finalDim_a(1)-2);
                            [outTab(iCond).TemplPWAvg, outTab(iCond).TemplPwSEM] = MeanAndSEM(tabMat,6,1,finalDim_a(1)-2);
                        %else, let's save all the "tables" .
                        else
                            for i=1:nTraces
                                outTab(iCond).Table = tabMat;
                            end
                        end
                    end
                    
                    if app.AutoFailures_ck.Value
                        dimC = size(tabMat); %c is the "table" matrix relative to this condition.
                        if app.doubleStimCk.Value
                            l = dimC(1)-2;
                        else
                            l = dimC(1)-1;
                        end
                        
                        fails = sum(tabMat(:,1,:) < -1*app.AutoFailThreshold_edit.Value,1); %number of fails for each trace; previously all values haves been multiplied by -1!!
                        nans = sum(isnan(tabMat(:,1,:)),1); %number of NaNs eventually introduced before
                        actTrials = l - nans; %actual number of trials
                        
                        %overall stats for this condition
                        totFails = sum(fails);
                        totTrials = sum(actTrials);
                        
                        %finalFails is a matrix conditions * twice(nTraces+1)
                        if iCond == 1
                            finalFails = zeros(nCond,2*(nTraces+1));
                            for i=1:dimC(3)
                                finalFails(iCond,2*i-1) = fails(i);
                                finalFails(iCond,2*i) = actTrials(i);
                            end
                        else  %adjust if different number of traces
                            dim_prec = size(finalFails);
                            
                            if dim_prec(2) < 2*(dimC(3)+1) %if this condition has more traces, fill the previous matrix with zeroes
                                diff = 2*(dimC(3)+1) - dim_prec(2);
                                lastCol = finalFails(1:iCond-1,end-1:end);
                                finalFails(:,end-1:end) = [];
                                finalFails(:,end+1:end+2) = 0;
                                finalFails(1:iCond-1,end+1:end+2) = lastCol;
                            end
                            
                            for i=1:dimC(3) %copy the results for the traces of this condition
                            finalFails(iCond,2*i-1) = fails(i);
                            finalFails(iCond,2*i) = actTrials(i);
                            end
                                
                            if dim_prec(2) > 2*(dimC(3)+1)  %if this conditions has few traces, fill it with zeroes  
                                for i=dimC(3)+1:dim_prec(2)
                                finalFails(iCond,2*i-1) = 0;
                                finalFails(iCond,2*i) = 0;
                                end
                            end
                        end
                        finalFails(iCond,end-1) = totFails;
                        finalFails(iCond,end) = totTrials;
                    end
                    
            %         if handles.spectra_ck.Value
            %             %for the moment just collect spectra
            %         end
                    
                    if app.AutoSpg_ck.Value
                        %fare spg stessa lunghezza
            %             if nTraces>1
            %                 checkT=1;
            %                 for i=2:nTraces
            %                     checkT=checkT*isequal(handles.spg(1).spgT,handles.spg(i).spgT);
            %                 end
            %                 if ~checkT
            %                     error(['sgpT are different: length(spgT(1))=' num2str(length(spg(1).spgT)) '; length(spgT(' num2str(i) '))=' num2str(length(spg(i).spgT))])
            %                 end
            %             end
                        spgOut(iCond).spgT = spg(iCond).spgT(1,:);
                        spgOut(iCond).spgW = spg(iCond).spgW(1,:);
                        spgOut(iCond).spgMat = squeeze(mean(spg(iCond).spgMat,1));
                    end
                    
                    if app.AutoTrace_ck.Value
                        %fare tracce stessa lunghezza
                        if iCond == 1
                            l = length(traces(iCond).trace(iTraces,:));
                        else
                            %if current condition has longer trace, then it is limited by
                            %stopping at l;
                            %if current condition has shorter trace, then shorten the
                            %meanTraces and update l
                            l = length(meanTraces(iCond-1,:));
                            l1 = length(traces(iCond).trace(iTraces,:));
                            if l1 < l
                                meanTraces = meanTraces(:,1:l1);
                                l = length(meanTraces(iCond-1,:));
                            end
                        end
                        
                        tmp = mean(traces(iCond).trace(iTraces,:),1);
                        meanTraces(iCond,:) = tmp(1:l);
                    end   
                    if app.concat_ck.Value
                        clearConc_btn_Callback(app)
                    end
                else
                    disp(['no selected folders for dataset ' num2str(iCond)])
                end
            end
            
            %% Save
            
            if app.AutoTable_ck.Value
                if app.AutoTableAvg_ck.Value
                    z = struct2cell(outTab);
                    dim = size(z);
                    for i = 1:dim(1)
                        for j = 1:dim(2)
                            for k = 1:dim(3)
                                if isempty(z{i,j})
                                    z{i,j,k}=0;
                                end
                            end
                        end
                    end
                    z = squeeze(z)';
                    z = cell2mat(z);
                    save(app.AutoOutFileName_edit.Value, 'z');
                else
                    save(app.AutoOutFileName_edit.Value, 'outTab');
                end
            end
            
            if app.AutoFailures_ck.Value
                save([app.AutoOutFileName_edit.Value(1:end-4) '_failures.mat'], 'finalFails');
            end
            
            if app.AutoSpectra_ck.Value
                
                
                if app.AutoSpectraAvg_ck.Value %save mean power spectra
                    MeanSpectra.spectra1 = specTempMean;
                    if app.AutoWinSpectra2_ck.Value %second window
                        MeanSpectra.spectra2 = specTempMean2;
                    end
                    save([app.AutoOutFileName_edit.Value(1:end-4) '_SpectraMean.mat'], 'MeanSpectra');
                end
                if app.AutoSpectraTr_ck.Value %save spectra of single trials
                    TrialsSpectra.spectra1 = specTemp;
                    if app.AutoWinSpectra2_ck.Value %second window
                        TrialsSpectra.spectra2 = specTemp2;
                    end
                    save([app.AutoOutFileName_edit.Value(1:end-4) '_SpectraTrials.mat'], 'TrialsSpectra');
                end
            end
            
            if app.AutoSpg_ck.Value
            %     for i=1:length(handles.dataSet)
            %         save([handles.fileNameOut(1:end-4) '_spgT_' handles.dataSet{i} '.mat'  ], 'handles.spgOut(i).spgT');
            %         save([handles.fileNameOut(1:end-4) '_spgW_' handles.dataSet{i} '.mat'  ], 'handles.spgOut(i).spgW');
            %         save([handles.fileNameOut(1:end-4) '_spgMat_' handles.dataSet{i} '.mat'], 'handles.spgOut(i).spgMat');
            %     end
                tmp2 = spgOut;
                save([app.AutoOutFileName_edit.Value(1:end-4) '_spg.mat'], 'tmp2');
            end
            
            if app.AutoTrace_ck.Value
                tmp1 = [];
                tmp1(:,2:nCond+1) = meanTraces';
%                 tmp1(:,1) =(0:length(tmp1)-1)*app.sp; %GAB ENRICO 2018/12/12
                tmp1(:,1) = app.x(1:size(tmp1,1)); %GAB ENRICO 2018/12/12
                save([app.AutoOutFileName_edit.Value(1:end-4) '_meanTrace.mat'],'tmp1')
            
                for i=1:length(traces)
                    traces(i).time = app.x(1:length(traces(i).trace));
                end
                tmp2 = traces;
                save([app.AutoOutFileName_edit.Value(1:end-4) '_allTraces.mat'],'tmp2')
            end
            load gong
            sound(y,Fs)
            disp('ho finito!')            
        end

        % Button pushed function: SaveDownstSpec
        function SaveDownstSpecButtonPushed(app, event)
            SaveDownstSpectra(app,[])
        end

        % Button pushed function: analyzeData
        function analyzeData_Callback(app, event)
            % This function computes the distribution of the spectral power in the
            % 'envelope' band width. The distribution is fitted by a double gaussian
            % and the limits for the event detection are computed
                        
            app.evDet.Hcenters = [];
            app.evDet.cdist = [];
            app.evDet.cdistN = [];
            app.evDet.Hnelements = [];
            app.evDet.normNelements = [];
            
            % first, extract the data to be analyzed
            dataSource = 9;
            if get(app.detectionSPG,'Value')
                dataSource = 0;
            end
            if get(app.detectionBP1,'Value')
                if app.BPcomputed(2), dataSource = 2;
                end
            end
            if get(app.detectionBP2,'Value')
                if app.BPcomputed(3), dataSource = 3;
                end
            end
            if get(app.detectionBP3,'Value')
                if app.BPcomputed(4), dataSource = 4;
                end
            end
            if get(app.detectionHP,'Value')
                if app.BPcomputed(5), dataSource = 5;
                end
            end
            
            if dataSource < 9
                ct = app.currentTrial;
                cCh = app.currentCh;
                if dataSource == 0
                    sl = app.spgl;
                else
                    sl = app.BPpowerL(dataSource);
                end
                logFlag = get(app.powerLogCk,'Value');
                flagLeak = get (app.displayLeakCk,'Value');
                leftH = get(app.fromH, 'Value');
                rightH = get(app.toH, 'Value');
                bins = get(app.binNumber, 'Value');
            
                if (app.deleakedFlag && flagLeak)
                    % deleaked only for the SPG.
                    if logFlag
                        temp (1:sl) = log10(app.spgPlotDeleaked(cCh,ct,1:sl));
                    else
                        temp (1:sl) = app.spgPlotDeleaked(cCh,ct,1:sl);
                    end
                else
                    if logFlag
                        switch dataSource
                            case 0      % SPG
                                temp (1:sl) = log10(app.spgPlot(cCh,ct,1:sl));
                            case {2, 3, 4, 5}      % BP1, BP2, BP3, HP
                                temp (1:sl) = log10(app.BPpower(2,dataSource,cCh,ct,1:sl));
                        end
                    else
                        switch dataSource
                            case 0      % SPG
                                temp (1:sl) = app.spgPlot(cCh,ct,1:sl);
                            case {2, 3, 4, 5}      % BP1, BP2, BP3, HP
                                temp (1:sl) = app.BPpower(2,dataSource,cCh,ct,1:sl);
                        end
                    end
                end
            
                % check about auto scaling or not
                if leftH==0, [nelements,centers] = hist(temp,bins);
                else
                   xValues = leftH:(rightH-leftH)/bins:rightH;
                   [nelements,centers] = hist(temp,xValues);
                end
            
                % compute the 1) normalized histo, 2) cumulative distribution, 3)
                % normalized cumulative distribution
                totalN = sum (nelements);
                app.evDet.normNelements = nelements/totalN;
                for j=1:bins+1
                    app.evDet.cdist (j) = sum(nelements(1:j));
                end
                app.evDet.cdistN = app.evDet.cdist / totalN;
            
                app.evDet.Hcenters = centers;
                app.evDet.Hnelements = nelements;
            
                % plot the histogram
                % axes (app.histoPlot);
                cla(app.histoPlot)
                bar(app.histoPlot,centers,nelements);
                %axis ([leftH rightH 0 inf], 'auto y');
                app.histoPlot.XLim = [leftH rightH];
                app.histoPlot.YLim = [0 inf];
                app.histoPlot.YLimMode = 'auto';
                app.evDet.meanPW(cCh,ct) = mean(temp);
                app.evDet.stdPW(cCh,ct) = std(temp);
                nsamples = length(centers);
                nmeasures = sum(nelements);
            
                tll = app.evDet.meanPW(cCh,ct)-app.evDet.stdPW(cCh,ct)/3;
                set(app.baselineThr,'Value',tll);
                tll = app.evDet.meanPW(cCh,ct)+app.evDet.stdPW(cCh,ct)/3;
                set(app.eventThr,'Value',tll);
            
                %f = fit(centers.',nelements.','gauss2');
            
                % fit the histogram with a double gaussian. 10.0266 = 2*2*sqr(2*pi)
                param(2)= app.evDet.meanPW(cCh,ct)-app.evDet.stdPW(cCh,ct); % mean first component
                param(3)= app.evDet.stdPW(cCh,ct)/2;                      % SD first component
                amplitude(1)= nmeasures/(10.0266*param(3));                 % amplitude first component
                param(5)= app.evDet.meanPW(cCh,ct)+app.evDet.stdPW(cCh,ct); % mean second component
                param(6)= app.evDet.stdPW(cCh,ct)/2;                      % SD second component
                amplitude(2)= nmeasures/(10.0266*param(6));                 % amplitude first component
                gaussFnc = [];
            
                % July 10, 2017. First, fit with fixed means and STDs to obtain a
                % preliminary estimates of the gaussian amplitudes.
                ampliOut = fminsearch(@gaussFit1,amplitude);
                param(1) = ampliOut(1);
                param(4) = ampliOut(2);
                % next, recompute everything.
                paramOut = fminsearch(@gaussFit,param);
            
                % paramOut contains the 6 parameters that define the best fit.
                st = ['Gauss 1; mean:' num2str(paramOut(2),'%2.2f') ' ±SD: ' num2str(paramOut(3),'%2.2f')];
                set (app.gauss1param, 'Text', st);
                st = ['Gauss 2; mean:' num2str(paramOut(5),'%2.2f') ' ±SD: ' num2str(paramOut(6),'%2.2f')];
                set (app.gauss2param, 'Text', st);
            
                plot(app.histoPlot,centers,gaussFnc,'red','LineWidth',2);
                yl = app.histoPlot.YLim;
                line(app.histoPlot,[paramOut(2)+paramOut(3) paramOut(2)+paramOut(3)],[yl(1) yl(2)]);
                line(app.histoPlot,[paramOut(5)-paramOut(6) paramOut(5)-paramOut(6)],[yl(1) yl(2)]);
            
                % now compute the state thresholds
                xinterc = fzero(@gaussDiff,(paramOut(2)+paramOut(5))/2);
            
                app.evDet.autoblThr = xinterc;
                app.evDet.autoevThr = xinterc;
                
                disp('Data analyzed')
            end
            
                function res = gaussFit(a)
                    % gmodel = a(1)*exp(-(centers-a(2))^2/2*a(3).^2) + a(4)*exp(-(centers-a(5))^2/2*a(6).^2)
                    % nelements is the array that contains the histogram bin counts: this
                    % is the function to fit
                    gaussFnc = a(1)*exp(-(centers-a(2)).^2/(2*(a(3)^2))) + a(4)*exp(-(centers-a(5)).^2/(2*(a(6)^2)));
                    res = sum((nelements-gaussFnc).^2);
                end
                function res = gaussFit1(a)
                    % gmodel = a(1)*exp(-(centers-a(2))^2/2*a(3).^2) + a(4)*exp(-(centers-a(5))^2/2*a(6).^2)
                    % nelements is the array that contains the histogram bin counts: this
                    % is the function to fit
                    gaussFnc = a(1)*exp(-(centers-param(2)).^2/(2*(param(3)^2))) + a(2)*exp(-(centers-param(5)).^2/(2*(param(6)^2)));
                    res = sum((nelements-gaussFnc).^2);
                end
            
                function y = gaussDiff(x)
                    y = paramOut(1)*exp(-(x-paramOut(2)).^2/(2*(paramOut(3)^2)));
                    y = y - paramOut(4)*exp(-(x-paramOut(5)).^2/(2*(paramOut(6)^2)));
                end
        end

        % Button pushed function: exportTab_btn
        function exportTab_btn_Callback(app, event)
            % hObject    handle to exportTab_btn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            tmp = app.evDet.UStable.Data;
            splitDir = regexp(app.dir_in,'\','split');
            outName = [app.dir_in, '\UStable_' splitDir{end-1} '.mat'];
            save(outName,'tmp');
        end

        % Button pushed function: extractEvents
        function extractEvents_Callback(app, event)
            % refers to the 'compute' button            
            app.evDet.upstates = struct('fromI',1,'fromT',1,'toI',1,'toT',1,'deltaT',1,'medianV',1,'SD',1,'pw',1,...
                'medianV_hem2', 1, 'SD_hem2', 1, 'pw_hem2', 1, 'enable',1);
            app.evDet.downstates = struct('fromI',1,'fromT',1,'toI',1,'toT',1,'deltaT',1,'medianV',1,'SD',1,'pw',1,...
                'medianV_hem2', 1, 'SD_hem2', 1, 'pw_hem2', 1, 'enable',1);
            
            app.evDet.SPG = [];
            app.evDet.LFP = [];
            app.evDet.LFP_hem2 = [];
            app.evDet.timeSPG = [];
            app.evDet.upstates.fromI = [];
            app.evDet.upstates.toI = [];
            app.evDet.downstates.fromI = [];
            app.evDet.downstates.toI = [];
            app.evDet.USlist = [];
            app.evDet.USlist = {1 1 1 1 1 1 1 1 1 1};
            
            % set the threshold for event detection based on the used method
            if get(app.autoThr,'Value') % if autosetting state thresshold is clicked, automatically determine threshold
                lowThr = app.evDet.autoblThr; % this value is calculated later in analyzeData function based on power distribution!
                highThr = app.evDet.autoevThr; % this value is calculated later in analyzeData function based on power distribution!
            else
                lowThr = get(app.baselineThr,'Value'); %otherwise, obtain user defined threshold values
                highThr = get(app.eventThr,'Value');
            end
            
            % Identify the data set to be analyzed
            dataSource = 9;
            if get(app.detectionBP1,'Value'),
                if app.BPcomputed(2), dataSource = 2; %BPcomputed is a vector containing a 0 if the
                   % specific band pass has not been analyzed, and a 1 if it has been analyzed
                end
            end
            if get(app.detectionBP2,'Value'),
                if app.BPcomputed(3), dataSource = 3;
                end
            end
            if get(app.detectionBP3,'Value'),
                if app.BPcomputed(4), dataSource = 4;
                end
            end
            if get(app.detectionHP,'Value'),
                if app.BPcomputed(5), dataSource = 5;
                end
            end
            if get(app.detectionSPG,'Value'), dataSource = 0;
            end
            
            if dataSource < 9
                ct = app.currentTrial;
                cCh = app.currentCh;
                len = app.dtaLen;
                spp = app.sp;
                logFlag = get(app.powerLogCk,'Value'); % if 'log of envelope power' is checked
                flagLeak = get (app.displayLeakCk,'Value');
                app.evDet.tmin = get(app.leftTime,'Value'); % time values entered by user in the bottom left box
                app.evDet.tmax = get(app.rightTime,'Value');
            
                app.evDet.LFP(1:len) = app.workLFP (cCh,ct,1:len);
                if app.nCh == 2
                    if cCh == 1
                        Ch_hem2 = 2;
                    elseif cCh == 2
                        Ch_hem2 = 1;
                    end
                    app.evDet.LFP_hem2(1:len) = app.workLFP (Ch_hem2, ct, 1:len);
                end
            
            
                if dataSource == 0          % length of the spectral power file
                    sl = app.spgl;
                    app.evDet.timeSPG = app.spgt;
                else
                    sl = app.BPpowerL(dataSource); % length of the vector power of band passed data
                    app.evDet.timeSPG(1:sl) = app.BPpower(1,dataSource,cCh,ct,1:sl); % spectrogram data itself for current BP setting
                end
            
                if (app.deleakedFlag && flagLeak) % If we want the deleaked version of the spg
                    if logFlag, app.evDet.SPG (1:sl) = log10(app.spgPlotDeleaked(cCh,ct,1:sl)); %calculate the log of spg if clicked
                    else
                        app.evDet.SPG (1:sl) = app.spgPlotDeleaked(cCh,ct,1:sl);
                    end
                else
                    if logFlag % if not the deleaked version, but log is clicked:
                        switch dataSource
                            case 0      % SPG
                                app.evDet.SPG (1:sl) = log10(app.spgPlot(cCh,ct,1:sl));
                            case {2, 3, 4, 5}      % BP1, BP2, BP3, HP
                                app.evDet.SPG (1:sl) = log10(app.BPpower(2,dataSource,cCh,ct,1:sl));
                        end
                    else % finally, if not the the deleaked version, and log is not clicked:
                        app.evDet.SPG (1:sl) = app.spgPlot(cCh,ct,1:sl);
                        switch dataSource
                            case 0      % SPG
                                app.evDet.SPG (1:sl) = app.spgPlot(cCh,ct,1:sl);
                            case {2, 3, 4, 5}      % BP1, BP2, BP3, HP
                                app.evDet.SPG (1:sl) = app.BPpower(2,dataSource,cCh,ct,1:sl);
                        end
                    end
                end
            
                downStatesBolean = app.evDet.SPG < lowThr; %logical array of SPG below threshold
                downStatesI = find(app.evDet.SPG< lowThr); % indexes of SPG where it is below threshold (i.e. is downstate)
                upStatesBolean = app.evDet.SPG > highThr; % same for up states (above threshold)
                upStatesI = find(app.evDet.SPG>highThr);
                limboBolean = ~(downStatesBolean | upStatesBolean); % I don't understand this, it seems to create an empty logical array
            
                % now the vector app.evDet.SPGt (downstatesBolean) contains the timing of the
                % upstates. This has to be converted to the indexes of the workLFP file
            
                lus = length (upStatesI);
                lds = length (downStatesI);
            
                % process upstates first
                if lus>0
                    firstI = upStatesI (1);
                    firstTime = app.evDet.timeSPG (firstI);
                    app.evDet.upstates.fromT (1) = firstTime;
                    app.evDet.upstates.fromI (1) = 1+firstTime/spp; % spp is the sampling period, this converts the time to the LFP index
                    nus = 1; % keeps track of the number of up states
                    for i=2:lus
                        nextI = upStatesI(i); % loop through all the indexes with higher than threshold value
                        if nextI==firstI+1
                            % continue the old US
                            firstI=nextI;
                            if i == lus
                                % we have reached the end of the track. close the US
                                endTime = app.evDet.timeSPG (firstI);
                                app.evDet.upstates.toT (nus) = endTime;
                                app.evDet.upstates.toI (nus) = int32(1+endTime/spp);
                                app.evDet.upstates.deltaT (nus) = endTime-app.evDet.upstates.fromT (nus);
                                app.evDet.upstates.enable (nus) = 1;
                            end
                        else
                            firstTime = app.evDet.timeSPG (firstI);
                            % beginning of a new US. Close the old US
                            app.evDet.upstates.toT (nus) = firstTime;
                            app.evDet.upstates.toI (nus) = int32(1+firstTime/spp);
                            app.evDet.upstates.deltaT (nus) = firstTime-app.evDet.upstates.fromT (nus);
                            app.evDet.upstates.enable (nus) = 1;
                            nus = nus + 1;
                            % open new US
                            app.evDet.upstates.fromT (nus) = app.evDet.timeSPG (nextI);
                            app.evDet.upstates.fromI (nus) =  int32(1+app.evDet.timeSPG (nextI)/spp);
                            firstI = nextI;
                        end
                    end
                end
            
                nus = length (app.evDet.upstates.toI);      % just to make sure...
            
                % process downstates
                if nus>0
                    firstI = downStatesI (1);
                    firstTime = app.evDet.timeSPG (firstI);
                    app.evDet.downstates.fromT (1) = firstTime;
                    app.evDet.downstates.fromI (1) = 1+firstTime/spp;
                    nds = 1;
            
                    for i=2:lds
                        nextI = downStatesI(i);
                        if nextI==firstI+1
                            % continue the old DS
                            firstI=nextI;
                             if i == lds
                                  % we have reached the end of the track. close the DS
                                  endTime = app.evDet.timeSPG (firstI);
                                  app.evDet.downstates.toT (nds) = endTime;
                                  app.evDet.downstates.toI (nds) = int32(1+endTime/spp);
                                  app.evDet.downstates.deltaT (nds) = endTime-app.evDet.downstates.fromT (nds);
                                  app.evDet.downstates.enable (nds) = 1;
                             end
                        else
                            firstTime = app.evDet.timeSPG (firstI);
                            % beginning of a new DS. Close the old DS
                            app.evDet.downstates.toT (nds) = firstTime;
                            app.evDet.downstates.toI (nds) = int32(1+firstTime/spp);
                            app.evDet.downstates.deltaT (nds) = firstTime-app.evDet.downstates.fromT (nds);
                            app.evDet.downstates.enable (nds) = 1;
                            nds = nds + 1;
                            % open new DS
                            app.evDet.downstates.fromT (nds) = app.evDet.timeSPG (nextI);
                            app.evDet.downstates.fromI (nds) =  int32(1+app.evDet.timeSPG (nextI)/spp);
                            firstI = nextI;
                        end
                    end
                end
                nds = length(app.evDet.downstates.toI);
            
            
                % defragment DS and US across short state gap. The max size of the filled
                % gap is defined in the GUI
                % These are the rules: a brief interruption shorter than maxInterruption is
                % filled in by assigning to the neighboring US that are fused in a longer
                % one. Isolated US briefer than minDuration are attributed to the limbo.
            
                maxGap = get(app.maxInterruption,'Value');
                minUS = get(app.minDuration,'Value');
            
                if get(app.removeCk,'Value') %if the remove state interruptions button is clicked
                    % process US first
                    usi = 2;
                    while usi <= nus % there must be at least two up states to do this operation, loop through from 2 till nus
                        % perform the fusions first since brief US might get fused together
                        % to form a longer and legal US.
                        interval = [app.evDet.upstates.fromT(usi) app.evDet.upstates.toT(usi-1)];
                        distance = interval(1) - interval(2);
                        if (distance<=maxGap)
                            % first fuse the two upstates
                            app.evDet.upstates.toT(usi-1) = app.evDet.upstates.toT(usi);
                            app.evDet.upstates.toI(usi-1) = app.evDet.upstates.toI(usi);
                            app.evDet.upstates.deltaT(usi-1) = app.evDet.upstates.toT(usi-1) - app.evDet.upstates.fromT(usi-1);
                            % second remove the US pointed to by usi
                            for k = usi:nus-1
                                % shift all USs
                                app.evDet.upstates.toT(k) = app.evDet.upstates.toT(k+1);
                                app.evDet.upstates.toI(k) = app.evDet.upstates.toI(k+1);
                                app.evDet.upstates.fromT(k) = app.evDet.upstates.fromT(k+1);
                                app.evDet.upstates.fromI(k) = app.evDet.upstates.fromI(k+1);
                                app.evDet.upstates.deltaT(k) = app.evDet.upstates.deltaT (k+1);
                            end
                            nus = nus - 1;
                        else
                            usi = usi+1;
                        end
                    end
                    % remove brief states
                    for usi=1:nus
                        if app.evDet.upstates.deltaT(usi) <= minUS, app.evDet.upstates.enable(usi) = 0;
                        end
                    end
                    % remove the disabled states from the list
                    cnt = nus;
                    for usi=nus:-1:1
                        if app.evDet.upstates.enable(usi)
                            % do nothing
                        else
                            cnt = cnt - 1;
                            % shift down if the US is not the last one of the track
                            if usi < nus
                                app.evDet.upstates.fromI (cnt:-1:usi) = app.evDet.upstates.fromI (cnt+1:-1:usi+1);
                                app.evDet.upstates.toI (cnt:-1:usi) = app.evDet.upstates.toI (cnt+1:-1:usi+1);
                                app.evDet.upstates.fromT (cnt:-1:usi) = app.evDet.upstates.fromT (cnt+1:-1:usi+1);
                                app.evDet.upstates.toT (cnt:-1:usi) = app.evDet.upstates.toT (cnt+1:-1:usi+1);
                                app.evDet.upstates.deltaT (cnt:-1:usi) = app.evDet.upstates.deltaT (cnt+1:-1:usi+1);
                                app.evDet.upstates.enable (cnt:-1:usi) = app.evDet.upstates.enable (cnt+1:-1:usi+1);
                            end
                        end
                    end
                    nus = cnt;
            
                    % process DS
                    for usi=2:nds
                        interval = [app.evDet.downstates.fromT(usi) app.evDet.downstates.toT(usi-1)];
                        distance = interval(1) - interval(2);
                        if (distance<=maxGap)
                            % fuse the contiguous downstates
                            app.evDet.downstates.toT(usi-1) = app.evDet.downstates.toT(usi);
                            app.evDet.downstates.toI(usi-1) = app.evDet.downstates.toI(usi);
                            app.evDet.downstates.deltaT(usi-1) = app.evDet.downstates.toT(usi-1) - app.evDet.downstates.fromT(usi-1);
                            app.evDet.downstates.enable(usi) = 0;
                        end
                    end
            
                    for usi=1:nds
                        if app.evDet.downstates.deltaT(usi) <= minUS, app.evDet.downstates.enable(usi) = 0;
                        end
                    end
            
                    cnt = nds;
                    for usi=nds:-1:1
                        if app.evDet.downstates.enable(usi)
                            % do nothing
                        else
                            cnt = cnt - 1;
                            % shift down
                            if usi < nds
                                app.evDet.downstates.fromI (cnt:-1:usi) = app.evDet.downstates.fromI (cnt+1:-1:usi+1);
                                app.evDet.downstates.toI (cnt:-1:usi) = app.evDet.downstates.toI (cnt+1:-1:usi+1);
                                app.evDet.downstates.fromT (cnt:-1:usi) = app.evDet.downstates.fromT (cnt+1:-1:usi+1);
                                app.evDet.downstates.toT (cnt:-1:usi) = app.evDet.downstates.toT (cnt+1:-1:usi+1);
                                app.evDet.downstates.deltaT (cnt:-1:usi) = app.evDet.downstates.deltaT (cnt+1:-1:usi+1);
                                app.evDet.downstates.enable (cnt:-1:usi) = app.evDet.downstates.enable (cnt+1:-1:usi+1);
                            end
                        end
                    end
                    nds = cnt;
                end
            
            
                % refine the edges of the state detection. Experimental and not great
                % not to be used as of March 22, 2017
                refineEdges (app);
            
                % At this stage all US and DS have been extracted and we can compute the
                % relative metrics.
                % Compute median, PW and SD of each US and DS.
                for usi=1:nds   % compute DS first since you need this to compute US size
                   i1 = app.evDet.downstates.fromI(usi);
                   i2 = app.evDet.downstates.toI(usi);
                   app.evDet.downstates.medianV(usi) = median(app.evDet.LFP(i1:i2));
                   app.evDet.downstates.SD(usi) = std(app.evDet.LFP(i1:i2));
                   app.evDet.downstates.pw(usi) = rms(app.evDet.LFP(i1:i2));
                   if app.nCh == 2
                       app.evDet.downstates.medianV_hem2(usi) = median(app.evDet.LFP_hem2(i1:i2));
                       app.evDet.downstates.SD_hem2(usi) = std(app.evDet.LFP_hem2(i1:i2));
                       app.evDet.downstates.pw_hem2(usi) = rms(app.evDet.LFP_hem2(i1:i2));
                   end
                end
            
                DSsearch = 1;
                dsi1 = app.evDet.downstates.fromI(DSsearch);
                dsi2 = app.evDet.downstates.toI(DSsearch);
                for usi=1:nus
                   i1 = app.evDet.upstates.fromI(usi);
                   i2 = app.evDet.upstates.toI(usi);
                   app.evDet.upstates.medianV(usi) = median(app.evDet.LFP(i1:i2));
                   app.evDet.upstates.SD(usi) = std(app.evDet.LFP(i1:i2));
                   app.evDet.upstates.pw(usi) = rms(app.evDet.LFP(i1:i2));
                   if app.nCh == 2
                       app.evDet.upstates.medianV_hem2(usi) = median(app.evDet.LFP_hem2(i1:i2));
                       app.evDet.upstates.SD_hem2(usi) = std(app.evDet.LFP_hem2(i1:i2));
                       app.evDet.upstates.pw_hem2(usi) = rms(app.evDet.LFP_hem2(i1:i2));
                   end
                   % now search for the closest DS
                   while dsi2 < i1 && DSsearch <= nds % while the end of the downstate is smaller than the start of the current up
                       % state and smaller than the number of down states
                       DSsearch = DSsearch + 1; % it will increase by one: we found the down state following the current up state
                       if DSsearch <= nds
                           dsi2 = app.evDet.downstates.toI(DSsearch);
                       end
                   end
                   % now DSsearch points to the DS immediatey after unless the data ends
                   % with a US. A second exception is when the data starts with an US
                   if DSsearch == 1     % data begins with US
                       baseline = app.evDet.downstates.medianV(1);
                       if app.nCh == 2
                           baseline_hem2 = app.evDet.downstates.medianV_hem2(1);
                       end
                   else
                       if DSsearch > nds    % data ends up with a US
                           baseline = app.evDet.downstates.medianV(nds);
                           if app.nCh == 2
                               baseline_hem2 = app.evDet.downstates.medianV_hem2(nds);
                           end
                       else
                           % OK, this is a middle of the road US!
                           baseline = (app.evDet.downstates.medianV(DSsearch)+app.evDet.downstates.medianV(DSsearch-1))/2;
                           if app.nCh == 2
                               baseline_hem2 = (app.evDet.downstates.medianV_hem2(DSsearch)+app.evDet.downstates.medianV_hem2(DSsearch-1))/2;
                           end
                       end
                   end
                   % this is added by Didi, in order to access the value later, so that
                   % I can add a downward going filter
                   newmedianV(usi) = app.evDet.upstates.medianV(usi) - baseline;
                   if app.nCh == 2
                        newmedianV_hem2(usi) = app.evDet.upstates.medianV_hem2(usi) - baseline_hem2;
                   end
                end
            
                % This part is added by Didi in November, 2018. Meant to remove
                % upstates from the list that have a positive medianV (i.e. that are
                % just artefacts)
                for us = 1:nus
                   i1 = app.evDet.upstates.fromI(us);
                   i2 = app.evDet.upstates.toI(us);
                    if newmedianV(us) > 0
                        app.evDet.upstates.enable(us) = 0;
                    end
                end
            
                % now remove the disabled up states from the list as was done above
                cnt = nus;
                for usi=nus:-1:1
                    if app.evDet.upstates.enable(usi)
                    % do nothing
                    else
                        cnt = cnt - 1;
                        % shift down if the US is not the last one of the track
                        if usi < nus
                            app.evDet.upstates.fromI (cnt:-1:usi) = app.evDet.upstates.fromI (cnt+1:-1:usi+1);
                            app.evDet.upstates.toI (cnt:-1:usi) = app.evDet.upstates.toI (cnt+1:-1:usi+1);
                            app.evDet.upstates.fromT (cnt:-1:usi) = app.evDet.upstates.fromT (cnt+1:-1:usi+1);
                            app.evDet.upstates.toT (cnt:-1:usi) = app.evDet.upstates.toT (cnt+1:-1:usi+1);
                            app.evDet.upstates.deltaT (cnt:-1:usi) = app.evDet.upstates.deltaT (cnt+1:-1:usi+1);
                            app.evDet.upstates.enable (cnt:-1:usi) = app.evDet.upstates.enable (cnt+1:-1:usi+1);
                            app.evDet.upstates.medianV(cnt:-1:usi) = app.evDet.upstates.medianV(cnt+1:-1:usi+1);
                            newmedianV(cnt:-1:usi) = newmedianV(cnt+1:-1:usi+1);
                            app.evDet.upstates.SD(cnt:-1:usi) = app.evDet.upstates.SD(cnt+1:-1:usi+1);
                            app.evDet.upstates.pw(cnt:-1:usi) = app.evDet.upstates.pw(cnt+1:-1:usi+1);
                            if app.nCh == 2
                                app.evDet.upstates.medianV_hem2(cnt:-1:usi) = app.evDet.upstates.medianV_hem2(cnt+1:-1:usi+1);
                                newmedianV_hem2(cnt:-1:usi) = newmedianV_hem2(cnt+1:-1:usi+1);
                                app.evDet.upstates.SD_hem2(cnt:-1:usi) = app.evDet.upstates.SD_hem2(cnt+1:-1:usi+1);
                                app.evDet.upstates.pw_hem2(cnt:-1:usi) = app.evDet.upstates.pw_hem2(cnt+1:-1:usi+1);
                            end
                        end
                    end
                end
                nus = cnt;
            
                app.evDet.NUS = nus;
                app.evDet.NDS = nds;
            
                % now create the list:
            
                for usi = 1:nus
                   app.evDet.USlist (usi,5) = num2cell(newmedianV(usi));
                   app.evDet.USlist (usi,6) = num2cell(app.evDet.upstates.SD(usi));
                   app.evDet.USlist (usi,7) = num2cell(app.evDet.upstates.pw(usi));
                   if app.nCh == 2
                       app.evDet.USlist (usi,8) = num2cell(newmedianV_hem2(usi));
                       app.evDet.USlist (usi,9) = num2cell(app.evDet.upstates.SD_hem2(usi));
                       app.evDet.USlist (usi,10) = num2cell(app.evDet.upstates.pw_hem2(usi));
                   end
                end
            
                % Creation of the list of USs
                %app.evDet.USlist (1,1) = num2cell(1);
                %app.evDet.USlist (1,2) = num2cell(app.evDet.upstates.fromT(1));
                app.evDet.USlist (1:nus,1) = num2cell(1:nus); % the first cell contains the up state number
                app.evDet.USlist (1:nus,2) = num2cell(app.evDet.upstates.fromT(1:nus)); % the second cell contains the start
                % time of the corresponding up state
                app.evDet.USlist (1:nus,3) = num2cell(app.evDet.upstates.toT(1:nus)); % then the end time
                app.evDet.USlist (1:nus,4) = num2cell(app.evDet.upstates.toT(1:nus) - app.evDet.upstates.fromT(1:nus)); % and the length of the up state
            
                % Didi February 2019: Add Number of Multipeak Waves for both hemispheres. Based on the set of three papers Tononi lab: Sleep Homeostasis 
                % and Cortical Synchronization. This analysis is going to use the Band Passed filter data in the BP1 range. Here, an up state is represented
                % as a negative deflection of the signal. Multipeak waves are defined as waves with more than 1 negative peak between 2 zero crossings. So 
                % here, I took the BP1 filtered LFP from the start till the end of each up state and used the findpeaks function of matlabs Signal Processing
                % Toolbox ot find negative peak number of both hemispheres. 
                
                % start by setting up the parameters to find multipeaks of the current hemispere
                multipeak = zeros(app.evDet.NUS, 1);
                deltaBP(1:app.dtaLen) = app.bandPassed_LFP(2,app.currentCh, app.currentTrial, 1:app.dtaLen);
                
                % Now start looping through the up states and find peaks
                for usn = 1:app.evDet.NUS
                    cnt = 0;
                    start = app.evDet.upstates.fromI(usn);
                    stop = app.evDet.upstates.toI(usn);
                    pks = findpeaks(-deltaBP(start:stop));
                    if isempty(pks)
                        cnt = 0;
                    else
                    cnt = length(pks);
                    end
                    multipeak(usn) = cnt;
                end                
                
                % same to find peaks of other hemisphere
                if app.nCh == 2
                    multipeak_hem2 = zeros(app.evDet.NUS, 1);
                    deltaBP_hem2(1:app.dtaLen) = app.bandPassed_LFP(2,Ch_hem2, app.currentTrial, 1:app.dtaLen);
                    for usn2 = 1:app.evDet.NUS
                        cnt2 = 0;
                        start2 = app.evDet.upstates.fromI(usn2);
                        stop2 = app.evDet.upstates.toI(usn2);
                        pks2 = findpeaks(-deltaBP_hem2(start2:stop2));
                        if isempty(pks2)
                            cnt2 = 0;
                        else
                            cnt2 = length(pks2);
                        end
                    multipeak_hem2(usn2) = cnt2;
                    end 
                end
                
               % End of the finding of multipeak waves. 
               
               % Didi February 2019: Finding slopes of the slow waves. Based on the set of three papers Tononi lab: Sleep Homeostasis and Cortical 
               % Synchronization. Here, we take the 0.5-4 Hz (BP1) filtered LFP, where an up state is represented as a negative delfection. We define
               % both the average slope, and the maximum slope of the first and second segment of both hemispheres. Average slope is defined as the 
               % amplitude of the most negative peak, divided by the time of the previous zero crossing till the peak (for the first segment) or from 
               % the peak till the next zero crossing (for the second segment). Maximum slopes were defined as the maximum of the signal derivative 
               % (after applying a 50-millisecond moving average) following the negative zero crossing (first-segment slope) but before the most negative
               % peak, or subsequent to the most negative peak (second-segment slope) but prior to the positive-going zero crossing.
                
                
                % Add the up state info to the table in the GUI
                set(app.UStable,'Data',app.evDet.USlist);
                        
                % plot the state IDs
                plotStatesFun (app)
            
                % create the joined files
                app.evDet.US = [];
                app.evDet.DS = [];
            
                ifrom = app.evDet.upstates.fromI(1);
                ito = app.evDet.upstates.toI(1);
                app.evDet.US = app.evDet.LFP(ifrom:ito);
            
                for usi=2:nus
                    ifrom = app.evDet.upstates.fromI(usi);
                    ito = app.evDet.upstates.toI(usi);
                    if get(app.joinOffsetChk,'Value')
                        offset = app.evDet.LFP(ifrom)-app.evDet.US(end);
                        app.evDet.US = [app.evDet.US app.evDet.LFP(ifrom:ito)-offset];
                    end
                    if get(app.joinCk,'Value'), app.evDet.US = [app.evDet.US tempData(ifrom:ito)];
                    end
                end
            
                for usi=1:nds
                    ifrom = app.evDet.downstates.fromI(usi);
                    ito = app.evDet.downstates.toI(usi);
                    if get(app.joinOffsetChk,'Value') %Gab
                        if ~isempty(app.evDet.DS) %Gab
                            offset = app.evDet.LFP(ifrom)-app.evDet.DS(end); %Gab
                            app.evDet.DS = [app.evDet.DS app.evDet.LFP(ifrom:ito)-offset];    %Gab
                        else %Gab
                            app.evDet.DS = [app.evDet.DS app.evDet.LFP(ifrom:ito)]; %Gab
                        end %Gab
                    else %Gab
                    app.evDet.DS = [app.evDet.DS app.evDet.LFP(ifrom:ito)];
                    end
                end
            
                % and finally lets compute the power spectra!
                app.params = struct('tapers',[],'Fs',1,'fpass',[]);
            
                flagChronux = get(app.ChronuxCk,'Value');
                if flagChronux
                    params.tapers = [5 9] ;%[5 9];
                    params.Fs = app.acqF;
                    params.fpass = [0 1000];
                    [app.evDet.pssUS,app.evDet.fUS] = mtspectrumc(app.evDet.US, params);
                    app.evDet.fUS = app.evDet.fUS';
                    [app.evDet.pssDS,app.evDet.fDS] = mtspectrumc(app.evDet.DS, params);
                    app.evDet.fDS = app.evDet.fDS';
                else
                    [app.evDet.pssUS,app.evDet.fUS] = pwelch(app.evDet.US,[],[],[],app.acqF);
                    [app.evDet.pssDS,app.evDet.fDS] = pwelch(app.evDet.DS,[],[],[],app.acqF);
                end
                %lUS = size(app.evDet.fUS);
                %lDS = size(app.evDet.fDS);
            
%                 axes(app.powerSpectra);
%                 hold on;
%                 lim = axis;
%                 cla
                cla(app.powerSpectra);
                lim = [app.powerSpectra.XLim, app.powerSpectra.YLim];
                
                %flagN = get(app.spectreData.normSpectraCk,'Value');
                % plot the mean traces
                plot(app.powerSpectra,app.evDet.fUS,10*log10(app.evDet.pssUS.'),'red');            % spectra in decibels
                plot(app.powerSpectra,app.evDet.fDS,10*log10(app.evDet.pssDS.'),'green');
            
                lim(1) = get(app.minFreq,'Value');
                lim(2) = get(app.maxFreq,'Value');
                lim(3) = get(app.minPw,'Value');
                lim(4) = get(app.maxPw,'Value');
                %axis (lim);
                app.powerSpectra.XLim = [lim(1) lim(2)];
                app.powerSpectra.YLim = [lim(3) lim(4)];
            
                set(app.powerSpectra,'XScale','log');
                app.powerSpectra.XLabel.String = 'Frequency (Hz)';
                app.powerSpectra.YLabel.String ='Magnitude (dB)';
            end
        end

        % Button pushed function: replot
        function replot_Callback(app, event)
            plotStatesFun(app,[])
        end

        % Button pushed function: saveHCD
        function saveHCD_Callback(app, event)
            % Save the distributions of the integrated spectral power
            % March 22, 2017.
            
            tmpOut1 = [];
            
            cTr = app.currentTrial;
            cCh = app.currentCh;
            
            fileOut = [app.dir_in 'PWSdistribution Ch' num2str(cCh) ' Trial' num2str(cTr) '.dat'];
            tmpOut1 (1,:) = app.evDet.Hcenters;
            tmpOut1 (2,:) = app.evDet.Hnelements;
            tmpOut1 (3,:) = app.evDet.normNelements;
            tmpOut1 (4,:) = app.evDet.cdist;
            tmpOut1 (5,:) = app.evDet.cdistN;
            
            % create the format descriptor
            fmtSt=['%12.10f %12.10f %12.10f %12.10f %12.10f\n'];  % 5 columns and start new line
            fid = fopen(fileOut,'w');
            fprintf (fid,fmtSt,tmpOut1);
            fclose(fid);
        end

        % Button pushed function: savePWS
        function savePWS_Callback(app, event)
            % save as two different files the PS computed from the defragmented UP and
            % Downstate cumulative files.
                        
            cTr = app.currentTrial;
            cCh = app.currentCh;
            
            fileOutUS = [app.dir_in 'USpws Ch' num2str(cCh) ' Trial' num2str(cTr) '.dat'];
            fileOutDS = [app.dir_in 'DSpws Ch' num2str(cCh) ' Trial' num2str(cTr) '.dat'];
            usl = length (app.evDet.fUS);
            tmpOut1 = [];
            tmpOut2 = [];
            % US and DS have to be saved as separate files since they have different x
            % axis due to the variable length of the consolidated data.
            tmpOut1 (1,:) = app.evDet.fUS;
            tmpOut1 (2,:) = app.evDet.pssUS;
            tmpOut1 (3,:) = 10*log10(app.evDet.pssUS);
            
            tmpOut2 (1,:) = app.evDet.fDS;
            tmpOut2 (2,:) = app.evDet.pssDS;
            tmpOut2 (3,:) = 10*log10(app.evDet.pssDS);
            
            % create the format descriptor
            fmtSt=['%12.10f %12.10f %12.10f\n'];       % 3 columns and start new line
            fid = fopen(fileOutUS,'w');
            fprintf (fid,fmtSt,tmpOut1);
            fclose(fid);
            fid = fopen(fileOutDS,'w');
            fprintf (fid,fmtSt,tmpOut2);
            fclose(fid);
        end

        % Button pushed function: saveSegmentedStates
        function saveSegmentedStates_Callback(app, event)
            %GAB: saves 2 separate files for up & down states
            currCh=num2str(app.currentCh);
            currTr=num2str(app.currentTrial);
            fmt='%8.6f \n';
            fid=fopen([app.dir_in 'DS_ch' currCh '_tr' currTr '.dat'],'w');
            fprintf(fid,fmt,app.evDet.DS);
            fid=fopen([app.dir_in 'US_ch' currCh '_tr' currTr '.dat'],'w');
            fprintf(fid,fmt,app.evDet.US);
            fclose(fid);
        end

        % Button pushed function: traslateLeft
        function traslateLeft_Callback(app, event)
            app.evDet.tmin = get(app.leftTime,'Value');
            app.evDet.tmax = get(app.rightTime,'Value');
            delta = app.evDet.tmax - app.evDet.tmin;
            
            set(app.leftTime,'Value',app.evDet.tmin-delta);
            set(app.rightTime,'Value',app.evDet.tmin);
            
            plotStatesFun (app)
        end

        % Button pushed function: traslateRight
        function traslateRight_Callback(app, event)
            app.evDet.tmin = get(app.leftTime,'Value');
            app.evDet.tmax = get(app.rightTime,'Value');
            delta = app.evDet.tmax - app.evDet.tmin;
            
            set(app.leftTime,'Value',app.evDet.tmax);
            set(app.rightTime,'Value',app.evDet.tmax+delta);
            
            plotStatesFun(app)
        end

        % Value changed function: yminTxt
        function yminTxt_Callback(app, event)
            event = [];
            event.yLim = [app.yminTxt.Value, app.ymaxTxt.Value];
            plotWideWindow(app,event)
            plotMultiBand(app)
            plotSpectrogram(app)
        end

        % Button pushed function: ResetButton
        function ResetButton_Callback(app, event)
            plotWideWindow(app,[])
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ZebraMainFig = uifigure;
            app.ZebraMainFig.Name = 'ZebraExplore';
            app.TabGroup = uitabgroup(app.ZebraMainFig);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.uipanel2 = uipanel(app.PlotTab);
            app.uipanel2.Title = 'Input Data';
            app.select_data = uibutton(app.uipanel2, 'push');
            app.select_data.ButtonPushedFcn = createCallbackFcn(app, @select_data_Callback, true);
            app.select_data.Text = 'Open';
            app.dataFormatPanel = uibuttongroup(app.uipanel2);
            app.dataFormatPanel.SelectionChangedFcn = createCallbackFcn(app, @dataFormatPanel_SelectionChangeFcn, true);
            app.dataFormatPanel.Title = 'Data Format';
            app.normalDataRB = uiradiobutton(app.dataFormatPanel);
            app.normalDataRB.Text = 'Default';
            app.normalDataRB.Value = true;
            app.meyerVepRB = uiradiobutton(app.dataFormatPanel);
            app.meyerVepRB.Text = 'Meyer VEP';
            app.meyerSspRB = uiradiobutton(app.dataFormatPanel);
            app.meyerSspRB.Text = 'SEP';
            app.lauraEEG = uiradiobutton(app.dataFormatPanel);
            app.lauraEEG.Text = 'EEG format';
            app.axopatchCk = uiradiobutton(app.dataFormatPanel);
            app.axopatchCk.Tooltip = 'Import data in the Molecular Dynemics Axopatch format';
            app.axopatchCk.Text = 'AxoP';
            app.TRCimportRB = uiradiobutton(app.dataFormatPanel);
            app.TRCimportRB.Tooltip = 'Import data from the Micromed system';
            app.TRCimportRB.Text = 'TRC import';
            app.meaRB = uiradiobutton(app.dataFormatPanel);
            app.meaRB.Tooltip = 'Data from IMM multi electrode arrays';
            app.meaRB.Text = 'IMM MEA';
            app.excludeChFlag = uicheckbox(app.uipanel2);
            app.excludeChFlag.Text = 'Exclude Ch:';
            app.internalSynchCk = uicheckbox(app.uipanel2);
            app.internalSynchCk.Text = 'Synch on Ch:';
            app.uipanel15 = uibuttongroup(app.uipanel2);
            app.mVck = uiradiobutton(app.uipanel15);
            app.mVck.Tooltip = 'Click here if data are expressed in mV';
            app.mVck.Text = 'mV';
            app.mVck.Value = true;
            app.microVck = uiradiobutton(app.uipanel15);
            app.microVck.Tooltip = 'Click here if data are expressed in micro V';
            app.microVck.Text = 'μV';
            app.autoclear = uicheckbox(app.uipanel2);
            app.autoclear.Tooltip = 'Clear spreadsheet in Zebra Says when a new data is loaded in';
            app.autoclear.Text = 'Autoclear';
            app.autoclear.Value = true;
            app.openDir = uibutton(app.uipanel2, 'push');
            app.openDir.ButtonPushedFcn = createCallbackFcn(app, @openDir_Callback, true);
            app.openDir.Text = 'Open Dir';
            app.TrialtimeoffsetsLabel = uilabel(app.uipanel2);
            app.TrialtimeoffsetsLabel.Text = 'Trial time offset (s):';
            app.trTimeOffset_txt = uieditfield(app.uipanel2, 'numeric');
            app.GainEditFieldLabel = uilabel(app.uipanel2);
            app.GainEditFieldLabel.Text = 'Gain:';
            app.gain = uieditfield(app.uipanel2, 'numeric');
            app.gain.ValueChangedFcn = createCallbackFcn(app, @gain_Callback, true);
            app.gain.Value = 1000;
            app.SamplingsEditFieldLabel = uilabel(app.uipanel2);
            app.SamplingsEditFieldLabel.Text = 'Sampling (s):';
            app.spEdit = uieditfield(app.uipanel2, 'numeric');
            app.spEdit.ValueChangedFcn = createCallbackFcn(app, @spEdit_Callback, true);
            app.spEdit.Value = 0.0005;
            app.NchannelsEditFieldLabel = uilabel(app.uipanel2);
            app.NchannelsEditFieldLabel.Text = 'N channels:';
            app.chEdit = uieditfield(app.uipanel2, 'numeric');
            app.chEdit.ValueChangedFcn = createCallbackFcn(app, @chEdit_Callback, true);
            app.chEdit.Value = 2;
            app.TrialsEditFieldLabel = uilabel(app.uipanel2);
            app.TrialsEditFieldLabel.Text = 'Trials:';
            app.trialsEdit = uieditfield(app.uipanel2, 'numeric');
            app.trialsEdit.Value = 1;
            app.excludeCh = uieditfield(app.uipanel2, 'numeric');
            app.excludeCh.Value = 2;
            app.synchCh = uieditfield(app.uipanel2, 'numeric');
            app.synchCh.Value = 2;
            app.trialText = uilabel(app.uipanel2);
            app.trialText.Text = 'Trials (1)';
            app.trialSlider = uispinner(app.uipanel2);
            app.trialSlider.Limits = [1 Inf];
            app.trialSlider.RoundFractionalValues = 'on';
            app.trialSlider.ValueChangedFcn = createCallbackFcn(app, @trialSlider_Callback, true);
            app.trialSlider.Tooltip = {'Select the trial to visualize'};
            app.trialSlider.Value = 1;
            app.ChText = uilabel(app.uipanel2);
            app.ChText.Text = 'Ch (1)';
            app.chSlider = uispinner(app.uipanel2);
            app.chSlider.Limits = [1 Inf];
            app.chSlider.RoundFractionalValues = 'on';
            app.chSlider.ValueChangedFcn = createCallbackFcn(app, @chSlider_Callback, true);
            app.chSlider.Tooltip = {'Select the channel to visualize'};
            app.chSlider.Value = 1;
            app.SweeptimesLabel = uilabel(app.uipanel2);
            app.SweeptimesLabel.Text = 'Sweep time (s):';
            app.SweepTimeEdit = uieditfield(app.uipanel2, 'numeric');
            app.plotSlate = uipanel(app.PlotTab);
            app.plotSlate.Title = 'Plot Window';
            app.wide_plot_HP = uiaxes(app.plotSlate);
            app.wide_plot_HP.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.wide_plot_HP.XTickLabel = '0';
            app.scrollLeft = uibutton(app.plotSlate, 'push');
            app.scrollLeft.ButtonPushedFcn = createCallbackFcn(app, @scrollLeft_Callback, true);
            app.scrollLeft.Text = '< Scroll ';
            app.tResetBtn = uibutton(app.plotSlate, 'push');
            app.tResetBtn.ButtonPushedFcn = createCallbackFcn(app, @tResetBtn_Callback, true);
            app.tResetBtn.Text = 'Reset T';
            app.sendToClipboard = uibutton(app.plotSlate, 'push');
            app.sendToClipboard.ButtonPushedFcn = createCallbackFcn(app, @sendToClipboard_Callback, true);
            app.sendToClipboard.Tooltip = 'Copy the inset widows to the system clipboard';
            app.sendToClipboard.Text = 'Clipboard';
            app.tmaxTxt = uieditfield(app.plotSlate, 'numeric');
            app.tmaxTxt.ValueChangedFcn = createCallbackFcn(app, @tmaxTxt_Callback, true);
            app.tmaxTxt.Value = 5;
            app.tminTxt = uieditfield(app.plotSlate, 'numeric');
            app.tminTxt.ValueChangedFcn = createCallbackFcn(app, @tminTxt_Callback, true);
            app.tminTxt.Value = 5;
            app.spectraWide = uiaxes(app.plotSlate);
            app.spectraWide.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.spectraWide.XTickLabel = '0';
            app.wide_plot_up = uiaxes(app.plotSlate);
            app.ymaxTxt = uieditfield(app.plotSlate, 'numeric');
            app.ymaxTxt.ValueChangedFcn = createCallbackFcn(app, @ymaxTxt_Callback, true);
            app.yminTxt = uieditfield(app.plotSlate, 'numeric');
            app.yminTxt.ValueChangedFcn = createCallbackFcn(app, @yminTxt_Callback, true);
            app.ResetButton = uibutton(app.plotSlate, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_Callback, true);
            app.ResetButton.Text = {'R'; 'e'; 's'; 'e'; 't'};
            app.scrollRight = uibutton(app.plotSlate, 'push');
            app.scrollRight.ButtonPushedFcn = createCallbackFcn(app, @scrollRight_Callback, true);
            app.scrollRight.Text = 'Scroll > ';
            app.uipanel8 = uipanel(app.PlotTab);
            app.uipanel8.Title = 'Display Control';
            app.computeBP = uibutton(app.uipanel8, 'push');
            app.computeBP.ButtonPushedFcn = createCallbackFcn(app, @computeBP_Callback, true);
            app.computeBP.Tooltip = 'Recompute and plot band pass data';
            app.computeBP.Text = 'Compute BP';
            app.plotMeanFlag = uicheckbox(app.uipanel8);
            app.plotMeanFlag.ValueChangedFcn = createCallbackFcn(app, @plotMeanFlag_Callback, true);
            app.plotMeanFlag.Text = 'Plot mean';
            app.plotMultiCh = uicheckbox(app.uipanel8);
            app.plotMultiCh.ValueChangedFcn = createCallbackFcn(app, @plotMultiCh_Callback, true);
            app.plotMultiCh.Text = 'Multi ch plot';
            app.plotTrCk = uicheckbox(app.uipanel8);
            app.plotTrCk.ValueChangedFcn = createCallbackFcn(app, @plotTrCk_Callback, true);
            app.plotTrCk.Text = 'Plot each trial';
            app.plotTrCk.Value = true;
            app.autoOffCk = uicheckbox(app.uipanel8);
            app.autoOffCk.ValueChangedFcn = createCallbackFcn(app, @autoOffCk_Callback, true);
            app.autoOffCk.Tooltip = 'Automatic offset when plotting multiple channels';
            app.autoOffCk.Text = 'Auto offset';
            app.recomputeSPG = uibutton(app.uipanel8, 'push');
            app.recomputeSPG.ButtonPushedFcn = createCallbackFcn(app, @recomputeSPG_Callback, true);
            app.recomputeSPG.Tooltip = 'Compute the spectogram and the associated entities with the current parameters';
            app.recomputeSPG.Text = 'SPG';
            app.autoscaleYck = uicheckbox(app.uipanel8);
            app.autoscaleYck.Text = 'Y autoscale';
            app.plotTemplateCk = uicheckbox(app.uipanel8);
            app.plotTemplateCk.Text = 'Plot template';
            app.displayLeakCk = uicheckbox(app.uipanel8);
            app.displayLeakCk.ValueChangedFcn = createCallbackFcn(app, @displayLeakCk_Callback, true);
            app.displayLeakCk.Text = 'Leak corrected';
            app.lpcheck = uicheckbox(app.uipanel8);
            app.lpcheck.ValueChangedFcn = createCallbackFcn(app, @lpcheck_Callback, true);
            app.lpcheck.Tooltip = 'Visualize the low pass data';
            app.lpcheck.Text = 'LP';
            app.lpcheck.Value = true;
            app.bpCheck1 = uicheckbox(app.uipanel8);
            app.bpCheck1.ValueChangedFcn = createCallbackFcn(app, @bpCheck1_Callback, true);
            app.bpCheck1.Tooltip = 'Visualize the band passed data';
            app.bpCheck1.Text = 'BP1';
            app.bpCheck1.Value = true;
            app.bpCheck2 = uicheckbox(app.uipanel8);
            app.bpCheck2.ValueChangedFcn = createCallbackFcn(app, @bpCheck2_Callback, true);
            app.bpCheck2.Tooltip = 'Visualize the band passed data';
            app.bpCheck2.Text = 'BP2';
            app.bpCheck2.Value = true;
            app.bpCheck3 = uicheckbox(app.uipanel8);
            app.bpCheck3.ValueChangedFcn = createCallbackFcn(app, @bpCheck3_Callback, true);
            app.bpCheck3.Tooltip = 'Visualize the band passed data';
            app.bpCheck3.Text = 'BP3';
            app.bpCheck3.Value = true;
            app.hpCheck = uicheckbox(app.uipanel8);
            app.hpCheck.ValueChangedFcn = createCallbackFcn(app, @hpCheck_Callback, true);
            app.hpCheck.Tooltip = 'Visualize the high pass data';
            app.hpCheck.Text = 'HP';
            app.hpCheck.Value = true;
            app.screenCaptureBtn = uicheckbox(app.uipanel8);
            app.screenCaptureBtn.ValueChangedFcn = createCallbackFcn(app, @screenCaptureBtn_Callback, true);
            app.screenCaptureBtn.Text = 'Screen capture btn';
            app.screenCaptureBtn.Value = true;
            app.SPGmode = uibuttongroup(app.uipanel8);
            app.SPGmode.SelectionChangedFcn = createCallbackFcn(app, @SPGmode_SelectionChangeFcn, true);
            app.SPGmode.Title = 'Spectrogram mode';
            app.SPG = uiradiobutton(app.SPGmode);
            app.SPG.Tooltip = 'Display the spectrogram';
            app.SPG.Text = 'SPG';
            app.SPG.Value = true;
            app.gammaPower = uiradiobutton(app.SPGmode);
            app.gammaPower.Tooltip = 'Display the power in the envelope frequency band';
            app.gammaPower.Text = 'Envelope';
            app.SPGlogCk = uicheckbox(app.SPGmode);
            app.SPGlogCk.ValueChangedFcn = createCallbackFcn(app, @SPGlogCk_Callback, true);
            app.SPGlogCk.Tooltip = 'Use log scale for the linear plots';
            app.SPGlogCk.Text = 'Log';
            app.EIbutton = uiradiobutton(app.SPGmode);
            app.EIbutton.Tooltip = 'Display the time course o the power spectra slope in the 20-50 Hz range';
            app.EIbutton.Text = 'E/I';
            app.BP = uiradiobutton(app.SPGmode);
            app.BP.Text = 'BP';
            app.ChronuxCk = uicheckbox(app.uipanel8);
            app.ChronuxCk.Text = 'Use Chronux';
            app.ChronuxCk.Value = true;
            app.leakageCk = uicheckbox(app.uipanel8);
            app.leakageCk.Text = 'Correct spectral leak';
            app.uipanel5 = uipanel(app.PlotTab);
            app.uipanel5.Title = 'Save Output';
            app.saveCurrentCh = uibutton(app.uipanel5, 'push');
            app.saveCurrentCh.ButtonPushedFcn = createCallbackFcn(app, @saveCurrentCh_Callback, true);
            app.saveCurrentCh.Tooltip = 'Save data in the current view ';
            app.saveCurrentCh.Text = 'Trace';
            app.saveSPG = uibutton(app.uipanel5, 'push');
            app.saveSPG.ButtonPushedFcn = createCallbackFcn(app, @saveSPG_Callback, true);
            app.saveSPG.Text = 'SPG';
            app.saveBP = uibutton(app.uipanel5, 'push');
            app.saveBP.ButtonPushedFcn = createCallbackFcn(app, @saveBP_Callback, true);
            app.saveBP.Tooltip = 'Save the BP filtered data ONLY within the display window. Sensitive to multi-channel flag.';
            app.saveBP.Text = 'BP';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.autoProcessing = uipanel(app.SettingsTab);
            app.autoProcessing.Title = 'Auto Processing Settings';
            app.uipanel20 = uipanel(app.autoProcessing);
            app.uipanel20.Title = 'Child Processes';
            app.powerSpectraCk = uicheckbox(app.uipanel20);
            app.powerSpectraCk.ValueChangedFcn = createCallbackFcn(app, @powerSpectraCk_Callback, true);
            app.powerSpectraCk.Tooltip = 'Compute power spectra of each loaded data';
            app.powerSpectraCk.Text = 'Power Spectra';
            app.autoSavePSck = uicheckbox(app.uipanel20);
            app.autoSavePSck.Tooltip = 'Auto save power spectra of each loaded data';
            app.autoSavePSck.Text = 'Auto Save';
            app.analyzePWdistributionCk = uicheckbox(app.uipanel20);
            app.analyzePWdistributionCk.ValueChangedFcn = createCallbackFcn(app, @analyzePWdistributionCk_Callback, true);
            app.analyzePWdistributionCk.Tooltip = 'Analyze the power distribution for each BP and the HP data';
            app.analyzePWdistributionCk.Text = 'Analyze Power Distribution';
            app.autoSavePWck = uicheckbox(app.uipanel20);
            app.autoSavePWck.Tooltip = 'Auto save distribution of spectral power';
            app.autoSavePWck.Text = 'Auto Save';
            app.TrRej_ck = uicheckbox(app.uipanel20);
            app.TrRej_ck.ValueChangedFcn = createCallbackFcn(app, @TrRej_ck_Callback, true);
            app.TrRej_ck.Text = 'Trial Rejection';
            app.multiChAnalyzer = uicheckbox(app.uipanel20);
            app.multiChAnalyzer.ValueChangedFcn = createCallbackFcn(app, @multiChAnalyzer_Callback, true);
            app.multiChAnalyzer.Tooltip = 'Xcorrelation and Xspectra of multiple channels';
            app.multiChAnalyzer.Text = 'Cross correlation and cross spectra';
            app.singleUnitExtraction_ck = uicheckbox(app.uipanel20);
            app.singleUnitExtraction_ck.ValueChangedFcn = createCallbackFcn(app, @singleUnitExtraction_ck_Callback, true);
            app.singleUnitExtraction_ck.Tooltip = 'Xcorrelation and Xspectra of multiple channels';
            app.singleUnitExtraction_ck.Text = 'Single unit extraction';
            app.hit_ck = uicheckbox(app.uipanel20);
            app.hit_ck.ValueChangedFcn = createCallbackFcn(app, @hit_ck_Callback, true);
            app.hit_ck.Text = 'Hit %';
            app.checkbox_sleepscoring = uicheckbox(app.uipanel20);
            app.checkbox_sleepscoring.ValueChangedFcn = createCallbackFcn(app, @checkbox_sleepscoring_Callback, true);
            app.checkbox_sleepscoring.Text = 'Sleep Scoring';
            app.saveParams_btn = uibutton(app.autoProcessing, 'push');
            app.saveParams_btn.ButtonPushedFcn = createCallbackFcn(app, @saveParams_btn_Callback, true);
            app.saveParams_btn.Text = 'Save Parameters';
            app.loadParams_btn = uibutton(app.autoProcessing, 'push');
            app.loadParams_btn.ButtonPushedFcn = createCallbackFcn(app, @loadParams_btn_Callback, true);
            app.loadParams_btn.Text = 'Load Parameters';
            app.uipanel21 = uipanel(app.autoProcessing);
            app.uipanel21.Title = 'Concatenation Panel';
            app.concat_ck = uicheckbox(app.uipanel21);
            app.concat_ck.ValueChangedFcn = createCallbackFcn(app, @concat_ck_Callback, true);
            app.concat_ck.Tooltip = 'Concatenate trials opening different files';
            app.concat_ck.Text = 'Concatenate Trials';
            app.analyzeWhileConc_ck = uicheckbox(app.uipanel21);
            app.analyzeWhileConc_ck.ValueChangedFcn = createCallbackFcn(app, @analyzeWhileConc_ck_Callback, true);
            app.analyzeWhileConc_ck.Text = 'Analyze while concatenating';
            app.concat_btn = uibutton(app.uipanel21, 'push');
            app.concat_btn.ButtonPushedFcn = createCallbackFcn(app, @concat_btn_Callback, true);
            app.concat_btn.Enable = 'off';
            app.concat_btn.Tooltip = 'Concatenate uploaded trials and run the analysis';
            app.concat_btn.Text = 'Concatenate now';
            app.clearConc_btn = uibutton(app.uipanel21, 'push');
            app.clearConc_btn.ButtonPushedFcn = createCallbackFcn(app, @clearConc_btn_Callback, true);
            app.clearConc_btn.Tooltip = 'Clear traces stored for concatenation';
            app.clearConc_btn.Text = 'Clear';
            app.dataProcessing = uipanel(app.SettingsTab);
            app.dataProcessing.Title = 'Data Processing';
            app.uipanel4 = uibuttongroup(app.dataProcessing);
            app.uipanel4.Title = 'Analysis Options';
            app.text8 = uilabel(app.uipanel4);
            app.text8.Text = 'Baseline for power computation (s):';
            app.text5 = uilabel(app.uipanel4);
            app.text5.Text = 'Threshold multiunits (SD multiplier):';
            app.text16 = uilabel(app.uipanel4);
            app.text16.Text = 'Trial analysis, baseline (s):';
            app.text17 = uilabel(app.uipanel4);
            app.text17.Text = 'Trial analysis, response (s):';
            app.doubleStimCk = uicheckbox(app.uipanel4);
            app.doubleStimCk.Tooltip = 'Click if the trial contain two stimuli, one at the beginning, the second at half period.';
            app.doubleStimCk.Text = 'Two stimuli per trial';
            app.templateCk = uicheckbox(app.uipanel4);
            app.templateCk.Tooltip = 'Template-based response analysis';
            app.templateCk.Text = 'Template analysis';
            app.templateCk.Value = true;
            app.text29 = uilabel(app.uipanel4);
            app.text29.Text = 'Template definition interval (s):';
            app.skip1trialCk = uicheckbox(app.uipanel4);
            app.skip1trialCk.Tooltip = 'Template-based response analysis';
            app.skip1trialCk.Text = 'Skip first trial';
            app.skip1trialCk.Value = true;
            app.text40 = uilabel(app.uipanel4);
            app.text40.Tooltip = 'Ignore all square waves on the trigger channel longer than this (measured in sampling units)';
            app.text40.Text = 'Trigger synch max duration (samples):';
            app.templExp_bt = uibutton(app.uipanel4, 'push');
            app.templExp_bt.ButtonPushedFcn = createCallbackFcn(app, @templExp_bt_Callback, true);
            app.templExp_bt.Tooltip = 'Export the current template';
            app.templExp_bt.Text = 'Export';
            app.templImp_bt = uibutton(app.uipanel4, 'push');
            app.templImp_bt.ButtonPushedFcn = createCallbackFcn(app, @templImp_bt_Callback, true);
            app.templImp_bt.Tooltip = 'Import a template from a .mat file';
            app.templImp_bt.Text = 'Import';
            app.templRecalc_bt = uibutton(app.uipanel4, 'push');
            app.templRecalc_bt.Tooltip = 'Recalculate template for the current trace with specified parameter';
            app.templRecalc_bt.Text = 'Recalc';
            app.smooth_ck = uicheckbox(app.uipanel4);
            app.smooth_ck.Tooltip = 'filter the trace before template calculation';
            app.smooth_ck.Text = 'Smoothing';
            app.smooth_ck.Value = true;
            app.ROI4template_bt = uibutton(app.uipanel4, 'push');
            app.ROI4template_bt.ButtonPushedFcn = createCallbackFcn(app, @ROI4template_bt_Callback, true);
            app.ROI4template_bt.Tooltip = 'Set the template window centered on the ROI minimum';
            app.ROI4template_bt.Text = 'ROI for template';
            app.text42 = uilabel(app.uipanel4);
            app.text42.Text = 'before (s):';
            app.text43 = uilabel(app.uipanel4);
            app.text43.Text = 'after (s):';
            app.singleUnitsThr = uieditfield(app.uipanel4, 'numeric');
            app.singleUnitsThr.Value = 2;
            app.baselinePower = uieditfield(app.uipanel4, 'numeric');
            app.baselinePower.Value = 5;
            app.baselineFrom = uieditfield(app.uipanel4, 'numeric');
            app.baselineFrom.Value = 0.03;
            app.baselineTo = uieditfield(app.uipanel4, 'numeric');
            app.baselineTo.Value = 0.08;
            app.respFrom = uieditfield(app.uipanel4, 'numeric');
            app.respFrom.Value = 0.09;
            app.respTo = uieditfield(app.uipanel4, 'numeric');
            app.respTo.Value = 0.35;
            app.triggerMaxLength = uieditfield(app.uipanel4, 'numeric');
            app.triggerMaxLength.Value = 250;
            app.p100bef = uieditfield(app.uipanel4, 'numeric');
            app.p100bef.Value = 0.03;
            app.p100after = uieditfield(app.uipanel4, 'numeric');
            app.p100after.Value = 0.02;
            app.winPWleft = uieditfield(app.uipanel4, 'numeric');
            app.winPWleft.Value = 0.02;
            app.winPWright = uieditfield(app.uipanel4, 'numeric');
            app.winPWright.Value = 0.8;
            app.templateFrom = uieditfield(app.uipanel4, 'numeric');
            app.templateFrom.Value = 0.02;
            app.templateTo = uieditfield(app.uipanel4, 'numeric');
            app.templateTo.Value = 0.35;
            app.BPtext = uilabel(app.uipanel4);
            app.BPtext.Text = 'Select (BP)';
            app.sliderBPpower = uispinner(app.uipanel4);
            app.sliderBPpower.Limits = [1 3];
            app.sliderBPpower.RoundFractionalValues = 'on';
            app.sliderBPpower.ValueChangedFcn = createCallbackFcn(app, @sliderBPpower_Callback, true);
            app.sliderBPpower.Tooltip = {'Select the Band for power computation'};
            app.sliderBPpower.Value = 3;
            app.text32 = uilabel(app.uipanel4);
            app.text32.Tooltip = 'Window for the computation of the power integral ';
            app.text32.Text = 'Window for power computation (s):';
            app.DisplayPanel = uibuttongroup(app.dataProcessing);
            app.DisplayPanel.Title = 'Options of Spectral Analysis';
            app.text4 = uilabel(app.DisplayPanel);
            app.text4.Text = 'Band pass definitions     from (Hz)        to  ';
            app.notchFilter = uicheckbox(app.DisplayPanel);
            app.notchFilter.ValueChangedFcn = createCallbackFcn(app, @notchFilter_Callback, true);
            app.notchFilter.Text = '   Notch filter';
            app.predefinedBW = uibuttongroup(app.DisplayPanel);
            app.predefinedBW.SelectionChangedFcn = createCallbackFcn(app, @predefinedBW_SelectionChangeFcn, true);
            app.predefinedBW.Title = 'Predefined bands';
            app.mouseCk = uiradiobutton(app.predefinedBW);
            app.mouseCk.Text = 'Mouse';
            app.mouseCk.Value = true;
            app.zebraCk = uiradiobutton(app.predefinedBW);
            app.zebraCk.Text = 'Zebrafish';
            app.EEGck = uiradiobutton(app.predefinedBW);
            app.EEGck.Text = 'EEG VEP';
            app.NewcastleBand = uiradiobutton(app.predefinedBW);
            app.NewcastleBand.Text = 'Newcastle';
            app.envelopeChoice = uibuttongroup(app.DisplayPanel);
            app.envelopeChoice.Title = 'Envelope Computation';
            app.text12 = uilabel(app.envelopeChoice);
            app.text12.Text = 'BP from (Hz)                to';
            app.radiobutton6 = uiradiobutton(app.envelopeChoice);
            app.radiobutton6.Text = 'Thresholded variance';
            app.radiobutton6.Value = true;
            app.plotEnvelopeCk = uicheckbox(app.envelopeChoice);
            app.plotEnvelopeCk.ValueChangedFcn = createCallbackFcn(app, @plotEnvelopeCk_Callback, true);
            app.plotEnvelopeCk.Text = 'Plot HP envelope';
            app.filterBPpower = uicheckbox(app.envelopeChoice);
            app.filterBPpower.Text = 'Filter power plot';
            app.envSPGfrom = uieditfield(app.envelopeChoice, 'numeric');
            app.envSPGfrom.Value = 30;
            app.envSPGto = uieditfield(app.envelopeChoice, 'numeric');
            app.envSPGto.Value = 80;
            app.text14 = uilabel(app.DisplayPanel);
            app.text14.Text = 'SPG plot limits (dB):';
            app.uipanel17 = uipanel(app.DisplayPanel);
            app.uipanel17.Title = 'Spectrogram computation';
            app.uipanel18 = uipanel(app.uipanel17);
            app.uipanel18.Title = 'Signal Analysis Toolbox';
            app.txt = uilabel(app.uipanel18);
            app.txt.Text = 'SPG step (Hz):';
            app.txtspg = uilabel(app.uipanel18);
            app.txtspg.Text = 'SPG window:';
            app.text21 = uilabel(app.uipanel18);
            app.text21.Text = 'Overlap:';
            app.SPGresolution = uieditfield(app.uipanel18, 'numeric');
            app.SPGresolution.Value = 5;
            app.SPGwindow = uieditfield(app.uipanel18, 'numeric');
            app.SPGwindow.Value = 256;
            app.SPGoverlap = uieditfield(app.uipanel18, 'numeric');
            app.SPGoverlap.Value = 16;
            app.uipanel19 = uipanel(app.uipanel17);
            app.uipanel19.Title = 'Chronux';
            app.ChronuxAutoConfigCk = uicheckbox(app.uipanel19);
            app.ChronuxAutoConfigCk.Text = 'Automatic configuration';
            app.ChronuxAutoConfigCk.Value = true;
            app.text25 = uilabel(app.uipanel19);
            app.text25.Text = 'SPG window (s):';
            app.text26 = uilabel(app.uipanel19);
            app.text26.Text = 'Overlap:';
            app.ChronuxRW = uieditfield(app.uipanel19, 'numeric');
            app.ChronuxRW.Value = 0.25;
            app.ChronuxOvl = uieditfield(app.uipanel19, 'numeric');
            app.ChronuxOvl.Value = 0.05;
            app.text27 = uilabel(app.uipanel17);
            app.text27.Text = 'Band of SPG from (Hz):';
            app.text28 = uilabel(app.uipanel17);
            app.text28.Text = 'To:';
            app.decimateFlag = uicheckbox(app.uipanel17);
            app.decimateFlag.Tooltip = 'To be used only for very long data set and slow PCs';
            app.decimateFlag.Text = 'Decimate data for spectogram';
            app.text30 = uilabel(app.uipanel17);
            app.text30.Tooltip = 'BP of the signal on which computing the SPG';
            app.text30.Text = 'LP for leakage correction (Hz):';
            app.SPGfromFreq = uieditfield(app.uipanel17, 'numeric');
            app.SPGtoFreq = uieditfield(app.uipanel17, 'numeric');
            app.SPGtoFreq.Value = 200;
            app.leakageLP = uieditfield(app.uipanel17, 'numeric');
            app.leakageLP.ValueChangedFcn = createCallbackFcn(app, @leakageLP_Callback, true);
            app.leakageLP.Value = 10;
            app.text33 = uilabel(app.DisplayPanel);
            app.text33.Text = 'Butterworth filter order:';
            app.text35 = uilabel(app.DisplayPanel);
            app.text35.Text = 'Low Pass:';
            app.text36 = uilabel(app.DisplayPanel);
            app.text36.Text = 'Band Pass 1:';
            app.text37 = uilabel(app.DisplayPanel);
            app.text37.Text = 'Band Pass 2:';
            app.text38 = uilabel(app.DisplayPanel);
            app.text38.Text = 'Band Pass 3:';
            app.text39 = uilabel(app.DisplayPanel);
            app.text39.Text = 'High Pass:';
            app.LPenableCK = uicheckbox(app.DisplayPanel);
            app.LPenableCK.Tooltip = 'Enable/disable computation of band passed data ';
            app.LPenableCK.Text = '';
            app.HPenableCK = uicheckbox(app.DisplayPanel);
            app.HPenableCK.Tooltip = 'Enable/disable computation of band passed data ';
            app.HPenableCK.Text = '';
            app.HPenableCK.Value = true;
            app.BP1enableCK = uicheckbox(app.DisplayPanel);
            app.BP1enableCK.Tooltip = 'Enable/disable computation of band passed data ';
            app.BP1enableCK.Text = '';
            app.BP1enableCK.Value = true;
            app.BP3enableCK = uicheckbox(app.DisplayPanel);
            app.BP3enableCK.Tooltip = 'Enable/disable computation of band passed data ';
            app.BP3enableCK.Text = '';
            app.BP3enableCK.Value = true;
            app.BP2enableCK = uicheckbox(app.DisplayPanel);
            app.BP2enableCK.Tooltip = 'Enable/disable computation of band passed data ';
            app.BP2enableCK.Text = '';
            app.BP2enableCK.Value = true;
            app.lowerDB = uieditfield(app.DisplayPanel, 'numeric');
            app.lowerDB.ValueChangedFcn = createCallbackFcn(app, @lowerDB_Callback, true);
            app.lowerDB.Value = -60;
            app.upperDB = uieditfield(app.DisplayPanel, 'numeric');
            app.upperDB.ValueChangedFcn = createCallbackFcn(app, @upperDB_Callback, true);
            app.filtOrder = uieditfield(app.DisplayPanel, 'numeric');
            app.filtOrder.Value = 2;
            app.notchFrom = uieditfield(app.DisplayPanel, 'numeric');
            app.notchFrom.ValueChangedFcn = createCallbackFcn(app, @notchFrom_Callback, true);
            app.notchFrom.Value = 49;
            app.notchTo = uieditfield(app.DisplayPanel, 'numeric');
            app.notchTo.ValueChangedFcn = createCallbackFcn(app, @notchTo_Callback, true);
            app.notchTo.Value = 51;
            app.lpTo = uieditfield(app.DisplayPanel, 'numeric');
            app.lpTo.ValueChangedFcn = createCallbackFcn(app, @lpTo_Callback, true);
            app.lpTo.Value = 8;
            app.alphaFrom = uieditfield(app.DisplayPanel, 'numeric');
            app.alphaFrom.ValueChangedFcn = createCallbackFcn(app, @alphaFrom_Callback, true);
            app.alphaFrom.Value = 0.5;
            app.alphaTo = uieditfield(app.DisplayPanel, 'numeric');
            app.alphaTo.ValueChangedFcn = createCallbackFcn(app, @alphaTo_Callback, true);
            app.alphaTo.Value = 4;
            app.betaFrom = uieditfield(app.DisplayPanel, 'numeric');
            app.betaFrom.ValueChangedFcn = createCallbackFcn(app, @betaFrom_Callback, true);
            app.betaFrom.Value = 9;
            app.betaTo = uieditfield(app.DisplayPanel, 'numeric');
            app.betaTo.ValueChangedFcn = createCallbackFcn(app, @betaTo_Callback, true);
            app.betaTo.Value = 25;
            app.gammaFrom = uieditfield(app.DisplayPanel, 'numeric');
            app.gammaFrom.ValueChangedFcn = createCallbackFcn(app, @gammaFrom_Callback, true);
            app.gammaFrom.Value = 40;
            app.gammaTo = uieditfield(app.DisplayPanel, 'numeric');
            app.gammaTo.ValueChangedFcn = createCallbackFcn(app, @gammaTo_Callback, true);
            app.gammaTo.Value = 100;
            app.hpFrom = uieditfield(app.DisplayPanel, 'numeric');
            app.hpFrom.ValueChangedFcn = createCallbackFcn(app, @hpFrom_Callback, true);
            app.hpFrom.Value = 300;
            app.ZebraAutoPanel = uipanel(app.SettingsTab);
            app.ZebraAutoPanel.Title = 'ZebraAuto';
            app.AutoSetContrasts_bt = uibutton(app.ZebraAutoPanel, 'push');
            app.AutoSetContrasts_bt.ButtonPushedFcn = createCallbackFcn(app, @AutoSetContrasts_btButtonPushed, true);
            app.AutoSetContrasts_bt.Text = 'Set Contrasts';
            app.uipanel1 = uipanel(app.ZebraAutoPanel);
            app.uipanel1.Title = 'Panel';
            app.AutoSpg_ck = uicheckbox(app.uipanel1);
            app.AutoSpg_ck.Text = 'SPG';
            app.AutoTable_ck = uicheckbox(app.uipanel1);
            app.AutoTable_ck.Text = 'Table';
            app.AutoTable_ck.Value = true;
            app.AutoTableAvg_ck = uicheckbox(app.uipanel1);
            app.AutoTableAvg_ck.Text = 'Average conditions';
            app.AutoTableAvg_ck.Value = true;
            app.AutoTrace_ck = uicheckbox(app.uipanel1);
            app.AutoTrace_ck.Text = 'Trace';
            app.uipanel3 = uipanel(app.uipanel1);
            app.uipanel3.Title = 'Failures';
            app.AutoFailures_ck = uicheckbox(app.uipanel3);
            app.AutoFailures_ck.Text = 'Failures';
            app.FailurethresholdLabel = uilabel(app.uipanel3);
            app.FailurethresholdLabel.Text = 'Failure threshold:';
            app.AutoFailThreshold_edit = uieditfield(app.uipanel3, 'numeric');
            app.AutoFailThreshold_edit.Value = 0.05;
            app.ChannelEditFieldLabel = uilabel(app.uipanel1);
            app.ChannelEditFieldLabel.Text = 'Channel';
            app.AutoSelCh_txt = uieditfield(app.uipanel1, 'numeric');
            app.AutoSelCh_txt.Value = 1;
            app.AutoGo_bt = uibutton(app.ZebraAutoPanel, 'push');
            app.AutoGo_bt.ButtonPushedFcn = createCallbackFcn(app, @AutoGo_btButtonPushed, true);
            app.AutoGo_bt.Enable = 'off';
            app.AutoGo_bt.Text = 'Go';
            app.uipanel2_2 = uipanel(app.ZebraAutoPanel);
            app.uipanel2_2.Title = 'Panel';
            app.text2 = uilabel(app.uipanel2_2);
            app.text2.Text = 'Main folder';
            app.AutoFileNameIn_edit = uieditfield(app.uipanel2_2, 'text');
            app.AutoFileNameIn_edit.Value = 'ltp001.dat';
            app.AutoOutFileName_edit = uieditfield(app.uipanel2_2, 'text');
            app.AutoOutFileName_edit.Value = '.mat';
            app.text3 = uilabel(app.uipanel2_2);
            app.text3.Text = 'data file name';
            app.AutoMainFolder_edit = uieditfield(app.uipanel2_2, 'text');
            app.autoMode_bt = uibutton(app.ZebraAutoPanel, 'push');
            app.autoMode_bt.ButtonPushedFcn = createCallbackFcn(app, @autoMode_btButtonPushed, true);
            app.autoMode_bt.Text = 'Auto Mode OFF';
            app.text4_2 = uilabel(app.ZebraAutoPanel);
            app.text4_2.Text = 'output file name';
            app.nofconditionsEditFieldLabel = uilabel(app.ZebraAutoPanel);
            app.nofconditionsEditFieldLabel.Text = 'n of conditions';
            app.AutoNConds_txt = uieditfield(app.ZebraAutoPanel, 'numeric');
            app.AutoNConds_txt.Value = 6;
            app.uipanel4_2 = uipanel(app.ZebraAutoPanel);
            app.uipanel4_2.Title = 'Panel';
            app.AutoSpectra_ck = uicheckbox(app.uipanel4_2);
            app.AutoSpectra_ck.Text = 'Spectra';
            app.AutoWinSpectra_ck = uicheckbox(app.uipanel4_2);
            app.AutoWinSpectra_ck.Text = 'On window 1, (s)';
            app.AutoWinSpectra2_ck = uicheckbox(app.uipanel4_2);
            app.AutoWinSpectra2_ck.Text = 'On window 2, (s)';
            app.AutoSpectraTr_ck = uicheckbox(app.uipanel4_2);
            app.AutoSpectraTr_ck.Text = 'Single Trials';
            app.AutoSpectraAvg_ck = uicheckbox(app.uipanel4_2);
            app.AutoSpectraAvg_ck.Text = 'Average';
            app.fromEditFieldLabel = uilabel(app.uipanel4_2);
            app.fromEditFieldLabel.Text = 'from';
            app.AutoSpectraFrom_edit = uieditfield(app.uipanel4_2, 'numeric');
            app.AutoSpectraFrom_edit.Value = 1;
            app.toEditFieldLabel = uilabel(app.uipanel4_2);
            app.toEditFieldLabel.Text = 'to';
            app.AutoSpectraTo_edit = uieditfield(app.uipanel4_2, 'numeric');
            app.AutoSpectraTo_edit.Value = 1;
            app.fromEditField_2Label = uilabel(app.uipanel4_2);
            app.fromEditField_2Label.Text = 'from';
            app.AutoSpectraFrom2_edit = uieditfield(app.uipanel4_2, 'numeric');
            app.AutoSpectraFrom2_edit.Value = 1;
            app.toEditField_2Label = uilabel(app.uipanel4_2);
            app.toEditField_2Label.Text = 'to';
            app.AutoSpectraTo2_edit = uieditfield(app.uipanel4_2, 'numeric');
            app.AutoSpectraTo2_edit.Value = 1;
            app.ZebraSaysTab = uitab(app.TabGroup);
            app.ZebraSaysTab.Title = 'ZebraSays';
            app.outable = uitable(app.ZebraSaysTab);
            app.outable.ColumnName = {'Dir'; 'File Name'; 'Time'; 'Ch'; 'Trial'; 'LP RMS'; 'BP1 RMS'; 'BP2 RMS'; 'BP3 RMS'; 'HP RMS'; 'Notes'};
            app.outable.RowName = {};
            app.outable.ColumnEditable = [false false false false false false false false false false true];
            app.MetricsoftheentiretraceLabel = uilabel(app.ZebraSaysTab);
            app.MetricsoftheentiretraceLabel.Text = 'Metrics of the entire trace';
            app.MetricsoftheevokedresponsessingletrialsandaveragesLabel = uilabel(app.ZebraSaysTab);
            app.MetricsoftheevokedresponsessingletrialsandaveragesLabel.Text = 'Metrics of the evoked responses (single trials and averages)';
            app.MetricsofasynchronouseventsburstsandseizuresLabel = uilabel(app.ZebraSaysTab);
            app.MetricsofasynchronouseventsburstsandseizuresLabel.Text = 'Metrics of asynchronous events (bursts and seizures)';
            app.outEvochedResponses = uitable(app.ZebraSaysTab);
            app.outEvochedResponses.ColumnName = {'File Name'; 'Ch'; 'Trial'; 'Rep'; 'Amp'; 'T Peak'; 'Base PW BP2'; 'Resp PW BP2'; 'Resp/Base'; 'Temp PW BP2'; 'Comment'};
            app.outEvochedResponses.RowName = {};
            app.outEvochedResponses.ColumnEditable = false;
            app.outEventsSummary = uitable(app.ZebraSaysTab);
            app.outEventsSummary.ColumnName = {'Dir'; 'File Name'; 'N'; 'Duration'; 'Time'; 'Base Beta'; 'Base RMS'; 'Ratio Beta'; 'Base Gamma'; 'Gamma RMS'; 'Ratio Gamma'; 'Ratio B/G'; 'Base HP'; 'HP RMS'; 'Ratio HP'; 'Notes'};
            app.outEventsSummary.RowName = {};
            app.outEventsSummary.ColumnEditable = [false false false false false false false false false false false false false false false true];
            app.EventDetectionTab = uitab(app.TabGroup);
            app.EventDetectionTab.Title = 'EventDetection';
            app.uipanel2_3 = uipanel(app.EventDetectionTab);
            app.uipanel2_3.Title = 'Parameters';
            app.text1 = uilabel(app.uipanel2_3);
            app.text1.Text = 'Baseline threshold:';
            app.text2_2 = uilabel(app.uipanel2_3);
            app.text2_2.Text = 'Event threshold:';
            app.text3_2 = uilabel(app.uipanel2_3);
            app.text3_2.Tooltip = 'Shortest admissible duration of an upstate';
            app.text3_2.Text = 'Minimum state duration (s):';
            app.extractEvents = uibutton(app.uipanel2_3, 'push');
            app.extractEvents.ButtonPushedFcn = createCallbackFcn(app, @extractEvents_Callback, true);
            app.extractEvents.Text = 'Compute';
            app.analyzeData = uibutton(app.uipanel2_3, 'push');
            app.analyzeData.ButtonPushedFcn = createCallbackFcn(app, @analyzeData_Callback, true);
            app.analyzeData.Text = 'Analyze';
            app.text4_3 = uilabel(app.uipanel2_3);
            app.text4_3.Tooltip = 'Upstate definitions are bridged across interuptons shorter than this interval';
            app.text4_3.Text = 'Shortest state interruption:';
            app.gauss1param = uilabel(app.uipanel2_3);
            app.gauss1param.Text = 'Gaussian 1: ';
            app.gauss2param = uilabel(app.uipanel2_3);
            app.gauss2param.Text = 'Gaussian 2:';
            app.text8_2 = uilabel(app.uipanel2_3);
            app.text8_2.Tooltip = 'Limits in log scale of the power histogram and bin number. Set to the lower limits to 0 for autoscaling.';
            app.text8_2.Text = 'From, To, Bins:';
            app.text9 = uilabel(app.uipanel2_3);
            app.text9.Text = 'Limits of the test plot:';
            app.replot = uibutton(app.uipanel2_3, 'push');
            app.replot.ButtonPushedFcn = createCallbackFcn(app, @replot_Callback, true);
            app.replot.Text = 'Replot';
            app.uipanel7 = uibuttongroup(app.uipanel2_3);
            app.detectionBP1 = uiradiobutton(app.uipanel7);
            app.detectionBP1.Tooltip = 'Select bandwidth for event extraction';
            app.detectionBP1.Text = 'BP1';
            app.detectionBP1.Value = true;
            app.detectionBP2 = uiradiobutton(app.uipanel7);
            app.detectionBP2.Tooltip = 'Select bandwidth for event extraction';
            app.detectionBP2.Text = 'BP2';
            app.detectionBP3 = uiradiobutton(app.uipanel7);
            app.detectionBP3.Tooltip = 'Select bandwidth for event extraction';
            app.detectionBP3.Text = 'BP3';
            app.detectionHP = uiradiobutton(app.uipanel7);
            app.detectionHP.Tooltip = 'Select bandwidth for event extraction';
            app.detectionHP.Text = 'HP';
            app.detectionSPG = uiradiobutton(app.uipanel7);
            app.detectionSPG.Tooltip = 'Select bandwidth for event extraction';
            app.detectionSPG.Text = 'SPG envelope';
            app.erodeCk = uicheckbox(app.uipanel2_3);
            app.erodeCk.Text = 'Check state edges';
            app.uipanel3_2 = uibuttongroup(app.uipanel2_3);
            app.powerLogCk = uiradiobutton(app.uipanel3_2);
            app.powerLogCk.Text = 'Log of ''envelope'' power';
            app.powerLogCk.Value = true;
            app.radiobutton2 = uiradiobutton(app.uipanel3_2);
            app.radiobutton2.Text = '''Envelope'' power';
            app.removeCk = uicheckbox(app.uipanel2_3);
            app.removeCk.Text = 'Remove state interruptions';
            app.removeCk.Value = true;
            app.autoThr = uicheckbox(app.uipanel2_3);
            app.autoThr.Text = 'Autosetting state threshold';
            app.autoThr.Value = true;
            app.conMethod = uibuttongroup(app.uipanel2_3);
            app.conMethod.Title = 'Method of concatenation';
            app.noConcCk = uiradiobutton(app.conMethod);
            app.noConcCk.Text = 'No concatenation';
            app.joinCk = uiradiobutton(app.conMethod);
            app.joinCk.Text = 'Join';
            app.joinOffsetChk = uiradiobutton(app.conMethod);
            app.joinOffsetChk.Text = 'Join with offset';
            app.joinOffsetChk.Value = true;
            app.traslateLeft = uibutton(app.uipanel2_3, 'push');
            app.traslateLeft.ButtonPushedFcn = createCallbackFcn(app, @traslateLeft_Callback, true);
            app.traslateLeft.Tooltip = 'Traslate the plot window left';
            app.traslateLeft.Text = '<';
            app.traslateRight = uibutton(app.uipanel2_3, 'push');
            app.traslateRight.ButtonPushedFcn = createCallbackFcn(app, @traslateRight_Callback, true);
            app.traslateRight.Tooltip = 'Traslate the plot window right';
            app.traslateRight.Text = '>';
            app.leftTime = uieditfield(app.uipanel2_3, 'numeric');
            app.rightTime = uieditfield(app.uipanel2_3, 'numeric');
            app.rightTime.Value = 10;
            app.fromH = uieditfield(app.uipanel2_3, 'numeric');
            app.fromH.Value = -5;
            app.toH = uieditfield(app.uipanel2_3, 'numeric');
            app.binNumber = uieditfield(app.uipanel2_3, 'numeric');
            app.binNumber.Value = 32;
            app.minDuration = uieditfield(app.uipanel2_3, 'numeric');
            app.minDuration.Value = 0.11;
            app.baselineThr = uieditfield(app.uipanel2_3, 'numeric');
            app.baselineThr.Value = -3;
            app.maxInterruption = uieditfield(app.uipanel2_3, 'numeric');
            app.maxInterruption.Value = 0.11;
            app.eventThr = uieditfield(app.uipanel2_3, 'numeric');
            app.eventThr.Value = -3;
            app.uipanel1_2 = uipanel(app.EventDetectionTab);
            app.uipanel1_2.Title = 'Histogram';
            app.histoPlot = uiaxes(app.uipanel1_2);
            app.plotStates = uiaxes(app.uipanel1_2);
            app.statesTrack = uiaxes(app.uipanel1_2);
            app.saveHCD = uibutton(app.uipanel1_2, 'push');
            app.saveHCD.ButtonPushedFcn = createCallbackFcn(app, @saveHCD_Callback, true);
            app.saveHCD.Tooltip = 'Save the histograms and the cumulative distributions';
            app.saveHCD.Text = 'Save';
            app.uipanel4_3 = uipanel(app.EventDetectionTab);
            app.uipanel4_3.Title = 'Power Spectra';
            app.powerSpectra = uiaxes(app.uipanel4_3);
            app.uipanel5_2 = uipanel(app.EventDetectionTab);
            app.uipanel5_2.Title = 'Output options';
            app.text10 = uilabel(app.uipanel5_2);
            app.text10.Text = 'Set min & max power (dB):';
            app.text11 = uilabel(app.uipanel5_2);
            app.text11.Text = 'Set min & max freq (Hz):';
            app.savePWS = uibutton(app.uipanel5_2, 'push');
            app.savePWS.ButtonPushedFcn = createCallbackFcn(app, @savePWS_Callback, true);
            app.savePWS.Text = 'Save PwS';
            app.saveSegmentedStates = uibutton(app.uipanel5_2, 'push');
            app.saveSegmentedStates.ButtonPushedFcn = createCallbackFcn(app, @saveSegmentedStates_Callback, true);
            app.saveSegmentedStates.Text = 'Save US/DS';
            app.SaveDownstSpec = uibutton(app.uipanel5_2, 'push');
            app.SaveDownstSpec.ButtonPushedFcn = createCallbackFcn(app, @SaveDownstSpecButtonPushed, true);
            app.SaveDownstSpec.Text = 'Save Dwnst PwS';
            app.UStable = uitable(app.uipanel5_2);
            app.UStable.ColumnName = {'N'; 'T from'; 'T to'; 'Duration'; 'Median V'; 'SD'; 'Power'; 'Median V other hemisphere'; 'SD other hemisphere'; 'Power other hemisphere'};
            app.UStable.RowName = {};
            app.UStable.ColumnEditable = [false false false false false false false false false false];
            app.exportTab_btn = uibutton(app.uipanel5_2, 'push');
            app.exportTab_btn.ButtonPushedFcn = createCallbackFcn(app, @exportTab_btn_Callback, true);
            app.exportTab_btn.Text = 'Export table';
            app.minPw = uieditfield(app.uipanel5_2, 'numeric');
            app.minPw.Value = -70;
            app.maxPw = uieditfield(app.uipanel5_2, 'numeric');
            app.maxPw.Value = -30;
            app.minFreq = uieditfield(app.uipanel5_2, 'numeric');
            app.minFreq.Value = 8;
            app.maxFreq = uieditfield(app.uipanel5_2, 'numeric');
            app.maxFreq.Value = 200;
        end
    end

    methods (Access = public)

        % Construct app
        function app = ZebraExploreApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ZebraMainFig)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ZebraMainFig)
        end
    end
end